classdef LIPRAS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        menu_file                       matlab.ui.container.Menu
        menuoption_new                  matlab.ui.container.Menu
        menu_new                        matlab.ui.container.Menu
        Untitled_14                     matlab.ui.container.Menu
        FitParamImport                  matlab.ui.container.Menu
        Untitled_12                     matlab.ui.container.Menu
        FitParamExport                  matlab.ui.container.Menu
        menu_saveasimage                matlab.ui.container.Menu
        ResetSizeMenu                   matlab.ui.container.Menu
        menu_FileResetProfile           matlab.ui.container.Menu
        menu_restart                    matlab.ui.container.Menu
        menu_close                      matlab.ui.container.Menu
        menu_plot                       matlab.ui.container.Menu
        menu_xaxis                      matlab.ui.container.Menu
        menu_xaxis_linear               matlab.ui.container.Menu
        menu_xaxis_dspace               matlab.ui.container.Menu
        menu_yaxis                      matlab.ui.container.Menu
        menu_ylinear                    matlab.ui.container.Menu
        menu_ylog                       matlab.ui.container.Menu
        menu_yroot                      matlab.ui.container.Menu
        menuPlot_superimpose            matlab.ui.container.Menu
        LegendMenu                      matlab.ui.container.Menu
        LegendOn                        matlab.ui.container.Menu
        LegendOff                       matlab.ui.container.Menu
        MathMenu                        matlab.ui.container.Menu
        DerivativeMenu                  matlab.ui.container.Menu
        IntegralMenu                    matlab.ui.container.Menu
        PreferencesMenu                 matlab.ui.container.Menu
        LeastSquaresWeightsMenu         matlab.ui.container.Menu
        DefaultMenu_2                   matlab.ui.container.Menu
        NoneMenu_2                      matlab.ui.container.Menu
        obsMenu_2                       matlab.ui.container.Menu
        sqrtobsMenu_2                   matlab.ui.container.Menu
        StartingDirectoryMenu           matlab.ui.container.Menu
        SelectMenu_2                    matlab.ui.container.Menu
        UniqueSaveMenu                  matlab.ui.container.Menu
        YesMenu_3                       matlab.ui.container.Menu
        NoMenu_3                        matlab.ui.container.Menu
        ImageResolutionMenu             matlab.ui.container.Menu
        DPIMenu_6                       matlab.ui.container.Menu
        DPIMenu_7                       matlab.ui.container.Menu
        DPIMenu_8                       matlab.ui.container.Menu
        ImageTypeMenu                   matlab.ui.container.Menu
        TIFFMenu_2                      matlab.ui.container.Menu
        JPEGMenu_2                      matlab.ui.container.Menu
        PNGMenu_2                       matlab.ui.container.Menu
        PDFMenu_2                       matlab.ui.container.Menu
        ImageSaveAllMenu                matlab.ui.container.Menu
        YesMenu_4                       matlab.ui.container.Menu
        NoMenu_4                        matlab.ui.container.Menu
        AutoCuK2Menu                    matlab.ui.container.Menu
        NoMenu_5                        matlab.ui.container.Menu
        YesMenu_5                       matlab.ui.container.Menu
        SavePreferenceFileMenu          matlab.ui.container.Menu
        menulabel_help                  matlab.ui.container.Menu
        menu_about                      matlab.ui.container.Menu
        menu_help                       matlab.ui.container.Menu
        checkUpdate                     matlab.ui.container.Menu
        CFLabel                         matlab.ui.control.Label
        HTML                            matlab.ui.control.HTML
        HTML2                           matlab.ui.control.HTML
        text55                          matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        LineProfilesPanel               matlab.ui.container.Panel
        UpdateButton                    matlab.ui.control.Button
        FitDataButton                   matlab.ui.control.Button
        NoBoundsCheckBox                matlab.ui.control.CheckBox
        RefineBkgCheckBox               matlab.ui.control.CheckBox
        RecycleCheckBox                 matlab.ui.control.CheckBox
        UITable2                        matlab.ui.control.Table
        SelectPeaksButton               matlab.ui.control.Button
        ConstraintsPanel                matlab.ui.container.Panel
        wCheckBox                       matlab.ui.control.CheckBox
        mCheckBox                       matlab.ui.control.CheckBox
        fCheckBox                       matlab.ui.control.CheckBox
        xCheckBox                       matlab.ui.control.CheckBox
        NCheckBox                       matlab.ui.control.CheckBox
        UITable                         matlab.ui.control.Table
        AsymmetryCheckBox               matlab.ui.control.CheckBox
        CuK2CheckBox                    matlab.ui.control.CheckBox
        NumberofPeaksSpinner            matlab.ui.control.Spinner
        NumberofPeaksSpinnerLabel       matlab.ui.control.Label
        BackgroundPanel                 matlab.ui.container.Panel
        PlotBackgroundButton            matlab.ui.control.Button
        SelectPointsButton              matlab.ui.control.Button
        PolyOrderSpinner                matlab.ui.control.Spinner
        PolyOrderSpinnerLabel           matlab.ui.control.Label
        EditModeButtonGroup             matlab.ui.container.ButtonGroup
        DeleteButton                    matlab.ui.control.RadioButton
        AddButton                       matlab.ui.control.RadioButton
        NewButton                       matlab.ui.control.RadioButton
        ModelDropDown                   matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        EditFieldWave                   matlab.ui.control.NumericEditField
        lambdaLabel                     matlab.ui.control.Label
        EditMax2T                       matlab.ui.control.NumericEditField
        EditMin2T                       matlab.ui.control.NumericEditField
        ThetaLabel                      matlab.ui.control.Label
        ResultsTab                      matlab.ui.container.Tab
        UITable3                        matlab.ui.control.Table
        AllFitsButton                   matlab.ui.control.Button
        FitStatisticsButton             matlab.ui.control.Button
        PlotViewsButtonGroup            matlab.ui.container.ButtonGroup
        CoefficientsButton              matlab.ui.control.RadioButton
        FilesListBox                    matlab.ui.control.ListBox
        PeakFitButton                   matlab.ui.control.RadioButton
        FilesListBoxLabel               matlab.ui.control.Label
        BayesianTab                     matlab.ui.container.Tab
        PlottingandResultsButtonGroup   matlab.ui.container.ButtonGroup
        PlotBayesFitButton              matlab.ui.control.Button
        FilesListBox_2                  matlab.ui.control.ListBox
        FilesListBox_2Label             matlab.ui.control.Label
        ParametersandBoundsButtonGroup  matlab.ui.container.ButtonGroup
        MultiplyLabel                   matlab.ui.control.Label
        UITable4                        matlab.ui.control.Table
        SDDropDown                      matlab.ui.control.DropDown
        SDDropDownLabel                 matlab.ui.control.Label
        LBUBDropDown                    matlab.ui.control.DropDown
        LBUBDropDownLabel               matlab.ui.control.Label
        CustomButton                    matlab.ui.control.RadioButton
        DefaultButton                   matlab.ui.control.RadioButton
        ControlsButtonGroup             matlab.ui.container.ButtonGroup
        PlotHistogramButton             matlab.ui.control.RadioButton
        IncludeBackgroundCheckBox       matlab.ui.control.CheckBox
        RunBayesianButton               matlab.ui.control.Button
        PlotTraceButton                 matlab.ui.control.RadioButton
        AutoCheckBox                    matlab.ui.control.CheckBox
        GibbsSamplingCheckBox           matlab.ui.control.CheckBox
        AccRatioLabel                   matlab.ui.control.Label
        SDEditField                     matlab.ui.control.NumericEditField
        UBEditField                     matlab.ui.control.NumericEditField
        LBEditField                     matlab.ui.control.NumericEditField
        SPEditField                     matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        BurninEditField                 matlab.ui.control.NumericEditField
        BurninEditFieldLabel            matlab.ui.control.Label
        SDEditFieldLabel                matlab.ui.control.Label
        UBEditFieldLabel                matlab.ui.control.Label
        LBEditFieldLabel                matlab.ui.control.Label
        SPEditFieldLabel                matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        button_browse                   matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        checkbox_reverse                matlab.ui.control.CheckBox
        edit8                           matlab.ui.control.TextArea
        text22                          matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        LIPRAS_Version="2.0"; % Version is Major.Minor, Keeps this as a string or else str2double may add an extra '0'
        profiles
        gui
        NoEqualData
        Min2T % Description
        Max2T
        a
        PointSelected
        KeyPressed
        BkgMode
        BkgSelect
        BkgCalcNext
        PeakSelect
        PeakPositions
        FitFunctions
        xrd0
        Asym
        Plotter
        XScale='linear'
        YScale='linear'
        prevCondsChanged=0;
        Coefficients
        FitInitial
        FitResults
        CheckBox0
        Fxn0
        TableC0 % For resetting/updating Coeff table
        UIAxesplot
        OrigCD
        BkgBackupPoints
        ObsStyleBackup
        axbayesid
        ScaleID
        Functions
        BD % Might need for Bayesian
        CF % curve fitting toolbox
        
    end
    
    properties (Access = private)
        SubplotPanel
        SubplotGrid
        SubplotAxes1
        SubplotAxes2 % Description
        axbayes
        LegendBayes
        debug=0 % Toggle debug mode
    end
    
    methods (Access = private)
        function ImagePref(~, o, ~, app, type)
            if strcmp(type,'DPI')
                val=o.Value;
                w=o.String(val);
                app.profiles.ImageRes=w{1};
            
            elseif strcmp(type,'Format')
                 val=o.Value;
                w=o.String(val);
                 app.profiles.ImageFormat=w{1};
            
            elseif strcmp(type,'ExportAll')
                val=o.Value;
                if val
                    app.profiles.ImageSaveAll=1;
                else
                    app.profiles.ImageSaveAll=0;
                end
            end
        end
        
        function SavePref(app)
                   PreferenceFile=fopen('Preference File.txt','w');
                        fprintf(PreferenceFile,'%s %s\n','OpenDirectory=',app.profiles.DataPath);
                        fprintf(PreferenceFile,'%s %s\n','Weights=',app.profiles.Weights);
                        fprintf(PreferenceFile,'%s %i\n','UniqueSave=',app.profiles.UniqueSave);
                        fprintf(PreferenceFile,'%s %s\n','ImageRes=',app.profiles.ImageRes);
                        fprintf(PreferenceFile,'%s %s\n','ImageFormat=',app.profiles.ImageFormat);
                        fprintf(PreferenceFile,'%s %i\n','ImageExportAll=',app.profiles.ImageSaveAll);
                        fprintf(PreferenceFile,'%s %f %f %f \n','GUIColor=',app.profiles.GUIColor(1),app.profiles.GUIColor(2),app.profiles.GUIColor(3));
                        fprintf(PreferenceFile,'%s %i\n','KalphaAuto=',app.profiles.KalphaAuto);

                        fclose all;
        end

        function LoadPref(app)
        % Preference File Read on LIPRAS start up
        try
            PrefFile=fopen('Preference File.txt','r');
            dat=fscanf(PrefFile,'%c');
            sdat=strsplit(dat,'\n');
            data_path=strsplit(sdat{1},'= ');
            rWeights=strsplit(sdat{2},'= ');
            rUniqueSave=strsplit(sdat{3},'= ');
            rImageRes=strsplit(sdat{4},'= ');
            rImageFormat=strsplit(sdat{5},'= ');
            rImageExportAll=strsplit(sdat{6},'= ');
            Gcolor=strsplit(sdat{7},'= ');
            kalpha_auto=strsplit(sdat{8},'= ');

            fclose(PrefFile);
            app.profiles.DataPath=data_path{2};
            app.profiles.Weights=rWeights{2};
            app.profiles.UniqueSave=str2double(rUniqueSave{2});
            app.profiles.ImageRes=rImageRes{2};
            app.profiles.ImageFormat=rImageFormat{2};
            app.profiles.ImageSaveAll=str2double(rImageExportAll{2});
            app.profiles.KalphaAuto=str2double(kalpha_auto{2});

            % Changes GUI Color, I like the white, but we can possibly do
            % Dark mode, might need to make it two options since this can
            % be a very stylistic thing
            Gc=str2num(Gcolor{2});
            app.figure1.Color= Gc;
            % app.SetupTab.BackgroundColor=Gc;
            app.SetupTab.ForegroundColor=[0 0 0];
            % app.BackgroundPanel.BackgroundColor=Gc;
            % app.BackgroundPanel.ForegroundColor=Gc;
            % app.BackgroundPanel.BorderColor=Gc;
            app.ResultsTab.BackgroundColor=Gc;
            % app.ResultsTab.ForegroundColor=[0 0 0];
            app.LineProfilesPanel.BackgroundColor=Gc;
            % app.LineProfilesPanel.BorderColor=Gc;
            app.PlotViewsButtonGroup.BackgroundColor=Gc;
            % app.PlotViewsButtonGroup.BorderColor=Gc;
            app.EditModeButtonGroup.BackgroundColor=Gc;
            % app.EditModeButtonGroup.BorderColor=Gc;
        catch
            disp('Preference file may not have fully loaded')
        end

        % Set All Relevant Menu Items

        % rUniqueSave
        if app.profiles.UniqueSave == 1
            app.YesMenu_3.Checked = 1;   % check Yes
            app.NoMenu_3.Checked  = 0;  % uncheck No
        end

        % Weights
        weightOption = strtrim(rWeights{2});  % clean leading/trailing whitespace
        set(app.LeastSquaresWeightsMenu.Children, 'Checked', 'off'); % Reset all checked boxes
        if ~strcmp(weightOption, 'Default')
            switch weightOption
                case 'None'
                    % Do what’s needed for 'None'
                    app.NoneMenu_2.Checked=1;
                case '1/obs'
                    % Action for inverse of observations
                    app.obsMenu_2.Checked=1;
                case '1/sqrt(obs)'
                    % Action for inverse sqrt of observations
                    app.sqrtobsMenu_2.Checked=1;
                otherwise
                    warning('Unrecognized weight option: %s', weightOption);
                    % Optional: fall back to default behavior or raise an error
                    app.DefaultMenu_2.Checked=1;

            end
        else
                    app.DefaultMenu_2.Checked=1;
        end

        % ImageRes
        ImageRes = strtrim(rImageRes{2});  % clean leading/trailing whitespace
        set(app.ImageResolutionMenu.Children, 'Checked', 'off'); % Reset all checked boxes
        if ~strcmp(ImageRes, 'Default')
            switch ImageRes
                case '100 DPI'
                    % Do what’s needed for 'None'
                    app.DPIMenu_6.Checked=1;
                case '200 DPI'
                    % Action for inverse of observations
                    app.DPIMenu_7.Checked=1;
                case '300 DPI'
                    % Action for inverse sqrt of observations
                    app.DPIMenu_8.Checked=1;
                otherwise
                    warning('Unrecognized image DPI: %s', ImageRes);
                    % Optional: fall back to default behavior or raise an error
                    app.DPIMenu_6.Checked=1;

            end
        else
                    app.DPIMenu_6.Checked=1;
        end

        %ImageType
        ImageType = strtrim(rImageFormat{2});  % clean leading/trailing whitespace
        set(app.ImageTypeMenu.Children, 'Checked', 'off'); % Reset all checked boxes
        if ~strcmp(ImageType, 'Default')
            switch ImageType
                case 'TIFF'
                    % Do what’s needed for 'None'
                    app.TIFFMenu_2.Checked=1;
                case 'JPEG'
                    % Action for inverse of observations
                    app.JPEGMenu_2.Checked=1;
                case 'PNG'
                    % Action for inverse sqrt of observations
                    app.PNGMenu_2.Checked=1;
                case 'PDF'
                    % Action for inverse sqrt of observations
                    app.PDFMenu_2.Checked=1;
                otherwise
                    warning('Unrecognized image DPI: %s', ImageType);
                    % Optional: fall back to default behavior or raise an error
                    app.TIFFMenu_2.Checked=1;

            end
        else
                    app.TIFFMenu_2.Checked=1;
        end

        % SaveAll
        if app.profiles.ImageSaveAll == 1
            app.YesMenu_4.Checked = 1;   % check Yes
            app.NoMenu_4.Checked  = 0;  % uncheck No
        end

        %AutoKalpha
        if app.profiles.KalphaAuto == 1
            app.YesMenu_5.Checked = 1;   % check Yes
            app.NoMenu_5.Checked  = 0;  % uncheck No
        end

        end
        
        function vali = Validate_bkg(app)
            
                        if isempty(app.profiles.xrd.getBackgroundPoints)
                            app.HTML.HTMLSource=app.formatHTML('Select background points...');
                            vali=1;
                            return
                        elseif length(app.profiles.xrd.getBackgroundPoints) <= app.profiles.xrd.getBackgroundOrder
                              d = dialog('Position',[300 500 300 120],'Name','Warning:');
                        txt = uicontrol('Parent',d,...
                          'Style','text',...
                          'Position',[25 10 270 100],...
                          'String',{'Polynomial order is greater than or equal to the number of points selected, add more background points or reduce polynomial order'},'FontSize',10);
                        btn1 = uicontrol('Parent',d,...
                       'Position',[100 10 100 30],...
                       'String','Ok','FontSize',11,...
                       'Callback','delete(gcf)');
                            vali=1;
                            app.HTML.HTMLSource=app.formatHTML('Select more background points...');

                            return
                        else
                            vali=0;
                        end

        end
        
        function results = disableFitData(app)
            app.UITable2.Enable="off";
            app.FitDataButton.Enable='off';
        end
        
        function exportPlotAsImage(app)
            % Export current or all plots based on app settings
            formatMap = struct( ...
                'TIFF', '-dtiff', ...
                'JPEG', '-djpeg', ...
                'PNG',  '-dpng', ...
                'PDF',  '-dpdf' ...
            );
        
            imgFormat = upper(app.profiles.ImageFormat);
            dpiValue = str2double(app.profiles.ImageRes(1:3));
        
            if ~isfield(formatMap, imgFormat)
                errordlg(sprintf('Unsupported format: %s', imgFormat), 'Export Error');
                return;
            end
        
            printFlag = formatMap.(imgFormat);
        
            % Create output directory
            outpath = fullfile(app.profiles.Writer.OutputPath, 'ExportedImages');
            if app.profiles.UniqueSave
                index = 0;
                prefix = '00';
                while exist(fullfile(outpath, ['Image_' prefix num2str(index)]), 'dir') == 7
                    index = index + 1;
                    if index > 100
                        prefix = '';
                    elseif index > 10
                        prefix = '0';
                    end
                end
                outpath = fullfile(outpath, ['Image_' prefix num2str(index)]);
            end
        
            if ~exist(outpath, 'dir')
                mkdir(outpath);
            end
        
            oldpath = pwd;
            cd(outpath);

            lineStyles  = arrayfun(@(h) h.LineStyle,  app.UIAxes.Children, 'UniformOutput', false);
            markerTypes = arrayfun(@(h) h.Marker,     app.UIAxes.Children, 'UniformOutput', false);
            markerSizes = arrayfun(@(h) h.MarkerSize, app.UIAxes.Children);
            markerFills = arrayfun(@(h) h.MarkerFaceColor, app.UIAxes.Children, 'UniformOutput', false);
        
            try
                if ~app.profiles.ImageSaveAll || app.CoefficientsButton.Value
                    % === SINGLE IMAGE EXPORT ===
                    filename = app.gui.FileNames{app.gui.CurrentFile};
                    [~, filename, ~] = fileparts(filename);
                    if app.CoefficientsButton.Value
                        filename = [filename '-Coeffs'];
                    end
                    % Get pixel sizes and positions
                    p1 = app.UIAxes.Position;   % [left, bottom, width, height]
                    p2 = app.UIAxes2.Position;
                    
                    % Determine total figure height needed
                    totalWidth  = max(p1(3), p2(3));
                    totalHeight = p1(4) + p2(4);
                    
                    % Create figure with exact size
                    fig = figure('Visible', 'off', 'Units', 'pixels', ...
                                 'Position', [100, 100, totalWidth, totalHeight], ...
                                 'PaperPositionMode', 'auto');
                    
                    % Copy axes
                    ax1 = copyobj(app.UIAxes, fig);
                    ax2 = copyobj(app.UIAxes2, fig);
                    
                    % Position axes exactly in stacked layout (lower-left corner origin)
                    set(ax1, 'Units', 'pixels', 'Position', [0, p2(4), p1(3), p1(4)]);  % top
                    set(ax2, 'Units', 'pixels', 'Position', [0, 0,     p2(3), p2(4)]);  % bottom
                    
                    % Optional: adjust styling
                    legend(ax1, 'show', 'Location', 'northeast', 'Box', 'off', 'FontSize', 9);
                    legend(ax2, 'show', 'Location', 'northeast', 'Box', 'off', 'FontSize', 9);
                    ax1.FontSize = 12; ax2.FontSize = 12;
                    
                    drawnow;
                    
                    % Export
                    print(fig, filename, printFlag, ['-r' num2str(dpiValue)]);
                    close(fig);

                else

                % === MULTIPLE IMAGE EXPORT USING UIAxes LAYOUT (Centered) ===
                
                % Get dimensions from UIAxes
                p1 = app.UIAxes.Position;    % Top plot
                p2 = app.UIAxes2.Position;   % Bottom plot
                
                % Use max width and combined height
                plotWidth  = max(p1(3), p2(3));
                plotHeight = p1(4) + p2(4);
                gap = 60;  % Vertical gap between plots
                
                % Add margins
                hMargin = 70;  % left/right margin
                vMargin = 50;  % top/bottom margin
                leftShift = 90;  % increase to shift more right

                figWidth  = plotWidth  + 2 * hMargin;
                figHeight = plotHeight + gap + 2 * vMargin;
                
                tt = app.profiles.getProfileResult{1}.TwoTheta;
                
                for jj = 1:numel(app.DropDown.Items)
                    filename = app.gui.FileNames{jj};
                    [~, baseName, ~] = fileparts(filename);
                
                    data  = app.profiles.xrd.getData(jj);
                    fit   = app.profiles.getProfileResult{jj}.FData;
                    bkg   = app.profiles.getProfileResult{jj}.Background;
                    peaks = app.profiles.getProfileResult{jj}.FPeaks;
                    % yy    = [data; fit; bkg; peaks + bkg];

                    if app.profiles.xrd.BkgLS == 1
                        yy = [data; fit; bkg; peaks + bkg];
                        fit_plot   = fit;
                        peaks_plot = peaks + bkg;
                    else
                        yy = [data; fit + bkg; bkg; peaks + bkg];
                        fit_plot   = fit + bkg;
                        peaks_plot = peaks + bkg;
                    end

                    residuals = data - fit;
                
                    % Create figure
                    fig = figure('Visible', 'off', 'Units', 'pixels', ...
                                 'Position', [100, 100, figWidth, figHeight], ...
                                 'PaperPositionMode', 'auto');
                
                    % Top axis (UIAxes)
                    ax1 = axes('Parent', fig, 'Units', 'pixels', ...
                               'Position', [leftShift, vMargin + p2(4) + gap, p1(3), p1(4)]);
                    hold(ax1, 'on');
                    plot(ax1, tt, data, "LineStyle",lineStyles{5},"Marker",markerTypes{5}, 'MarkerSize',markerSizes(5),'MarkerFaceColor',markerFills{5}, 'Color', [0.1 0.3 0.9]);
                    plot(ax1, tt, fit_plot, '.-', 'LineWidth', 1.5, 'Color', [0 0.6 0]);
                    plot(ax1, tt, bkg, '--', 'LineWidth', 1.5, 'Color', [1 0 0]);
                    plot(ax1, tt, peaks_plot, '--', 'LineWidth', 1.5, 'Color', [0.6 0 0.6]);
                    hold(ax1, 'off');
                    title(ax1, baseName, 'Interpreter', 'none');
                    box(ax1, 'on');
                    legend(ax1, {'Data','Fit','Background','Peaks+Background'}, ...
                           'Location','northeast','Box','off','FontSize',12);
                    xlabel(ax1, '2\theta');
                    ylabel(ax1, 'Intensity (a.u.)');
                    ax1.FontSize = 12;
                    xlim(ax1, app.UIAxes.XLim);
                    ylim(ax1, app.UIAxes.YLim);

                    % Bottom axis (UIAxes2)
                    ax2 = axes('Parent', fig, 'Units', 'pixels', ...
                               'Position', [leftShift, vMargin, p2(3), p2(4)]);
                    plot(ax2, tt, residuals, '-', 'LineWidth', 1.5, 'Color', 'r');
                    legend(ax2, 'Residuals (Data - Fit)', ...
                           'Location','northeast','Box','off','FontSize',9);
                    xlabel(ax2, '2\theta');
                    ylabel(ax2, 'Obs-Calc');
                    ax2.FontSize = 12;
                    xlim(ax2, app.UIAxes.XLim);

                    drawnow;
              
                    % Export
                    outname = fullfile(outpath, [baseName '.' lower(app.profiles.ImageFormat)]);
                    print(fig, outname, printFlag, ['-r' num2str(dpiValue)]);
                
                    close(fig);
                end

                end
        
                app.gui.PriorityStatus = sprintf('Successfully exported plot(s) as %s (%d DPI).', imgFormat, dpiValue);
            catch ME
                warning('Export failed: %s');
            end
        
            cd(oldpath);
        end
  
        function results = toolbar_legend_OnCallback(app)
                    % Turns on the legend.
            app.gui.Legend = 'on';
            app.gui.Legend = 'reset';
        end
        
        function results = toolbar_legend_OffCallback(app)
            app.gui.Legend = 'off';
        end
        
        function results = toolbar_legend_ClickedCallback(app)
            if strcmpi(app.State,'on')
                toolbar_legend_OnCallback(app);
            else
                toolbar_legend_OffCallback(app);
            end
        end
        
        function html = formatHTML(~,msg)
            html = sprintf('<div align="right"><font size="2" face="Helvetica"><i>%s</i></font></div>', msg);

        end

        function results = BayesPanel(app)
                    % Get positions of existing axes
            pos1 = app.UIAxes.Position;
            pos2 = app.UIAxes2.Position;
        
            % Calculate combined bounding box
            x = min(pos1(1), pos2(1));
            y = min(pos1(2), pos2(2));
            right = max(pos1(1)+pos1(3), pos2(1)+pos2(3));
            top   = max(pos1(2)+pos1(4), pos2(2)+pos2(4));
        
            width = right - x;
            height = top - y+2; % =2 is so uipanel covers plot zoom optionf rom UIAxes
            boundingBox = [x y width height];
        
            % Create a hidden panel at the combined position
            app.SubplotPanel = uipanel(app.figure1, ...
                'Position', boundingBox, ...
                'Visible', 'off');
        
            % Create a 1x2 grid layout inside the panel
            app.SubplotGrid = uigridlayout(app.SubplotPanel, [1 2]);
            app.SubplotGrid.ColumnWidth = {'1x', '1x'};
            app.SubplotGrid.RowHeight = {'1x'};
            app.SubplotGrid.Padding = [0 0 0 0];
        end

    function clearHtmlMessage(app)
        if isvalid(app) && isvalid(app.HTML)
            app.HTML.HTMLSource = app.formatHTML("");   % or app.HTML.HTMLSource = ""
        end
    end
    
        
end

    methods (Access = public)
        
        function result = isFitDirty(app)
            %ISFITDIRTY returns TRUE if the selected options above the 'UPDATE' button (i.e. the fit
        %   parameters) don't match the table_fitinitial coefficients.
        result = false;
        xrd = app.profiles.xrd;
        if isempty(xrd)
            return
        end
        if app.profiles.hasFit
            fitted = app.profiles.getProfileResult{1};
                    if xrd.BkgLS
                        if ~isequal(app.profiles.FitInitial.coeffs, xrd.getCoeffs)
                            result = true;
                            xrd.BkgLS=0;
                            xrd.ignore_bounds=0;
                        end
                        
                    else % For when not using BkgLS since it works, reversed or
                        % add transpose to xrd.getFunctionNames on
                        % 07-09-2020
                        if isequal(app.FcnNames, xrd.getFunctionNames') && ... % this elseif is to yield a result=false after completing a fit, reversed order of this and subsequent on 3-4-2017
                            isequal(app.Coefficients, xrd.getCoeffs) && ...
                            isequal(fitted.CoeffValues, app.FitResults{1}) % troubleshoot, this needs to compare to last stored fit, 07-09-2020
                            result = false;
                        elseif ~isequal(fitted.FunctionNames, app.FcnNames) || ...
                            ~isequal(fitted.CoeffNames, app.Coefficients) || ...
                            ~isequal(fitted.FitInitial.start, app.FitInitial.start)
                            result = true;                            
                        elseif isequal(app.FcnNames, xrd.getFunctionNames) || ... % this else if is to yield result=true when a constaint has been checked
                            isequal(app.Coefficients, xrd.getCoeffs) || ...
                            isequal(app.FitInitial.start, xrd.FitInitial.start)
                        result = true;
                        end
                    end
        else
            result = true;
        end
        
        end
        
        function value = areFuncsReady(app)
                    if isempty(find(cellfun(@isempty, app.FcnNames), 1))
                        % No blank cells in table
                        value = true;
                    else
                        value = false;
                    end
        end
        
        function h = FcnNames(app)
                    % Cell array of the fit function names.
                h = app.UITable.Data;
        end
  
        function setTable(this,input)
        
        hObject = this.UITable2;
        % Clear the table if input is empty
        if isempty(input)
            hObject.Data = cell(size(hObject.Data));
            return
            
        elseif isnumeric(input)
            if length(input) ~= length(hObject.Data(:,1))
                msg = 'Value must be the same length as table';
                throw(MException('LIPRAS:ProfileGUIManager:FitInitial:InvalidLength', msg))
            end
            input = num2cell(input);
            hObject.Data = input;
            
        elseif isstruct(input)
            if isfield(input, 'start') && ~isempty(input.start) && all(this.TableC0==0)
                 startpoints = num2cell(input.start)';
                 emptyIdx = cellfun(@(c)c<0, startpoints);
                 emptyIdx(contains(input.coeffs,{'x' 'a'})==1)=0; % allows for negative peak positions             
                 startpoints(emptyIdx) = {[]};
                 hObject.Data(:, 1) = startpoints;
            else
                hObject.Data(:, 1) = cell(size(hObject.Data,1),1);
            end
            if isfield(input, 'lower') && ~isempty(input.lower) && all(this.TableC0==0)
                lower = num2cell(input.lower)';
                emptyIdx = cellfun(@(c)c<0, lower);
                 emptyIdx(contains(input.coeffs,{'x' 'a'})==1)=0; % allows for negative peak positions             
                lower(emptyIdx) = {[]};
                hObject.Data(:, 2) = lower;
            else
                hObject.Data(:, 2) = cell(size(hObject.Data,1),1);
            end
            if isfield(input, 'upper') && ~isempty(input.upper) && all(this.TableC0==0)
                upper = num2cell(input.upper)';
                emptyIdx = cellfun(@(c)c<0, upper);
                 emptyIdx(contains(input.coeffs,{'x' 'a'})==1)=0; % allows for negative peak positions             
                upper(emptyIdx) = {[]};
                hObject.Data(:, 3) = upper;
            else
                hObject.Data(:, 3) = cell(size(hObject.Data,1),1);
            end
        
        elseif ~iscell(input)
            msg = 'Value must be a numeric array.';
            throw(MException('LIPRAS:ProfileGUIManager:FitInitial:InvalidType', msg))
        end
            
            
        end
        
        function results = PeakSelectChange(app)
            try
                if isequal(app.Fxn0, app.UITable.Data) 
                    app.TableC0=0;
                else
                    app.TableC0=1;
                    app.UITable2.Data=[];
                    app.FitDataButton.Enable=0;
                end
            catch ME
                
            end
        end
        
        function results = SelectPointsX(app, mode)
            obj = hittest(app.figure1);
            % 1) Must be inside app.UIAxes
            ax = ancestor(obj,'matlab.ui.control.UIAxes');
            if isempty(ax) || ax ~= app.UIAxes
                return
            end
            results = app.UIAxes.CurrentPoint(1,1);

            if strcmp(mode,'Peaks')
                dataLine = findobj(app.UIAxes, 'tag', 'Obs');
                xdata = dataLine.XData; ydata = dataLine.YData;
                idx = utils.findIndex(xdata, results);
                plot(app.UIAxes, results, ydata(idx), 'xr', 'MarkerSize', 15,'LineWidth',2,'Tag','PeakS');
            end

        end
        
        function results = BayesPlot(app,idF)
            
            
            % Optimize Subplot array based on coeff numbers
            nPlots = length(app.BD.coeff)+1; % example
            nside  = ceil(sqrt(nPlots)); % smallest square that fits all
            subD=nside;
            N = app.BD.iterations-app.BD.burnin;          % number of samples
            nbins = ceil(sqrt(N));   % square‑root rule            
            
            app.AccRatioLabel.Text = "σ² Acc. Ratio = " + string(round(app.BD.accS(:,1),3));

            % Plotting Time

            % Prep Subplot
            nMax  = subD^2;                     % Maximum number of axes
            nPar  = numel(app.BD.SP);           % Number of parameters
            
            % --- Safety check
            if nPar + 1 > nMax
                warndlg('Too many parameters to plot, check individual files', ...
                        '!! Warning !!');
                return
            end
            
            % --- Clear previous layout (if it exists)
            if ~isempty(app.SubplotGrid) && isvalid(app.SubplotGrid)
                delete(app.SubplotGrid)
            end
            
            % --- Create grid layout (once)
            app.SubplotGrid = uigridlayout(app.SubplotPanel, [subD subD]);
            app.SubplotGrid.RowHeight    = repmat({'1x'}, 1, subD);
            app.SubplotGrid.ColumnWidth = repmat({'1x'}, 1, subD);
            app.SubplotGrid.Padding     = [2 2 2 2];
            
            % --- Preallocate axes handles
            app.axbayes = gobjects(nPar + 1, 1);
            
            names = app.profiles.FitResults{1}{idF}.FitInitial.coeffs;
            nBkg  = sum(contains(names,'bkg')); % num of bkg coefficients
            if app.IncludeBackgroundCheckBox.Value
                nBkg=0; % this goes to zero because then you want all of coeffs in app.profiles.FitResults{1}{idF}.CoeffError(k+nBkg);
            end

            app.SubplotPanel.Visible="on"; % Turns Bayesian plot off when tabing away

            % Plot Histogram
            if strcmp(app.ControlsButtonGroup.SelectedObject.Text, 'Plot Histogram')
            
            
                % Loop through parameters
                for k = 1:nPar
            
                    % Create a new UI axes in your grid
                    app.axbayes(k) = uiaxes(app.SubplotGrid);
            
                    % Get histogram data (post‑burnin)
                    data = app.BD.param_trace(app.BD.burnin:end, k, idF);
            
                    % Plot histogram
                    v = histogram(app.axbayes(k), data, nbins);
                    title(app.axbayes(k), app.BD.coeffOrig{k}, 'Interpreter', 'none');
            
                    % Overlay fit coefficient with error
                    hold(app.axbayes(k), 'on')
            
                    % Get fit values
                    coeffValue = app.profiles.FitResults{1}{idF}.CoeffValues(k+nBkg);
                    coeffError = app.profiles.FitResults{1}{idF}.CoeffError(k+nBkg);
            
                    % Plot horizontal error bar
                    yMax = max(v.BinCounts) * 1.1;
                    errorbar(app.axbayes(k), coeffValue, yMax, coeffError, 'horizontal', 'o', 'MarkerSize', 5, 'MarkerFaceColor', 'red', 'Color', 'black');            
                    hold(app.axbayes(k), 'off')
            
                    % Basic formatting
                    grid(app.axbayes(k), 'on')
                    box(app.axbayes(k), 'on')
            
                end


            else % Ploting Trace
            
            % --- Plot parameter traces
            for k = 1:nPar
                app.axbayes(k) = uiaxes(app.SubplotGrid);
                yp=app.BD.param_trace(app.BD.burnin:end, k, idF);
                plot(app.axbayes(k), yp, 'LineWidth', 1,'Color',[0.08 0.17 0.65]);
            
                title(app.axbayes(k), app.BD.coeffOrig{k}, ...
                      'Interpreter', 'none');
            
                grid(app.axbayes(k), 'on')
                box(app.axbayes(k), 'on');
                app.axbayes(k).XLimMode = 'auto';
                dy = (max(yp) - min(yp)) * 0.1;
                app.axbayes(k).YLim = [min(yp)-dy, max(yp)+dy];
                pause(0.1) % Need to wait
            end
            
            % --- Plot Log-Likelihood (last panel)
            kLL = nPar + 1;
            app.axbayes(kLL) = uiaxes(app.SubplotGrid);
            
            plot(app.axbayes(kLL), ...
                 app.BD.logp_trace(app.BD.burnin:end, 1, idF), ...
                 'LineWidth', 1,'Color',[0 .4 0]);
            
            title(app.axbayes(kLL), 'Log Likelihood')
            grid(app.axbayes(kLL), 'on')
            box(app.axbayes(kLL), 'on');
            
            % --- Link x-axes across all plots
            linkaxes(app.axbayes, 'x')

            end

            app.axbayesid=0; % Id so YScale doest not trigger on bayesplots
            app.HTML.HTMLSource=app.formatHTML('Y-Scale and Math are turned OFF for Bayes Plot');


        end
        
        function results = transformData(app,fig,mode)    %TRANSFORMDATA Apply or revert transformation on plot data
            %TRANSFORMDATA Apply or revert transformation to all subplots in app.axbayes
            %   mode = 'sqrt', 'log', or 'linear'
        
            % Find all axes inside app.axbayes (in case of subplot-style layout)
            allAxes = findall(app.axbayes, 'Type', 'axes');
        
            for a = 1:numel(allAxes)
                ax = allAxes(a);
        
                % ---- Transform line objects in this axes ----
                lines = findall(ax, 'Type', 'line');
                for k = 1:numel(lines)
                    line = lines(k);
                    if ~isfield(line.UserData, 'OriginalY')
                        line.UserData.OriginalY = line.YData;
                    end
                    y_orig = line.UserData.OriginalY;
        
                    switch mode
                        case 'sqrt'
                            y2 = NaN(size(y_orig));
                            idx = y_orig >= 0;
                            y2(idx) = sqrt(y_orig(idx));
                        case 'log'
                            y2 = NaN(size(y_orig));
                            idx = y_orig > 0;
                            y2(idx) = log10(y_orig(idx));
                        case 'linear'
                            y2 = y_orig;
                        case 'Derivative'
                            % dy/dx using the line's XData spacing
                            x = line.XData;
                            y2 = gradient(y_orig, x);                
                        case 'Integral'
                            % cumulative integral ∫ y dx using the line's XData spacing
                            x = line.XData;
                            y2 = cumtrapz(x, y_orig);

                        otherwise
                            error('Unknown mode: %s. Use "sqrt", "log","Derivative","Integral", or "linear".', mode);
                    end
        
                    line.YData = y2;
                end
        
                % ---- Transform patch objects in this axes ----
                patches = findall(ax, 'Type', 'patch');
                for k = 1:numel(patches)
                    patchObj = patches(k);
                    if ~isfield(patchObj.UserData, 'OriginalY')
                        patchObj.UserData.OriginalY = patchObj.YData;
                    end
                    y_orig = patchObj.UserData.OriginalY;
        
                    switch mode
                        case 'sqrt'
                            y2 = NaN(size(y_orig));
                            idx = y_orig >= 0;
                            y2(idx) = sqrt(y_orig(idx));
                        case 'log'
                            y2 = NaN(size(y_orig));
                            idx = y_orig > 0;
                            y2(idx) = log10(y_orig(idx));
                        case 'linear'
                            y2 = y_orig;
                        case 'Derivative'
                            x = patchObj.XData(:).';
                            y = y_orig(:).';
                        
                            n = numel(x)/2;
                            xu = x(1:n);             xl = fliplr(x(n+1:end));
                            yu = y(1:n);             yl = fliplr(y(n+1:end));
                        
                            dyu = gradient(yu, xu);
                            dyl = gradient(yl, xl);
                        
                            y2 = [dyu, fliplr(dyl)];             
                        case 'Integral'
                            x = patchObj.XData(:).';
                            y = y_orig(:).';
                        
                            n = numel(x)/2;
                            xu = x(1:n);             xl = fliplr(x(n+1:end));
                            yu = y(1:n);             yl = fliplr(y(n+1:end));
                        
                            iu = cumtrapz(xu, yu);
                            il = cumtrapz(xl, yl);
                        
                            y2 = [iu, fliplr(il)];
                    end
        
                    patchObj.YData = y2;
                end
        
            % ---- Y label ----
            ax.YLabel.Interpreter = 'latex';
            switch mode
                case 'linear'
                    ax.YLabel.String = 'Intensity (a.u.)';
                    ax.YLabel.Interpreter = 'none';
                case 'sqrt'
                    ax.YLabel.String = '$\sqrt{I}\ \mathrm{(a.u.)}$';
                case 'log'
                    ax.YLabel.String = '$\log_{10}(I)\ \mathrm{(a.u.)}$';
            end

                % Auto Y-limits
                ylim(ax, 'auto');
            end
            
            results = true;
        end


        function setTheme(app,isDark)

        % palette
        if isDark
            bg    = [0.3 0.3 0.3];
            panel = bg;
            edit  = [0.7 0.7 0.7];
            btn   = [0.18 0.18 0.18];
            fg    = [0.9 0.9 0.9];
            gridc = [0.25 0.25 0.25];
            plotarea =fg;
        else
            bg    = [1.00 1.00 1.00];
            panel = [0.95 0.95 0.95];
            edit  = [1.00 1.00 1.00];
            btn   = [0.94 0.94 0.94];
            fg    = [0.10 0.10 0.10];
            gridc = [0.80 0.80 0.80];
            plotarea= [0.96 0.96 0.96];
        end
        
        app.figure1.Color = bg;
        
        kids = findall(app.figure1);
        
        for k = 1:numel(kids)
            h = kids(k);
        
            % containers
            if isa(h,'matlab.ui.container.Panel') || isa(h,'matlab.ui.container.Tab') || isa(h,'matlab.ui.container.TabGroup')
                try, h.BackgroundColor = panel; end
                try, h.ForegroundColor = fg; end
        
            % labels
            elseif isa(h,'matlab.ui.control.Label')
                try, h.FontColor = fg; end
        
            % buttons
            elseif isa(h,'matlab.ui.control.Button') || isa(h,'matlab.ui.control.ToggleButton') || isa(h,'matlab.ui.control.StateButton')
                try, h.BackgroundColor = btn; end
                try, h.FontColor = fg; end
        
            % inputs
            elseif isa(h,'matlab.ui.control.EditField') || isa(h,'matlab.ui.control.NumericEditField') || ...
                   isa(h,'matlab.ui.control.TextArea')  || isa(h,'matlab.ui.control.DropDown') || ...
                   isa(h,'matlab.ui.control.Spinner')   || isa(h,'matlab.ui.control.ListBox')
                try, h.BackgroundColor = edit; end
                try, h.FontColor = fg; end
        
            % table
            elseif isa(h,'matlab.ui.control.Table')
                try, h.BackgroundColor = edit; end
                try, h.FontColor = fg; end
                try, h.GridColor = gridc; end
        
            end
        end
        
        % UIAxes: only change text/labels (not axes background/grid)
        ax = findall(app.figure1,'Type','axes');
        for k = 1:numel(ax)
            a = ax(k);
            try, a.XColor = fg; end
            try, a.YColor = fg; end
            try, a.ZColor = fg; end
            try, a.Title.Color  = fg; end
            try, a.XLabel.Color = fg; end
            try, a.YLabel.Color = fg; end
            try, a.Color = plotarea; end
        
        end

        end

        function [commitCount, latestTag, latestName, latestDate,latestUrl ]=checkGIT(~)
            
        % Define repository details
        owner = 'SneakySnail';
        repo  = 'LIPRAS';
        
        % GitHub API URL for commits
        % We ask for 1 per page to make the total pages equal the total commits
        apiUrl = sprintf('https://api.github.com/repos/%s/%s/commits?per_page=1', owner, repo);
        
        % Set up weboptions (GitHub API requires a User-Agent)
        options = weboptions('HeaderFields', {'User-Agent', 'MATLAB-Client'});
        
        try
            % Use matlab.net.http to access the 'Link' response header
            import matlab.net.http.*
            import matlab.net.http.field.*
        
            request = RequestMessage();
            response = request.send(apiUrl);
        
            % Find the 'Link' header which contains pagination info
            linkHeader = response.Header.getFields('Link');
            
            if isempty(linkHeader)
                % If there's only one page of results, the Link header is omitted
                % In this case, we just count the items in the body
                data = webread(apiUrl, options);
                commitCount = numel(data);
            else
                % Extract the "last" page number using regex
                % Example header: <...&page=2>; rel="next", <...&page=504>; rel="last"
                linkStr = linkHeader.Value;
                tokens = regexp(linkStr, 'page=(\d+)>; rel="last"', 'tokens');
                
                if ~isempty(tokens)
                    commitCount = str2double(tokens{1}{1});
                else
                    % Fallback if "last" isn't found (already on the last page)
                    commitCount = 1; 
                end
            end
            
            % fprintf('Total commits for %s/%s: %d\n', owner, repo, commitCount);
        
        catch ME
            warning('Failed to retrieve commit count: %s', ME.message);
        end

        %Release
        
                options = weboptions('HeaderFields', {'User-Agent', 'MATLAB-Client'}, 'Timeout', 15);
                base = sprintf('https://api.github.com/repos/%s/%s', owner, repo);
        
                latestTag = '';
                latestName = '';
                latestDate = '';
                latestUrl = '';
        
                % Try latest release first
                try
                    r = webread([base '/releases/latest'], options);
                    latestName = string(r.name);
                    latestTag  = string(r.tag_name);
                    latestDate = string(r.published_at);
                    latestUrl  = string(r.html_url);
                catch
                    % Fallback: latest tag
                    try
                        t = webread([base '/tags?per_page=1'], options);
                        if ~isempty(t)
                            latestTag = string(t(1).name);
                            sha = string(t(1).commit.sha);
        
                            c = webread([base '/commits/' char(sha)], options);
                            latestName = latestTag;
                            latestDate = string(c.commit.author.date);
                            latestUrl  = "https://github.com/" + owner + "/" + repo + "/releases/tag/" + latestTag;
                        end
                    catch ME2
                        warning('Failed to retrieve latest release/tag: %s', ME2.message);
                    end
                end


        end

        function [hasUpdate, latestVerStr, latestDateStr, urlStr] = checkFEX(app)
    urlStr = "https://www.mathworks.com/matlabcentral/fileexchange/183300-line-profile-analysis-software-lipras";
    html   = char(webread(urlStr, weboptions('Timeout', 10)));

    % Remove scripts/styles (often huge) then strip tags -> plain text
    html = regexprep(html, '<script[\s\S]*?</script>', ' ');
    html = regexprep(html, '<style[\s\S]*?</style>',  ' ');
    txt  = regexprep(html, '<[^>]+>', ' ');           % remove tags
    txt  = regexprep(txt, '&nbsp;|&#160;', ' ');
    txt  = regexprep(txt, '\s+', ' ');                % normalize whitespace

    % Find the Version History header (now in plain text)
    hdr = 'Version Published Release Notes';
    k = strfind(txt, hdr);

    if isempty(k)
        hasUpdate=false; latestVerStr="unknown"; latestDateStr="unknown";
        return
    end

    tail = txt(k(1):min(length(txt), k(1)+3000));

    % First "version date" pair after the header
    tok = regexp(tail, '(\d+(?:\.\d+)*)\s+(\d{1,2}\s+[A-Za-z]{3}\s+\d{4})', ...
        'tokens', 'once');

    if isempty(tok)
        hasUpdate=false; latestVerStr="unknown"; latestDateStr="unknown";
        return
    end

    latestVerStr  = string(tok{1});
    latestDateStr = string(tok{2});

        v=lipras_local_version(app);
        try         currentVersion = v; 
        catch 
            currentVersion = app.LIPRAS_Version; 
        end

        cur = str2double(string(currentVersion));
        lat = str2double(string(latestVerStr));
        
        if isnan(cur) || isnan(lat)
            hasUpdate = false;   % fallback if parsing fails
        else
            hasUpdate = lat > cur;
        end
        
        end

    function v = lipras_local_version(~)
    % Read version from VERSION.txt shipped with the release
    
    v = "unknown";
    
    % 1) current directory
    vf = fullfile(pwd, "VERSION.txt");
    if exist(vf,"file")
        v = strtrim(string(fileread(vf)));
        if strlength(v) > 0, return; end
    end
    
    % 2) folder where this function lives (safer fallback)
    here = fileparts(mfilename('fullpath'));
    vf = fullfile(here, "VERSION.txt");
    if exist(vf,"file")
        v = strtrim(string(fileread(vf)));
        if strlength(v) == 0, v = "unknown"; end
    end
    end

    function SigFig(app) % Tries to make decimal places consistent
        step = app.profiles.xrd.Step2T;
    
        if ~isfinite(step) || step <= 0
            step = 0.002;
        end
        

if step >= 0.9
    % ~1
    n = 1;

elseif step >= 0.09
    % 0.1 .. 0.9
    n = 1;

elseif step >= 0.01
    % 0.01 .. 0.099
    n = 2;

elseif step >= 0.001
    % 0.001 .. 0.0099
    n = 4;

else
    n = 4;
end
    
        fmt = "%." + string(15) + "g";
        app.EditMin2T.ValueDisplayFormat = fmt;
        app.EditMax2T.ValueDisplayFormat = fmt;

        app.EditMin2T.Value = round(app.EditMin2T.Value, n);
        app.EditMax2T.Value = round(app.EditMax2T.Value, n);
    

    end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function LIPRAS_OpeningFcn(app)
            import ui.control.*
            import model.*
            import utils.fileutils.*

            app.profiles = ProfileListManager.getInstance();
            app.CheckBox0=[fliplr([app.ConstraintsPanel.Children.Value]) app.AsymmetryCheckBox.Value]; % Checkboxes
            app.OrigCD=pwd; % Default directory, used only for recovery if something goes wrong on restart
            
            % guidata(app.figure1,app) % GUIController.getInstance(app.figure1);
            app.gui=GUIController.getInstance(app);
            
            try    LoadPref(app); catch;                disp('No Preference File...one will be written'); SavePref(app);            end % If no preference file, this shouldnt give error

            % BayesPanel for Plotting
            BayesPanel(app);

            v = ver;
            tf = any(strcmp({v.Name}, 'Curve Fitting Toolbox'));
            app.profiles.CF=tf;
            app.CF=tf;
                        
            % Define source icon (project file)
            iconSource = fullfile(pwd,'Logo','LIPRAS-Icon-v2.png');
   
            % Assign icon ONLY to UIFigure
            app.figure1.Icon = iconSource;

            app.Functions=  {{'Gaussian', 'Lorentzian','Pearson VII','Pseudo-Voigt', ...
                'Asymmetric Pearson VII', 'Gauss-Bk2BkExpConv', 'PV-Bk2BkExpConv'}};


            if ~tf; app.CFLabel.Visible="on"; end
            
            if app.debug       
            assignin('base','app',app); %debug
            end

        end

        % Callback function
        function AsymmPeaks_Callback(app, event)
             if get(o, 'value')
              nFxn=length(app.profiles.xrd.FitFunctions);
              for p=1:nFxn
                  app.profiles.xrd.FitFunctions{p}.Asym=1;
              end
                app.table_fitinitial.Enable='off';
             else
              nFxn=length(app.profiles.xrd.FitFunctions);
              for p=1:nFxn
                  app.profiles.xrd.FitFunctions{p}.Asym=0;
              end
                app.table_fitinitial.Enable='off';
            
            end
        end

        % Callback function: button_browse, menu_new
        function button_browse_Callback(app, event)
                        
            if isfield(event, 'test')
                isNew = app.profiles.newXRD(evt.path, evt.filename);
            else
                app.profiles.XRDMLScan=[]; % for when reading in different datasets, this should always reset to nothing
                isNew = app.profiles.newXRD();
                 app.HTML.HTMLSource=app.formatHTML('Importing selected...');
            end  

            % Next two lines are fixes to uifileget moving App 'to back'
            drawnow;
            figure(app.figure1)

            if isNew % if not the same dataset as before
                app.DropDown.Items=app.profiles.FileNames;
                app.DropDown.ItemsData=1:app.profiles.xrd.NumFiles;
                app.SelectPeaksButton.Enable=0;            
                app.DropDown.Value=1;
                app.UITable2.Data=[];
                app.UITable3.Data=[];
                app.PolyOrderSpinner.Value=1; % must match order in Background.m

                try
                ui.update(app, 'dataset');
                cla(app.UIAxes);
                utils.plotutils.plotX(app, 'data'); % this should be activating setappdata(xdata), calls PlotX
                catch
                    
                end
            elseif any(isNew) && app.profiles.hasFit
                return % SHould do nothing if a fit is present and data is not new since that means user opened browse and selected same file anything
            else
                 app.HTML.HTMLSource=app.formatHTML('Nothing selected...');
                 return % do nothing
            end
            
            if app.profiles.NumFiles~=0 && any(isNew)~=0 % this is for when canceling browse file before a file has been inputtedd..only on startup
            for k=1:app.profiles.NumFiles
                cF(k)=isequal(app.profiles.dataReadin.two_theta{1},app.profiles.dataReadin.two_theta{k}); % test to make sure data is equal for inputted x values
            end
            if sum(double(cF))~=k
            h=warndlg({'You have entered files that do not have the same number of points AND/OR different X-values. CAUTION when using a fitting range', 'Check your 2-Theta range!!!'},'!! Warning !!');
            app.NoEqualData=1;
            else
                app.NoEqualData=0;
            end
            end
            
            if ~isempty(app.profiles.XRDMLScan)
                FiID=app.profiles.XRDMLScan{app.DropDown.Value};
                if ~isempty(FiID)
                    if strcmpi(FiID,'Gonio')||strcmpi(FiID,'2Theta')
                                    app.ThetaLabel.Text=['2' char(952) ' Range (°):'];
                                    app.UIAxes.XLabel.String=['2' char(952) ' (°)'];
                    elseif strcmpi(FiID,'Omega')
                                    app.ThetaLabel.Text=[char(969) ' Range (°):'];
                                    app.UIAxes.XLabel.String=[char(969) ' (°)'];
                    elseif strcmpi(FiID,'Chi')
                                    app.ThetaLabel.Text=[char(967) ' Range (°):'];
                                    app.UIAxes.XLabel.String=[char(967) ' (°)'];                                  
                    elseif strcmpi(FiID,'Phi')
                                    app.ThetaLabel.Text=[char(966) ' Range (°):'];
                                    app.UIAxes.XLabel.String=[char(966) ' (°)'];                                
                    elseif strcmpi(FiID,'2Theta/Omega')
                                    app.ThetaLabel.Text=['2' char(952) ' Range (°):'];
                                    app.UIAxes.XLabel.String=['2' char(952) ' (°)'];                                
                    elseif strcmpi(FiID,'Omega/2Theta')
                                    app.ThetaLabel.Text=['2' char(969) ' Range (°):'];
                                    app.UIAxes.XLabel.String=[char(969) ' (°)'];                        
                    end
                end
            else
                app.ThetaLabel.Text=['2' char(952) ' Range (°):'];
                app.UIAxes.XLabel.String=['2' char(952) ' (°)'];
                % return % Do this since nothing was selected, 1-3-25,
                % 1-10-2026, why was there a return here?
            end
                        
            c=app.DropDown.Value;
            app.DropDownLabel.Visible=1;
            app.DropDownLabel.Text=[num2str(c) ' of ' num2str(app.profiles.xrd.NumFiles)];
            
            % assignin('base','app',app); % may not be needed 1-9-2026
            app.NewButton.Value=1;
            app.EditMin2T.Value=app.profiles.xrd.DataSet{1}.Min2T;
            app.EditMax2T.Value=app.profiles.xrd.DataSet{1}.Max2T;
            app.edit8.Value=app.profiles.DataPath;
            app.EditFieldWave.Enable='on';
            app.profiles.xrd.CF=app.CF; % sets if Curve Fitting Toolbox is used to PackageFitDiffraction
            app.Min2T=app.profiles.xrd.DataSet{1}.Min2T;
            app.Max2T=app.profiles.xrd.DataSet{1}.Max2T;
            app.UIAxes.XLim=[app.Min2T app.Max2T]; % Forces reset of X when data is inputted, 2-5-2026

            app.SigFig % Significant figures for edit boxes

            % guidata(app.figure1,app) % need to get rid of this
            if isNew
                app.BD=[]; % resets Bayesian if data is new
                cla(app.UIAxes2)

                app.NumberofPeaksSpinner.Value=1;
                evt = struct('Source',app.NumberofPeaksSpinner,'EventName',"ValueChanged");
                app.NumberofPeaksSpinnerValueChanged(evt);
                app.FilesListBox.Items=app.profiles.FileNames;
                app.FilesListBox.ItemsData=1:length(app.profiles.FileNames);
                app.FilesListBox.Value=app.DropDown.Value;
                pause(0.5); % needed or its too fast

            end

        end

        % Callback function
        function checkbox_CuKa_Callback(app, event)
            if get(hObject,'Value')
                app.profiles.xrd.CuKa=true;
                app.profiles.xrd.KAlpha1 = app.gui.KAlpha1;
                app.profiles.xrd.KAlpha2 = app.gui.KAlpha2;
                set(app.panel_cuka,'Visible', 'on');
            else
                app.profiles.xrd.CuKa=false;
                set(app.panel_cuka,'Visible', 'off');
            end
        end

        % Callback function
        function checkbox_constraints_Callback(app, event)
            % Save new constraint as an index from panel_constraints.UserData
            if o.Value
                app.profiles.xrd.constrain(o.String);
            else
                app.profiles.xrd.unconstrain(o.String);
            end
            ui.update(app, 'Constraints');
        end

        % Value changed function: checkbox_reverse
        function checkbox_reverse_Callback(app, event)
        value=app.checkbox_reverse.Value;
        if isempty(app.profiles.xrd)
            return
        end
            app.profiles.xrd.reverseDataSetOrder;
            newList=app.profiles.FileNames;
            app.DropDown.Items=newList;
            app.DropDown.Value=1;
            app.DropDownValueChanged;
            
        end

        % Menu selected function: checkUpdate
        function checkforupdates(app, event)
        % ini (optional)
        ini = 'na';
        if nargin >= 2, ini = ''; end
        
        % quick internet check
        try
            webread('https://www.google.com', weboptions('Timeout',4));
        catch
            app.HTML.HTMLSource = app.formatHTML("No internet connection detected");
            return
        end
        
        % local
        
        % remote (File Exchange)
        [~, latestTag, latestDate, ~] = checkFEX(app);
        
        currentVersion = app.LIPRAS_Version; 

        % latestTag=strsplit(latestTag,'.');
        tagStr=latestTag;
        
        urlStr = "https://www.mathworks.com/matlabcentral/fileexchange/183300-line-profile-analysis-software-lipras";
        if ~isempty(urlStr), urlStr = string(urlStr); end
        
        dateStr = "unknown";
        if ~isempty(latestDate)
            try
                dt = datetime(string(latestDate), 'InputFormat','dd MMM yyyy');
                dateStr = string(dt, 'MM-dd-uuuu');
            catch
                dateStr = string(latestDate); % fallback
            end
        end
        
            d = uifigure('Name','LIPRAS','Position',[520 550 300 180], ...
                'WindowStyle','modal','Resize','off');

            try, d.Icon = fullfile(pwd,'Logo','LIPRAS-Icon-v2.png'); end
        
            gl = uigridlayout(d,[5 1]);
            gl.Padding = 10;
            gl.RowHeight = {35, 25, 25, 45};
        
            fontS = 12;
       
        
            uilabel(gl,'Text',sprintf('LIPRAS Version: %s', string(currentVersion)), ...
                'FontSize',fontS,'HorizontalAlignment','center');
        
            uilabel(gl,'Text',sprintf('Last Release: %s', tagStr), ...
                'FontSize',fontS,'HorizontalAlignment','center');
        
            uilabel(gl,'Text',sprintf('Released on: %s', dateStr), ...
                'FontSize',fontS,'HorizontalAlignment','center');
        
            btnRow = uigridlayout(gl,[1 2]);
            btnRow.Padding = 0;
            btnRow.ColumnWidth = {'1x','1x'};
            btnRow.ColumnSpacing = 10;
        
            uibutton(btnRow,'Text','MATLAB File Exchange','FontSize',fontS, ...
                'ButtonPushedFcn',@(s,e) web(char(urlStr),'-browser'));
        
            uibutton(btnRow,'Text','Close','FontSize',fontS, ...
                'ButtonPushedFcn',@(s,e) delete(d));


        end

        % Callback function
        function edit_kalpha_Callback(app, event)
            ka1 = str2double(get(app.edit_kalpha1, 'String'));
            ka2 = str2double(get(app.edit_kalpha2, 'String'));
            app.profiles.xrd.KAlpha1 = ka1;
            app.profiles.xrd.KAlpha2 = ka2;
            app.gui.KAlpha1 = ka1;
            app.gui.KAlpha2 = ka2;
            utils.plotutils.plotX(app);
            app.gui.Status = ['<html>' hObject.TooltipString ' wavelength set to ' hObject.String '.'];
        end

        % Callback function
        function listbox_results_Callback(app, event)

        end

        % Menu selected function: menuPlot_superimpose
        function menuPlot_superimpose_Callback(app, event)
             cla(app.UIAxes)
             if app.menuPlot_superimpose.Checked=='on'
                 app.menuPlot_superimpose.Checked='off';
             else
             app.menuPlot_superimpose.Checked='on';
             end
             % If box is checked, turn on hold in axes1
             if app.menuPlot_superimpose.Checked=='on'
                 hold(app.UIAxes, 'on')
                 app.UIAxes.ColorOrderIndex = 1;
                 utils.plotutils.plotX(app, 'superimpose');
                 app.HTML.HTMLSource=app.formatHTML('Superimposing data...');
             else
                 utils.plotutils.plotX(app);
                 app.HTML.HTMLSource='<div> </div>';
             end

        end

        % Menu selected function: menu_FileResetProfile
        function menu_FileResetProfile_Callback(app, event)
                if isempty(app.profiles.xrd)
                    app.HTML.HTMLSource=app.formatHTML('Nothing to reset');
                else
                    app.profiles.reset;
                    cla(app.UIAxes);
                    ui.update(app, 'dataset');
                    % Reset actions
                    app.disableFitData;
                    app.UITable.Data=[]; app.NumberofPeaksSpinner.Value=0; % reset profiles table.

                    app.SelectPeaksButton.Enable='off';
                    app.LineProfilesPanel.Enable="off";
        
                    utils.plotutils.plotX(app, 'data');
                    app.HTML.HTMLSource=app.formatHTML('Profiles reset');
                end
        end

        % Menu selected function: menu_about
        function menu_about_Callback(app, event)


    % Modal window
    f = uifigure( ...
        'Name','About', ...
        'Position',[500 440 480 410], ...
        'WindowStyle','modal', ...
        'Resize','off');

    try
        f.Icon = fullfile(pwd,'Logo','LIPRAS-Icon-v2.png');
    catch
    end

    % Main layout
    gl = uigridlayout(f,[3 1]);
    gl.RowHeight = {120, '1x', 40};   % logo | content | close
    gl.Padding = [10 10 10 10];
    gl.RowSpacing = 8;

    % Logo
    img = uiimage(gl, ...
        'ImageSource', fullfile('Logo','Logo_R3.png'), ...
        'ScaleMethod','fit');
    img.Layout.Row = 1;
    img.HorizontalAlignment = 'center';
    img.VerticalAlignment = 'center';

    % Content panel (replaces uitextarea)
    p = uipanel(gl, ...
        'Title','', ...
        'BorderType','line', ...
        'BackgroundColor','white');
    p.Layout.Row = 2;

    cg = uigridlayout(p,[9 1]);
    cg.RowHeight = {22, 6, 22, 40, 6, 22, 22, 6, '1x'};
    cg.ColumnWidth = {'1x'};
    cg.Padding = [8 8 8 8];
    cg.RowSpacing = 2;
    cg.BackgroundColor = 'white';

    % Version
    uilabel(cg, ...
        'Text', ['LIPRAS Version ' num2str(app.LIPRAS_Version)], ...
        'FontSize', 12, ...
        'FontWeight', 'bold', ...
        'BackgroundColor', 'white');

    uilabel(cg,'Text','', 'BackgroundColor','white');

    % Authors
    uilabel(cg, ...
        'Text', 'Authors:', ...
        'FontSize', 12, ...
        'FontWeight', 'bold', ...
        'BackgroundColor', 'white');

    uilabel(cg, ...
        'Text', 'Giovanni Esteves, Klarissa Ramos, Christopher M. Fancher, Jacob L. Jones', ...
        'FontSize', 12, ...
        'WordWrap', 'on', ...
        'VerticalAlignment', 'top', ...
        'BackgroundColor', 'white');

    uilabel(cg,'Text','', 'BackgroundColor','white');

    % Affiliation
    uilabel(cg, ...
        'Text', 'Originally developed at North Carolina State University (2016)', ...
        'FontSize', 12, ...
        'BackgroundColor', 'white');

    % Contact header
    uilabel(cg, ...
        'Text', 'Contact:', ...
        'FontSize', 12, ...
        'FontWeight', 'bold', ...
        'BackgroundColor', 'white');

    uilabel(cg,'Text','', 'BackgroundColor','white');

    % Contact lines
    uilabel(cg, ...
        'Text', sprintf('lipras17@gmail.com\njacobjones@ncsu.edu'), ...
        'FontSize', 12, ...
        'VerticalAlignment', 'top', ...
        'BackgroundColor', 'white');

    % Close button
    btn = uibutton(gl, 'Text', 'Close', ...
        'ButtonPushedFcn', @(s,e) delete(f));
    btn.Layout.Row = 3;
    
        end

        % Menu selected function: FitParamImport
        function FitParamImport_Callback(app, event)
        if app.profiles.hasData
            % Fit exists — open dialog in that directory
            filespec = fullfile(app.profiles.OutputPath, '*.txt');
            [filename, pathName] = uigetfile(filespec, 'Select Input File', ...
                                             'MultiSelect', 'off');
        else
            % No fit yet — return
                            app.gui.PriorityStatus='Input data first, then import';
            return
        end
        
        % Only proceed if user actually selected a file
        if ischar(filename) && ~isequal(filename, 0)
            fullFile = fullfile(pathName, filename);
            app.profiles.importProfileParametersFile(fullFile);
            ui.update(app, 'parameters');
        end
        end

        % Callback function
        function menu_preferences_Callback(app, event)
%                 pref(app);
        end

        % Menu selected function: menu_restart
        function menu_restart_Callback(app, event)
            app.menu_closeMenuSelected
            try
            LIPRAS;
            pause(1) % wait
            catch
                cd(app.OrigCD)
            LIPRAS;
                disp('Switching to LIPRAS Directory...')
            end
            
        end

        % Menu selected function: FitParamExport
        function FitParamExport_Callback(app, event)
            if app.profiles.hasFit
                app.profiles.exportProfileParametersFile();
            end
        end

        % Menu selected function: menu_saveasimage
        function menu_saveasimage_Callback(app, event)
                if ~isempty(app.profiles.FitResults)
                    exportPlotAsImage(app);
                else
                   msgbox('Conduct a fit first')
                end
        end

        % Menu selected function: menu_xaxis_dspace, menu_xaxis_linear
        function menu_xplotscale_Callback(app, event)
            plotter = app.gui.Plotter;
            if ~app.profiles.hasData
                app.HTML.HTMLSource=app.formatHTML('Input data to continue...');
                return
            end

            if isa(app.profiles.xrd.MonoWavelength,'numeric')
            wave=app.profiles.xrd.MonoWavelength;
            end
            o=event.Source.Text;
            switch o
                case 'Linear'
                    plotter.XScale = 'linear';
                    app.menu_xaxis_linear.Checked="on";
                    app.menu_xaxis_dspace.Checked="off";
                case 'D-Space'
                    plotter.XScale = 'dspace';
                    app.menu_xaxis_dspace.Checked="on";
                    app.menu_xaxis_linear.Checked="off";
            end
            
            plotter.updateXYLim % Update XY, 2-14-2026


        end

        % Menu selected function: menu_ylinear, menu_ylog, menu_yroot
        function menu_yplotscale_Callback(app, event)
            %MENU_YPLOTSCALE_CALLBACK executes when any option under 'Plot'->'Y-Axis Scale' menu is clicked. The
            %   default selection is 'menu_ylinear'.
            % turn off checks in all x plot menu items
            app.menu_yaxis.Checked="off";
            plotter = app.gui.Plotter;
            o=event.Source.Text;
            if ~app.ScaleID; return; end % Do nothing because 'coefficient' is on

            if strcmp(o,'Plot Bayes Fit')
            checkedItem = findobj(app.menu_yaxis.Children, 'Checked', 'on');
            label = checkedItem.Text;   % or .Label depending on what you used
            o=label;
            end
            switch o
                case 'Linear' % linear
                    plotter.YScale = 'linear';
                    app.menu_ylinear.Checked="on";
                    app.menu_yroot.Checked="off";
                    app.menu_ylog.Checked="off";
                    if strcmp(app.SubplotPanel.Visible,'on') && app.axbayesid
                        transformData(app,app.axbayes,'linear');                    
                    end
                case 'Logarithm'% log
                    plotter.YScale = 'log';
                    app.menu_ylog.Checked="on";
                    app.menu_ylinear.Checked="off";
                    app.menu_yroot.Checked="off";

                    if strcmp(app.SubplotPanel.Visible,'on') && app.axbayesid
                        transformData(app,app.axbayes,'log');                    
                    end

                case 'Square Root' % d-space
                    plotter.YScale = 'sqrt';
                    app.menu_yroot.Checked="on";
                    app.menu_ylinear.Checked="off";
                    app.menu_ylog.Checked="off";
                    if strcmp(app.SubplotPanel.Visible,'on') && app.axbayesid
                        transformData(app,app.axbayes,'sqrt');                    
                    end
            end
                    drawnow
                    figure(app.figure1)
        end

        % Callback function
        function panel_choosePlotView_SelectionChangedFcn(app, event)
% delete function
        end

        % Callback function
        function popup_bkgdmodel_Callback(app, event)
            app.profiles.xrd.setBackgroundModel(o.String{o.Value});
            ui.update(app,'backgroundmodel');
        end

        % Callback function
        function popup_filename_Callback(app, event)
            app.gui.CurrentFile = hObject.Value;
            app.profiles.xrd.CurrentPro=hObject.Value;
            
            if ~isempty(app.profiles.XRDMLScan)
                FiID=app.profiles.XRDMLScan{app.popup_filename.Value};
                    if strcmpi(FiID,'Gonio')||strcmpi(FiID,'2Theta')
                                    app.text4.String=['2' char(952) 'Range (°):'];
                    elseif strcmpi(FiID,'Omega')
                                    app.text4.String=[char(969) ' Range (°):'];
                    elseif strcmpi(FiID,'Chi')
                                    app.text4.String=[char(967) ' Range (°):'];
                    elseif strcmpi(FiID,'Phi')
                                    app.text4.String=[char(966) ' Range (°):'];
                    elseif strcmpi(FiID,'2Theta/Omega')
                                    app.text4.String=['2' char(952) ' Range (°):'];
                    elseif strcmpi(FiID,'Omega/2Theta')
                                    app.text4.String=['2' char(969) ' Range (°):'];
                    end
            app.gui.Plotter.updateXLabel(app.axes1)
            else
                 app.text4.String=['2' char(952) 'Range (°):'];
            end
            
            superimposed=strcmp(app.menuPlot_superimpose.Checked,'on');
            if superimposed
                utils.plotutils.plotX(app, 'superimpose');
            else
                utils.plotutils.plotX(app);
            end
        end

        % Callback function
        function push_fitbkgd_Callback(app, event)
            import utils.plotutils.*
            
            if Validate_bkg(app) % checks to make sure BkgOrder is not greater than points selected
                return
            end
            
            if ~app.gui.areFuncsReady || app.gui.isFitDirty
                utils.plotutils.plotX(app, 'background');
            else
            app.profiles.xrd.calculateBackground;
            utils.plotutils.plotX(app, 'sample');
            end
        end

        % Callback function
        function push_fitdata_Callback(app, event)
            
            if Validate_bkg(app)
                return
            end
            
            % From Preferences
            app.profiles.xrd.Weights=app.profiles.Weights;
            app.profiles.xrd.UniqueSave=app.profiles.UniqueSave;
            try
                app.profiles.xrd.FitInitial.lower=app.gui.FitInitial.lower;
                app.profiles.xrd.FitInitial.upper=app.gui.FitInitial.upper;
            catch
            end
            
            try
                prfn = app.profiles.ActiveProfile;
                fitresults = app.profiles.fitDataSet(prfn);
            
                % this is so when switching constraints, the table wont update with fitted values until a fit is done
                app.profiles.xrd.OriginalFitInitial.coeffs=app.gui.FitInitial.coeffs;
                if ~isempty(fitresults)
                    ui.update(app, 'results');
                    utils.plotutils.plotX(app,'fit');
                else
                    utils.plotutils.plotX(app,'sample');
                end
            catch ME
                ME.getReport
                assignin('base','lastException',ME)
                errordlg(ME.message)
                return
            end
        end

        % Callback function
        function push_fitstats_Callback(app, event)
            app.gui.onPlotFitChange('stats');
        end

        % Callback function
        function push_newbkgd_Callback(app, event)
% delete function
        end

        % Callback function
        function push_selectpeak_Callback(app, event)
% delete function
        end

        % Callback function
        function push_tabswitch_Callback(app, event)
% delete function
        end

        % Callback function
        function push_update_Callback(app, event)
% delete function
        end

        % Callback function
        function push_viewall_Callback(app, event)
% delete function
        end

        % Callback function
        function pushbutton44_Callback(app, event)
% delete function
        end

        % Callback function
        function table_fitinitial_CellEditCallback(app, event)
% delete function
        end

        % Callback function
        function table_paramselection_CellEditCallback(app, event)
% delete function
        end

        % Value changed function: NumberofPeaksSpinner
        function NumberofPeaksSpinnerValueChanged(app, event)
            value = app.NumberofPeaksSpinner.Value;
            if value==0
                return % if set to zero nothing happens
            end
            ln=length(app.UITable.Data);
            if value==1
            app.UITable.Data = {''}; % have 3 rows of gender information.
            app.UITable.ColumnFormat = app.Functions; % have option cell array within ColumnFormat property to indicate the 1st column is using dropdown menus.
            app.UITable.ColumnEditable = true;
            else
                % Logic for increasing and decreasing number of profiles in fit
               if value== ln+1
                row=cell(value,1);
                app.UITable.Data{end+1,1}='';
               elseif value< ln
                   app.UITable.Data(end)=[];
               elseif isempty(app.UITable.Data{value})
                   app.UITable.Data{end+1,1}='';
               else
                   
               end
            app.UITable.ColumnFormat = app.Functions; % have option cell array within ColumnFormat property to indicate the 1st column is using dropdown menus.
            % added 07-12-2020 to reduce user from accidentally typing a letter during peak fxn selection and creating a new profile shape function that is not available. 
            end
            
            try % added on 12-28-2022
                nP=sum(cellfun(@isempty, app.UITable.Data)); % how many are empty
                if nP~=0
                app.SelectPeaksButton.Enable='off';
                else
                app.SelectPeaksButton.Enable='on';

                end
            catch

            end
            UpdateButtonPushed(app, event) % added 1-5-26, attempt to remove Update button
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            c= app.DropDown.Value;
            
            app.DropDownLabel.Text=[num2str(c) ' of ' num2str(app.profiles.xrd.NumFiles)];
            app.gui.CurrentFile=c;
                        
            if  app.profiles.hasFit
                utils.plotutils.plotX(app,'fit')
                
                % Extract result values
                fitResult = app.profiles.FitResults{1}{app.DropDown.Value};
                rchi2 = round(fitResult.Rchi2, 2);
                rp    = round(fitResult.Rp, 2);
                rwp   = round(fitResult.Rwp, 2);
                
                % Create formatted HTML
                html = sprintf([ ...
                    '<div align="left"><font size="2" face="Helvetica"><b>', ...
                    '&Chi;<sup>2</sup> = %.2f, ', ...
                    'R<sub>p</sub> = %.2f%%, ', ...
                    'R<sub>wp</sub> = %.2f%%', ...
                    '</b></font></div>'], ...
                    rchi2, rp, rwp);
                
                % Assign to component
                app.HTML2.HTMLSource = html;

            elseif~isempty(app.profiles.xrd.Background.InitialPoints)
                delete(app.UIAxes.Children(~contains({app.UIAxes.Children.Tag},'Obs'))) % delete everything but Obs
                app.profiles.xrd.Background.Order=app.PolyOrderSpinner.Value;
                bkg=app.profiles.xrd.calculateBackground;
                utils.plotutils.plotX(app,'background')
            else
            end

            app.profiles.xrd.CurrentPro=c;
            
            if ~isempty(app.profiles.XRDMLScan)
                FiID=app.profiles.XRDMLScan{c};
                    if strcmpi(FiID,'Gonio')||strcmpi(FiID,'2Theta')
                                    app.ThetaLabel.Text=['2' char(952) 'Range (°):'];
                    elseif strcmpi(FiID,'Omega')
                                    app.ThetaLabel.Text=[char(969) ' Range (°):'];
                    elseif strcmpi(FiID,'Chi')
                                    app.ThetaLabel.Text=[char(967) ' Range (°):'];
                    elseif strcmpi(FiID,'Phi')
                                    app.ThetaLabel.Text=[char(966) ' Range (°):'];
                    elseif strcmpi(FiID,'2Theta/Omega')
                                    app.ThetaLabel.Text=['2' char(952) ' Range (°):'];
                    elseif strcmpi(FiID,'Omega/2Theta')
                                    app.ThetaLabel.Text=['2' char(969) ' Range (°):'];
                    end
            app.gui.Plotter.updateXLabel(app.UIAxes)
            else
                 app.ThetaLabel.Text=['2' char(952) ' Range (°):'];
            end
            
        superimposed=strcmp(app.menuPlot_superimpose.Checked,'on'); 
        if superimposed
            utils.plotutils.plotX(app, 'superimpose');
        else
            utils.plotutils.plotX(app);
            app.gui.Plotter.updateXYLim;
        end

            if strcmp(app.SubplotPanel.Visible,'on') && or(app.PlotHistogramButton.Value,app.PlotTraceButton.Value) && ~app.axbayesid % Only re-plot trace or histogram, else its BayesFit
                BayesPlot(app, c);
                    app.FilesListBox_2.Value=app.FilesListBox_2.Items{c}; % should move selection to match dropdown
            end

        end

        % Value changed function: EditMin2T
        function EditMin2TValueChanged(app, event)
            
        if isempty(app.profiles.xrd)
            app.EditMin2T.Value = 0;
            return
        end
    
        % --- read user values
        minUser = app.EditMin2T.Value;
        maxUser = app.EditMax2T.Value;
    
        % --- clamp to absolute data limits
        absMin = app.profiles.xrd.AbsoluteRange(1);
        absMax = app.profiles.xrd.AbsoluteRange(2);
    
        minVal = max(minUser, absMin);
        maxVal = min(maxUser, absMax);
    
        % keep sane ordering
        if maxVal <= minVal
            maxVal = minVal + eps(minVal);
        end
    
        % write back clamped UI values so user sees what happened
        app.EditMin2T.Value = minVal;
        app.EditMax2T.Value = maxVal;

        app.SigFig; % Set format
    
        % --- your NoEqualData guard (use Value, not String)
        if app.NoEqualData == 1
            cK = zeros(1, app.profiles.NumFiles);
            for p = 1:app.profiles.NumFiles
                cK(p) = min(app.profiles.dataReadin.two_theta{p});
            end
            if any(cK > minVal)
                warndlg('You have files with different number of data points!! Check your bounds to ensure that it is captured by all files');
                app.gui.Min2T = app.profiles.Min2T;
                return
            end
        end
    
        % --- commit range
        app.profiles.Min2T = minVal;
    
        % --- plot first (if plotX recreates lines/axes properties)
        utils.plotutils.plotX(app,'data');
    
        if app.menu_xaxis_dspace.Checked == "on"
            app.gui.Plotter.XScale = "dspace";
        end
    
        % --- ALWAYS apply XLim after plotting
        app.UIAxes.XLim = [minVal maxVal];
    
        % --- lock and define Home target
        app.UIAxes.XLimMode = 'manual';
        % app.UIAxes.YLimMode = 'auto';
        resetplotview(app.UIAxes,'SaveCurrentView');
        end

        % Value changed function: EditMax2T
        function EditMax2TValueChanged(app, event)
        if isempty(app.profiles.xrd)
            app.EditMax2T.Value = 0;
            return
        end
    
        % user inputs
        minUser = app.EditMin2T.Value;
        maxUser = app.EditMax2T.Value;
    
        % absolute limits
        absMin = app.profiles.xrd.AbsoluteRange(1);
        absMax = app.profiles.xrd.AbsoluteRange(2);
    
        % clamp
        minVal = max(minUser, absMin);
        maxVal = min(maxUser, absMax);
    
        % keep sane ordering
        if maxVal <= minVal
            minVal = min(maxVal - eps(maxVal), minVal); % or just force max up, your choice
            if maxVal <= minVal
                maxVal = minVal + eps(minVal);
            end
        end
    
        % write back so UI matches reality
        app.EditMin2T.Value = minVal;
        app.EditMax2T.Value = maxVal;

        app.SigFig; % Set format
    
        % (optional) NoEqualData check: use .Value not .String
        if app.NoEqualData == 1
            cK = zeros(1, app.profiles.NumFiles);
            for p = 1:app.profiles.NumFiles
                cK(p) = max(app.profiles.dataReadin.two_theta{p}); % for max check, use max
            end
            if any(cK < maxVal)
                warndlg('You have files with different number of data points!! Check your bounds to ensure that it is captured by all files');
                return
            end
        end
    
        % commit
        app.profiles.Max2T = maxVal;
    
        % plot first
        utils.plotutils.plotX(app,'data');
    
        if app.menu_xaxis_dspace.Checked == "on"
            app.gui.Plotter.XScale = "dspace";
        end
    
        % ALWAYS set limits after plotting
        app.UIAxes.XLim = [minVal maxVal];
    
        app.UIAxes.XLimMode = 'manual';
        % app.UIAxes.YLimMode = 'auto';
        resetplotview(app.UIAxes,'SaveCurrentView');
        end

        % Value changed function: RefineBkgCheckBox
        function RefineBkgCheckBoxValueChanged(app, event)
            if isempty(app.profiles.xrd)
                app.EditMax2T.Value=0;
                return
            end 
            value = app.RefineBkgCheckBox.Value;
            if value
              app.profiles.xrd.BkgLS = 1;
            else
              app.profiles.xrd.BkgLS = 0;
            end
        end

        % Value changed function: NoBoundsCheckBox
        function NoBoundsCheckBoxValueChanged(app, event)
            if isempty(app.profiles.xrd)
                app.EditMax2T.Value=0;
                return
            end          
            value = app.NoBoundsCheckBox.Value;
            if value
              app.profiles.xrd. ignore_bounds=1;
            else
              app.profiles.xrd.ignore_bounds=0;        
            end
        end

        % Value changed function: RecycleCheckBox
        function RecycleCheckBoxValueChanged(app, event)
            if isempty(app.profiles.xrd)
                app.EditMax2T.Value=0;
                return
            end            
            value = app.RecycleCheckBox.Value;
            if value
              app.profiles.xrd.recycle_results=1;
            else
              app.profiles.xrd.recycle_results=0; 
            end
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            delete(app.gui);
            delete(app.profiles);    
            app.delete
            iconTemp = fullfile(tempdir,'appIcon.png');
        
            delete(app.UIFigure)
            drawnow   % forces Java to release the icon file
        
            if isfile(iconTemp)
                delete(iconTemp)
            end
            clear('app', 'var');

        end

        % Menu selected function: menu_help
        function menu_helpMenuSelected(app, event)
            open('LIPRAS_Manual_7.pdf')
        end

        % Value changed function: PolyOrderSpinner
        function PolyOrderSpinnerValueChanged(app, event)
            value = app.PolyOrderSpinner.Value;
            if isempty(app.profiles.xrd)
            disp('Add data...')
            return
            end
            app.profiles.xrd.setBackgroundOrder(value);

            if value == 1 && strcmpi(app.profiles.xrd.getBackgroundModel, 'Spline')
            app.PolyOrderSpinner.Value=2;
            app.gui.PriorityStatus = '<html><font color="red">Spline Order must be > 1.';
            app.profiles.xrd.setBackgroundOrder(app.PolyOrderSpinner.Value);
            end
        end

        % Button pushed function: SelectPointsButton
        function SelectPointsButtonPushed(app, event)
            if isempty(app.profiles.xrd)
                app.HTML.HTMLSource = app.formatHTML('Input data to continue...');
                return
            end
        
            if ~ishold(app.UIAxes)
                hold(app.UIAxes);
            end
        
            % Revert Obs to same line style 
            if ~isempty(app.ObsStyleBackup)
                dataLine = findobj(app.UIAxes, 'tag', 'Obs');
                s = app.ObsStyleBackup;
                set(dataLine, 'LineStyle',s.LineStyle, 'Marker',s.Marker, 'MarkerSize',s.MarkerSize, ...
                'MarkerFaceColor',s.MarkerFaceColor, 'MarkerEdgeColor',s.MarkerEdgeColor);
            end


            % Determine mode from toggle buttons
            vals = [app.NewButton.Value app.AddButton.Value app.DeleteButton.Value];
            idx  = find(vals>0, 1, 'first');
        
            if isempty(idx) || idx==1
                mode = 'New';
        
                app.BkgBackupPoints = app.profiles.BackgroundPoints;

                % Reset stored points
                if ~isempty(app.profiles.BackgroundPoints)
                    app.profiles.BackgroundPoints = [];
                end
        
                % Remove plotted bkg points + background curve, keep Obs
                if ~isempty(findobj(app.UIAxes.Children,'Tag','Bkgpts')) || ~isempty(findobj(app.UIAxes.Children,'Tag','background'))
                    delete(app.UIAxes.Children(~contains({app.UIAxes.Children.Tag},'Obs')))
                    bg = app.UIAxes.Children(contains({app.UIAxes.Children.Tag},'background'));
                    if ~isempty(bg), delete(bg); end
                end
        
            elseif idx==2
                mode = 'Add';
        
                % If background curve exists, clear everything except Obs (so user edits points cleanly)
                if ~isempty(findobj(app.UIAxes.Children,'Tag','background'))
                    delete(app.UIAxes.Children(~contains({app.UIAxes.Children.Tag},'Obs')))
                end
        
                % Recreate previously selected points if not already plotted
                if isempty(findobj(app.UIAxes.Children,'Tag','Bkgpts'))
                    bkgpt = app.profiles.BackgroundPoints;
                    id    = app.profiles.xrd.Background.InitialPointsIdx;
                    dataLine = findobj(app.UIAxes.Children,'Tag','Obs');
                    ydata = dataLine.YData;
        
                    for i = 1:numel(bkgpt)
                        plot(app.UIAxes, bkgpt(i), ydata(id(i)), '*r', ...
                            'MarkerSize',8,'LineWidth',1.3,'Tag','Bkgpts');
                    end
                end
        
            else
                mode = 'Delete';
        
                if ~isempty(findobj(app.UIAxes.Children,'Tag','background'))
                    delete(app.UIAxes.Children(~contains({app.UIAxes.Children.Tag},'Obs')))
                end
        
                if isempty(findobj(app.UIAxes.Children,'Tag','Bkgpts'))
                    bkgpt = app.profiles.BackgroundPoints;
                    id    = app.profiles.xrd.Background.InitialPointsIdx;
                    dataLine = findobj(app.UIAxes.Children,'Tag','Obs');
                    ydata = dataLine.YData;
        
                    for i = 1:numel(bkgpt)
                        plot(app.UIAxes, bkgpt(i), ydata(id(i)), '*r', ...
                            'MarkerSize',8,'LineWidth',1.3,'Tag','Bkgpts');
                    end
                end
            end
        
            app.BkgMode   = mode;
            app.BkgSelect = 'on';
            app.KeyPressed = '';
        
            app.LineProfilesPanel.Enable = 'on';
            app.HTML.HTMLSource = app.formatHTML("Background selection is ON, Press 'Enter' to end ");

        end

        % Window button down function: figure1
        function figure1WindowButtonDown(app, event)


        % Only react to clicks inside app.UIAxes
        obj = hittest(app.figure1);
        ax  = ancestor(obj,'matlab.ui.control.UIAxes');
        if isempty(ax) || ax ~= app.UIAxes
            return
        end
    
        % Click location (debug)
        cp = app.UIAxes.CurrentPoint;
        x  = cp(1,1);
        y  = cp(1,2);
        if app.debug
            disp([x y])
        end
    
        % Common values
        clickType = app.figure1.SelectionType;   % 'alt' = right click
        alpha     = 200;
        app.a     = x;
        app.PointSelected = [x-alpha, x+alpha];
    
        % Cancel or finalize based on last keypress state
        if strcmp(app.KeyPressed,'escape')
            app.BkgSelect = 'off';
            app.HTML.HTMLSource = app.formatHTML('Selection CANCELED');
            return
        elseif any(strcmp(app.KeyPressed,{'return','enter'}))
            app.BkgSelect = 'off';
            app.HTML.HTMLSource = app.formatHTML('Selection OFF');
            return
        end
    
        % -------- Background selection --------
        if strcmp(app.BkgSelect,'on') && ~isempty(app.profiles)
    
            if isempty(app.UIAxes.Children)
                app.HTML.HTMLSource = app.formatHTML("Data hasn't loaded, and nothing is being plotted...");
                return
            end
    
            if strcmpi(app.BkgMode,'New')
                pts = utils.selectBackgroundPoints(app,'New',clickType);
                if isempty(app.profiles.BackgroundPoints)
                    app.profiles.BackgroundPoints = pts;
                else
                    app.profiles.BackgroundPoints = [app.profiles.BackgroundPoints pts];
                end
    
            elseif strcmpi(app.BkgMode,'Add')
                pts = utils.selectBackgroundPoints(app,'Add',clickType);
                if isempty(app.profiles.BackgroundPoints)
                    app.profiles.BackgroundPoints = pts;
                else
                    app.profiles.BackgroundPoints = [app.profiles.BackgroundPoints pts];
                end
    
            elseif strcmpi(app.BkgMode,'Delete')
                if isempty(app.profiles.xrd.getBackgroundPoints)
                    app.BkgSelect = 'off';
                    disp('Deleted All points')
                    return
                end
                app.profiles.BackgroundPoints = utils.selectBackgroundPoints(app,'Delete',clickType);
            end
        end
    
        % -------- Peak selection --------
        if strcmp(app.PeakSelect,'on')
    
            if ~ishold(app.UIAxes), hold(app.UIAxes,'on'); end
    
            peaks = SelectPointsX(app,'Peaks');
            if ~isempty(peaks)
                if isempty(app.PeakPositions)
                    app.PeakPositions = peaks;
                else
                    app.PeakPositions = [app.PeakPositions peaks];
                end
            end
    
            if app.NumberofPeaksSpinner.Value == numel(app.PeakPositions)
                app.PeakSelect = 'off';
                app.HTML.HTMLSource = app.formatHTML('Peak selection is done');
    
                delete(app.UIAxes.Children(1:app.NumberofPeaksSpinner.Value))
    
                app.profiles.xrd.PeakPositions  = app.PeakPositions;
                app.profiles.xrd.PeakPositions_ = app.PeakPositions;
                app.profiles.xrd.FitInitial     = app.profiles.xrd.getDefaultBounds;
    
                ui.update(app,'fitinitial_peakselect')
                setTable(app, app.profiles.xrd.FitInitial)
                utils.plotutils.plotX(app,'sample')
                app.FitDataButton.Enable = 1;
            end
        end


        end

        % Window key press function: figure1
        function figure1WindowKeyPress(app, event)
                key = event.Key;
                app.KeyPressed=key;

                % Only act on Return or Escape
                if ~(strcmp(key,'return') || strcmp(key,'escape'))
                    return
                end

                mods = event.Modifier;   % {} when none

                   % Optional: ignore combos like Shift+Return, Ctrl+Return
                if any(strcmp(mods,'shift')) || any(strcmp(mods,'control'))
                    return
                end
                                
                if strcmp(app.KeyPressed,'escape')% need to display that BkgSelectionis off
                app.BkgSelect='off';
                app.PeakSelect='off';
                    if ishold(app.UIAxes) % set plot to no-hold 
                    hold(app.UIAxes,'off');              
                    end
                app.HTML.HTMLSource=app.formatHTML('Selection CANCELED');


                if isprop(app,'BkgBackupPoints')
                    app.profiles.BackgroundPoints = app.BkgBackupPoints;
                end
                
                delete(findobj(app.UIAxes.Children,'Tag','Bkgpts'))
                delete(findobj(app.UIAxes.Children,'Tag','background'))
                
                if ~isempty(app.profiles.BackgroundPoints)
                    app.PlotBackgroundButtonPushed(app);  % restores the curve too, remove if you do not want to recompute
                end


                elseif strcmp(app.KeyPressed,'return') 
                app.BkgSelect='off';
                app.PeakSelect='off';
                    if ishold(app.UIAxes) % set plot to no-hold 
                    hold(app.UIAxes,'off');              
                    end                
                app.HTML.HTMLSource=app.formatHTML('Selection OFF');   
                            app.BkgBackupPoints = app.profiles.BackgroundPoints;   % or app.profiles.xrd.getBackgroundPoints

                end
                
                app.PlotBackgroundButtonPushed(app); % Originally this was an if statement checking for a fit, but now with bkgselection, it seems like it should always run

                if app.debug
                disp(key) % debug only
                end
        end

        % Window button motion function: figure1
        function figure1WindowButtonMotion(app, event)

        end

        % Button pushed function: PlotBackgroundButton
        function PlotBackgroundButtonPushed(app, event)
            if isempty(app.profiles.xrd) % make sure user doesnt hit plot without loading data
                return                   
            end
            if Validate_bkg(app) % checks to make sure BkgOrder is not greater than points selected
                return
            end

            if ~isempty(app.profiles.xrd.Background.InitialPoints)
                delete(app.UIAxes.Children(1:end-1)); % deletes everything to plot only Background and data
                app.profiles.xrd.Background.Order=app.PolyOrderSpinner.Value;
                
                if app.PolyOrderSpinner.Value > length(app.profiles.xrd.Background.InitialPoints)
                    CreateStruct.Interpreter = 'tex';
                    CreateStruct.WindowStyle = 'modal';
                    uiwait(msgbox('\fontsize{12} Poly order greater than number of points selected ','Warning', CreateStruct));
                    return
                else                
                bkg=app.profiles.xrd.calculateBackground;
                end
                
                xrd = app.profiles.xrd;

                utils.plotutils.plotX(app, 'background');

                if ~app.gui.areFuncsReady || app.gui.isFitDirty 
                    utils.plotutils.plotX(app, 'background'); % doesnt appear to be needed
                else
                app.profiles.xrd.calculateBackground;
                utils.plotutils.plotX(app, 'sample');
                end

            end
            app.BkgSelect='off';
            app.LineProfilesPanel.Enable='on';
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            
            % Check if input data exists
            if isempty(app.profiles.FileNames)
                app.HTML.HTMLSource = app.formatHTML("Input data...");
                return
            end
            
            % Validate background order selection
            if Validate_bkg(app)  % returns true if invalid
                return
            end
            
            % Validate UITable selection
            tableData = app.UITable.Data;
            isMissing = isempty(tableData) || any(ismissing(tableData(:)));
            if isMissing
                app.HTML.HTMLSource = app.formatHTML("Select profiles to continue...");
                return
            end
                
            if app.CoefficientsButton.Value
            app.gui.onPlotFitChange('peakfit')
            end

            if isempty(app.profiles.FitInitial) 
                s=1;
            else
                s= length(find(app.profiles.FitInitial.start<0));
                l= length(find(app.profiles.FitInitial.lower<0));
                u= length(find(app.profiles.FitInitial.upper<0));
                nC=length(app.profiles.FitInitial.start);
                try
                   nnC=length(app.gui.FitInitial.coeffs);
                catch
                end
           end

            app.profiles.FcnNames = app.gui.FcnNames;
            app.profiles.FitInitial = 'default';

            % Check Asym property for each FitFunction based on global Asym
            % This needs to go here since app.profiles above initialize, otherwise it rewrites one of the app.profiles.xrd.FitFunctions{p}
            nFxn = numel(app.profiles.xrd.FitFunctions);
            app.Asym=double(app.AsymmetryCheckBox.Value);
            for p = 1:nFxn
                app.profiles.xrd.FitFunctions{p}.Asym = app.Asym;
            end
            
            if logical(app.Asym)
            app.profiles.FitInitial = 'default';% This is needed after adjusting app.Asym, because user should really be selecting peaks next.
            end % Hopefully this preserves the old way of being able to just hit fit after changing profile functions
            
           try
               nnC=length(app.profiles.FitInitial.coeffs);
           catch
           end

            cla(app.UIAxes);
            ui.update(app, 'fitinitial');
            utils.plotutils.plotX(app,'sample');

            app.gui.PriorityStatus = 'Fit options were updated.';
            if s==0 && l==0 && u==0
            else
            if s==1 || s==nC || s~=nnC
                app.gui.PriorityStatus='Now Select Peak Posistions using Select Peak(s)';
            end
            end

            % To preserve previous conditions, must happen as the last few
            % commands 
            app.UITable2.ColumnEditable=true(1,3);
            app.CheckBox0=[fliplr([app.ConstraintsPanel.Children.Value]) app.AsymmetryCheckBox.Value]; % Checkboxes
            app.Coefficients=app.UITable2.RowName';
            app.SelectPeaksButton.Enable=1;
            app.UITable2.ColumnWidth = 'fit';   % default automatic sizing
            app.UITable2.ColumnWidth = 'auto';   % default automatic sizing


        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            data=app.UITable.Data; % will be used to check if any are empty

            % Check each cell for emptiness
            emptyMask = cellfun(@isempty, data);
       
            if ~isequal(app.Fxn0,app.UITable.Data) % update require since PFS were changed
                app.SelectPeaksButton.Enable=0;
                app.FitDataButton.Enable=0;
            elseif isequal(app.Fxn0,app.UITable.Data) % reset if checked back to original before hiting update
                app.SelectPeaksButton.Enable=1;
                app.FitDataButton.Enable=1;
            end

            row = event.Indices(1);
            col = event.Indices(2);
            if col == 1
                % Function change
                app.profiles.FcnNames{row} = app.gui.FcnNames{row}; % does not capture havign multiple functions, reducing to one, the continually changing that one function.
                ui.update(app, 'functions');
                try
                app.profiles.xrd.constrain(app.gui.Constraints);
                catch
                app.profiles.FcnNames= app.gui.FcnNames; % force function reset.
                app.profiles.xrd.constrain(app.gui.Constraints);

                end
                ui.update(app, 'constraints');
            else
                % On constraint value change
                app.profiles.xrd.unconstrain('Nxfwm');
                app.profiles.xrd.constrain(app.gui.ConstraintsInTable);
                ui.update(app, 'Constraints');
            end

            if any(emptyMask, 'all')
                % disp("There is at least one empty slot in the UITable.");
                % [row, col] = find(emptyMask);
                % fprintf("Empty at row %d, column %d\n", row, col);
                app.SelectPeaksButton.Enable=0;

            else
                app.SelectPeaksButton.Enable=1;
                UpdateButtonPushed(app, event) % to remove 'Update' button, 1-6-2026
                % disp("All cells are filled.");
            end  
            
            app.UITable.ColumnWidth = {'auto', 'fit'};   % 120 px for col 1, col 2 fits content

        end

        % Button pushed function: SelectPeaksButton
        function SelectPeaksButtonPushed(app, event)
            % Seletect Peak Positions for estimates...
            % Another Windows button down

            if app.NumberofPeaksSpinner.Value==0
            app.HTML.HTMLSource=app.formatHTML('Add peak(s) to continue... ');
            app.PeakSelect='off';
                return
            end
            pd=sum(cellfun(@isempty, app.UITable.Data));
            if pd>0                
            app.HTML.HTMLSource=app.formatHTML('Define profile shape functions first...');
                return
            elseif isempty(app.profiles.xrd)
            app.HTML.HTMLSource=app.formatHTML('Input data to continue...');
            app.PeakSelect='off';
                return
            end
            if isempty(app.profiles.BackgroundPoints)
            app.HTML.HTMLSource=app.formatHTML('Select background to continue...');
            app.PeakSelect='off';
            app.SelectPeaksButton.Enable=0;
            uialert(app.figure1, 'Select background to continue...', 'Notice'); % handy alert

                return
            else % Bkg not empty, need to remove points from bkg selection
            delete(app.UIAxes.Children(strcmp({app.UIAxes.Children.Tag},'Bkgpts')));
            
            end
            
            % Ensure  peak number wasnt increased, then Peak Select, before hitting 'Update'
            tableNames = app.UITable.Data(:);              % force column
            fcnNames   = app.profiles.getInstance.FcnNames(:);
            if isequal(tableNames, fcnNames)
                % same → proceed
                utils.plotutils.plotX(app, 'sample');
            else
                %  different → do X
            app.HTML.HTMLSource=app.formatHTML('"Update" before Peak Select'); % This shouldnt be occuring with more of Update occuring in the background
            pause(1);
            UpdateButtonPushed(app, event)
            utils.plotutils.plotX(app, 'sample');
            end

             try % Make sure all previous peaks selected that would get ploted are deleted, 1-6-2026
             objs = findall(app.UIAxes.Children, '-property', 'Tag');
             sampleObjs = objs(contains(string({objs.Tag}), "sample", 'IgnoreCase', true));
             delete(sampleObjs)
             catch
             end

            app.HTML.HTMLSource=app.formatHTML('Select peak positions...');
            app.PeakSelect='on';
            app.KeyPressed='0';
            app.PeakPositions=[]; % reset everytime its time to select new peaks

            delete(findall(app.UIAxes, 'Tag', 'PeakS')); % Delete all previously selected points if they exist, tag matches what is in selectPeakPointsXXX
        end

        % Menu selected function: menu_close
        function menu_closeMenuSelected(app, event)
            delete(app.gui);
            delete(app.profiles);    
            app.delete
            clear('app', 'var');
        end

        % Value changed function: AsymmetryCheckBox
        function AsymmetryCheckBoxValueChanged(app, event)
        % Get checkbox state
        asymValue = double(app.AsymmetryCheckBox.Value);
        app.Asym = asymValue;
        
        % Update Asym property for each FitFunction
        nFxn = numel(app.profiles.xrd.FitFunctions);
        for p = 1:nFxn
            app.profiles.xrd.FitFunctions{p}.Asym = asymValue;
        end
        
        % Get current constraint values
        currentValues = [fliplr([app.ConstraintsPanel.Children.Value]), asymValue];
        
        % Check consistency with initial state
        matchInitial = isequal(app.CheckBox0, currentValues);
        isSameToggle = (app.CheckBox0(6) == asymValue);
        
        % Determine UI state
        state = 'on';
        if ~(isSameToggle && matchInitial)
            state = 'off';
        end
        
        % Update UI controls
        app.FitDataButton.Enable = state;
        app.SelectPeaksButton.Enable = state;
        app.UITable2.Enable = state;

        UpdateButtonPushed(app, event) % added 1-5-2026, attempt to remove 'Update' button
        end

        % Button pushed function: FitDataButton
        function FitDataButtonPushed(app, event)
         if Validate_bkg(app)
            return
        end       
        if isempty(app.UITable2.Data)
                    app.HTML.HTMLSource=app.formatHTML('Select peaks to continue...');
            return
        end

        if strcmp(app.profiles.xrd.getBackgroundModel,'Spline') && app.RefineBkgCheckBox.Value==1
            app.gui.PriorityStatus = '<html><font color="red">Reverting to polynomial background since Refine Bkg is selected.';
          pause(0.5)
        end
        
        
        % From Preferences
        kids = app.PreferencesMenu.Children(8).Children;
        idx  = strcmp({kids.Checked}, "on");
        checkedItem = kids(idx); % Fixes issue of when loading new data it takes what is selected in the GUI
        app.profiles.Weights=checkedItem.Text;
        app.profiles.xrd.Weights=app.profiles.Weights;
        app.profiles.xrd.UniqueSave=app.profiles.UniqueSave;

        try
            app.profiles.xrd.FitInitial.lower=app.gui.FitInitial.lower;
            app.profiles.xrd.FitInitial.upper=app.gui.FitInitial.upper;
        catch
        end

        try
            prfn = app.profiles.ActiveProfile;
            fitresults = app.profiles.fitDataSet(prfn, app.HTML);
        
            % so when switching constraints, the table won’t update with fitted values until a fit is done
            app.profiles.xrd.OriginalFitInitial.coeffs = app.gui.FitInitial.coeffs;
        
            hasFit = ~isempty(fitresults);
        
            if hasFit
                ui.update(app, 'results');
                utils.plotutils.plotX(app, 'fit');
        
                if ~ishold(app.UIAxes)
                    hold(app.UIAxes);
                end
            else
                utils.plotutils.plotX(app, 'sample');
            end
        
        catch ME
            assignin('base', 'lastException', ME);
            errordlg(ME.message);
            return
        end

        app.HTML.HTMLSource=app.formatHTML('Fitting complete...');
        app.FilesListBox.Items=app.profiles.FileNames;
        app.FilesListBox.ItemsData=1:length(app.profiles.FileNames);
        app.FilesListBox.Value=app.DropDown.Value;
                % Extract result values
                fitResult = app.profiles.FitResults{1}{app.DropDown.Value};
                rchi2 = round(fitResult.Rchi2, 2);
                rp    = round(fitResult.Rp, 2);
                rwp   = round(fitResult.Rwp, 2);
                
                % Create formatted HTML
                html = sprintf([ ...
                    '<div align="left"><font size="2" face="Helvetica"><b>', ...
                    '&Chi;<sup>2</sup> = %.2f, ', ...
                    'R<sub>p</sub> = %.2f%%, ', ...
                    'R<sub>wp</sub> = %.2f%%', ...
                    '</b></font></div>'], ...
                    rchi2, rp, rwp);
                
                % Assign to component
                app.HTML2.HTMLSource = html;
                app.UITable3.ColumnWidth='fit';
                app.ScaleID=1; % Means, you can scale Y-axis
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            indices = event.Indices; % Index of edited cell
            newData = event.NewData; % New value
            
            if isnan(newData)
                app.gui.PriorityStatus = '<html><font color="red">Not a valid number.';
	            hObject.Data{event.Indices(1), event.Indices(2)} = event.PreviousData;
            else
                app.profiles.FitInitial=app.gui.FitInitial;
                emptyCell = find(cellfun(@isempty, app.UITable2.Data), 1);
            if isempty(emptyCell) 
            set(app.FitDataButton, 'enable', 'on');
            pause(0.5) % this is needed otherwise the GUI is too fast and wont activate...
            else
            set(app.FitDataButton, 'enable', 'off');
            end
            utils.plotutils.plotX(app, 'sample');
            end          
        end

        % Value changed function: NCheckBox
        function NCheckBoxValueChanged(app, event)
            o = event;
            if app.NumberofPeaksSpinner.Value==1
                kids = app.ConstraintsPanel.Children; % reset all constraint boxes
                for k = 1:numel(kids)
                    if isprop(kids(k),'Value')
                        kids(k).Value = 0;   % uncheck/off
                    end
                end
                return % Do nothing on constraints
            end 
            
            if or(isempty(app.profiles.xrd),isempty(app.UITable2.Data))
                app.HTML.HTMLSource=app.formatHTML('Follow through steps to do a fit...');
                return
            end
%             c=[fliplr([app.ConstraintsPanel.Children.Value]) app.AsymmetryCheckBox.Value];         
            if o.Value
                app.profiles.xrd.constrain(o.Source.Text);
            else
                app.profiles.xrd.unconstrain(o.Source.Text);
            end
            ui.update(app, 'Constraints');

            app.UITable.ColumnWidth = {'auto', 'fit'};   % 120 px for col 1, col 2 fits content

            UpdateButtonPushed(app); % to update, table 2-12-26
        end

        % Value changed function: xCheckBox
        function xCheckBoxValueChanged(app, event)
            
            NCheckBoxValueChanged(app,event)

        end

        % Value changed function: fCheckBox
        function fCheckBoxValueChanged(app, event)
            NCheckBoxValueChanged(app,event)

        end

        % Value changed function: wCheckBox
        function wCheckBoxValueChanged(app, event)
            NCheckBoxValueChanged(app,event)

        end

        % Value changed function: mCheckBox
        function mCheckBoxValueChanged(app, event)
            NCheckBoxValueChanged(app,event)

        end

        % Value changed function: FilesListBox
        function FilesListBoxValueChanged(app, event)
        % Change the call back of when a file is selected for plotting
        if strcmp(app.PlotViewsButtonGroup.SelectedObject.Text,'Coefficient(s)')==1   
                if app.profiles.hasFit
                    app.FilesListBox.Items=app.profiles.FitInitial.coeffs;
                    app.FilesListBox.ItemsData=1:length(app.profiles.FitInitial.coeffs);
                    utils.plotutils.plotX(app,'coeff')             
                end
        else
                app.FilesListBox.Items=app.profiles.FileNames;
                app.FilesListBox.ItemsData=1:length(app.profiles.FileNames);
            
                if  app.profiles.hasFit
                    value = app.FilesListBox.Value;
                    app.DropDown.Value=value;
                    app.DropDownValueChanged
                    utils.plotutils.plotX(app,'fit','list')
                else
                    utils.plotutils.plotX(app,'data')
                end        
        end
        end

        % Selection changed function: PlotViewsButtonGroup
        function PlotViewsButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotViewsButtonGroup.SelectedObject;
            if isempty(app.profiles.xrd)
                    app.HTML.HTMLSource=app.formatHTML('Do a fit before toggling these option');
                    if isempty(app.profiles.FitResults)
                    app.HTML.HTMLSource=app.formatHTML('Do a fit before toggling these option');
                    end
                return
            end
        if strcmp(selectedButton.Text,'Coefficient(s)')==1   
                    if app.profiles.hasFit
                        app.FilesListBox.Items=app.profiles.FitInitial.coeffs;
                        app.FilesListBox.ItemsData=1:length(app.profiles.FitInitial.coeffs);
                        app.FilesListBoxLabel.Text='Coefficient(s)';
                        utils.plotutils.plotX(app,'coeff')
                        app.ScaleID=0; % Means, you can not scale Y-axis
                        app.HTML.HTMLSource=app.formatHTML('Y-Scale and Math are OFF');


                    else

                    end   
        else
            app.FilesListBox.Items=app.profiles.FileNames;
            app.FilesListBox.ItemsData=1:length(app.profiles.FileNames);
            app.FilesListBox.Value=app.DropDown.Value;
            app.FilesListBoxLabel.Text='File(s)';
            app.ScaleID=1; % Means, you can scale Y-axis
            app.HTML.HTMLSource=app.formatHTML('Y-Scale and Math are ON');


                    if  app.profiles.hasFit
                        value = app.FilesListBox.Value;
                        app.DropDown.Value=value;
                        app.DropDownValueChanged % Plots fit
                        % utils.plotutils.plotX(app,'fit','list') % double plot
                    else
                        utils.plotutils.plotX(app,'data')
                    end        
        end
            
        end

        % Button pushed function: FitStatisticsButton
        function FitStatisticsButtonPushed(app, event)
            if ~isempty(app.profiles.xrd) %check to see if data has been loaded
                if isempty(app.profiles.FitResults) % checks for fits
                    app.HTML.HTMLSource=app.formatHTML('Do a fit before toggling Fit Statistics');
                else
                    utils.plotutils.plotX(app,'stats')
                end   
            end
        end

        % Button pushed function: AllFitsButton
        function AllFitsButtonPushed(app, event)
            if ~isempty(app.profiles.xrd) %check to see if data has been loaded
                if isempty(app.profiles.FitResults) % checks for fits
                    app.HTML.HTMLSource=app.formatHTML('Do a fit before toggling All Fits');
                elseif length(app.profiles.FileNames)>25
                    uialert(app.figure1, 'Plotting supported for up to 25 subplots', 'Notice'); % handy alert
                    return
                    
                else
                    utils.plotutils.plotX(app,'allfits')
                end   
            end                      
        end

        % Value changed function: CuK2CheckBox
        function CuK2CheckBoxValueChanged(app, event)
            value = app.CuK2CheckBox.Value;
            if value==1
                app.profiles.xrd.CuKa=true;
                app.profiles.KAlpha1 = 1.5405980;
                app.profiles.KAlpha2 = 1.5444260;
            else
                app.profiles.xrd.CuKa=false;
            end
            app.UpdateButtonPushed(app)
        end

        % Menu selected function: ResetSizeMenu
        function ResetSizeMenuSelected(app, event)
            app.figure1.Position=[100,100,1100,713]; % Change here to specify the size you want
        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)

        end

        % Button down function: ConstraintsPanel
        function ConstraintsPanelButtonDown(app, event)

        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)

        end

        % Menu selected function: UniqueSaveMenu
        function UniqueSaveMenuSelected(app, event)

        end

        % Menu selected function: YesMenu_3
        function YesMenu_3Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.NoMenu_3.Checked=0;
            app.profiles.UniqueSave=1;
            end
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            value = app.ModelDropDown.Value;
            if isempty(app.profiles.xrd)
            disp('Add data...')
            else
            app.profiles.xrd.setBackgroundModel(value);
            end
            PolyOrderSpinnerValueChanged(app,event) % in event it is changed, for spline, it will reset to '2'            
        end

        % Menu selected function: NoMenu_3
        function NoMenu_3Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.YesMenu_3.Checked=0;
            app.profiles.UniqueSave=0;
            end
        end

        % Menu selected function: LeastSquaresWeightsMenu
        function LeastSquaresWeightsMenuSelected(app, event)

        end

        % Menu selected function: NoneMenu_2
        function NoneMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.DefaultMenu_2.Checked=0;
            app.obsMenu_2.Checked=0;
            app.sqrtobsMenu_2.Checked=0;
            app.profiles.Weights='None';
            end
        end

        % Menu selected function: obsMenu_2
        function obsMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.DefaultMenu_2.Checked=0;
            app.NoneMenu_2.Checked=0;
            app.sqrtobsMenu_2.Checked=0;
            app.profiles.Weights='1/obs';
            end
        end

        % Menu selected function: sqrtobsMenu_2
        function sqrtobsMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.DefaultMenu_2.Checked=0;
            app.obsMenu_2.Checked=0;
            app.NoneMenu_2.Checked=0;
            app.profiles.Weights='1/sqrt(obs)';
            end
        end

        % Menu selected function: DefaultMenu_2
        function DefaultMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.NoneMenu_2.Checked=0;
            app.obsMenu_2.Checked=0;
            app.sqrtobsMenu_2.Checked=0;
            app.profiles.Weights='Default';
            end
        end

        % Menu selected function: DPIMenu_6
        function DPIMenu_6Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.DPIMenu_7.Checked=0;
            app.DPIMenu_8.Checked=0;
            app.profiles.ImageRes=' 100 DPI';
            end
        end

        % Menu selected function: DPIMenu_7
        function DPIMenu_7Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.DPIMenu_6.Checked=0;
            app.DPIMenu_8.Checked=0;
            app.profiles.ImageRes=' 200 DPI';
            end
        end

        % Menu selected function: DPIMenu_8
        function DPIMenu_8Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.DPIMenu_7.Checked=0;
            app.DPIMenu_6.Checked=0;
            app.profiles.ImageRes=' 300 DPI';
            end
        end

        % Menu selected function: TIFFMenu_2
        function TIFFMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.JPEGMenu_2.Checked=0;
            app.PNGMenu_2.Checked=0;
            app.PDFMenu_2.Checked=0;
            app.BMPMenu_2.Checked=0;
            app.EPSCMenu_2.Checked=0;
            app.SVGMenu_2.Checked=0;
            app.profiles.ImageFormat=event.Source.Text;
            end
        end

        % Menu selected function: JPEGMenu_2
        function JPEGMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.TIFFMenu_2.Checked=0;
            app.PNGMenu_2.Checked=0;
            app.PDFMenu_2.Checked=0;
            app.BMPMenu_2.Checked=0;
            app.EPSCMenu_2.Checked=0;
            app.SVGMenu_2.Checked=0;
            app.profiles.ImageFormat=event.Source.Text;
            end            
        end

        % Menu selected function: PNGMenu_2
        function PNGMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.JPEGMenu_2.Checked=0;
            app.TIFFMenu_2.Checked=0;
            app.PDFMenu_2.Checked=0;
            app.BMPMenu_2.Checked=0;
            app.EPSCMenu_2.Checked=0;
            app.SVGMenu_2.Checked=0;
            app.profiles.ImageFormat=event.Source.Text;

            end            
        end

        % Menu selected function: PDFMenu_2
        function PDFMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.JPEGMenu_2.Checked=0;
            app.PNGMenu_2.Checked=0;
            app.TIFFMenu_2.Checked=0;
            app.BMPMenu_2.Checked=0;
            app.EPSCMenu_2.Checked=0;
            app.SVGMenu_2.Checked=0;
            app.profiles.ImageFormat=event.Source.Text;

            end            
        end

        % Callback function
        function BMPMenu_2Selected(app, event)
% delete function           
        end

        % Callback function
        function EPSCMenu_2Selected(app, event)
% delete function       
        end

        % Callback function
        function SVGMenu_2Selected(app, event)
% delete function
           
        end

        % Menu selected function: YesMenu_4
        function YesMenu_4Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.NoMenu_4.Checked=0;
            app.profiles.ImageSaveAll=1;

            end
        end

        % Menu selected function: NoMenu_4
        function NoMenu_4Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.YesMenu_4.Checked=0;
            app.profiles.ImageSaveAll=0;

            end            
        end

        % Menu selected function: LegendOn
        function LegendOnSelected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.LegendOff.Checked=0;
            % show legend for that axes
            app.UIAxes.Legend.Visible='on';
            app.LegendBayes.Visible = 'on';   % or 'on'
            app.LegendBayes.Location = 'northeast';
            % toolbar_legend_OnCallback(app)
                % try legend(app.axbayes, {'Obs','LS','Bayesian','Low','High'}, ...
                %        'Location','best', 'FontSize', 8); catch; end
            end
        end

        % Menu selected function: LegendOff
        function LegendOffSelected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.LegendOn.Checked=0;
            app.UIAxes.Legend.Visible='off';
            app.LegendBayes.Visible = 'off';   % or 'on'
            app.LegendBayes.Location = 'northeast';
                % toolbar_legend_OffCallback(app)
                % try legend(app.axbayes,'off'); catch; end
            end
        end

        % Menu selected function: ImageResolutionMenu
        function ImageResolutionMenuSelected(app, event)
            
        end

        % Menu selected function: ImageSaveAllMenu
        function ImageSaveAllMenuSelected(app, event)
            
        end

        % Menu selected function: SelectMenu_2
        function SelectMenu_2Selected(app, event)
        % Prompt user to select a directory
            startingPath = pwd; % Specify the initial path
            selectedDir = uigetdir(startingPath, 'Select a Folder');
            
            % Check if the user selected a directory or canceled
            if selectedDir ~= 0
                disp(['Selected Directory: ', selectedDir]);
            else
                disp('User canceled the selection.');
            end
            app.profiles.DataPath=selectedDir;

        end

        % Menu selected function: SavePreferenceFileMenu
        function SavePreferenceFileMenuSelected(app, event)
        SavePref(app)
        app.HTML.HTMLSource=app.formatHTML("Preference file saved");

        % Create one-shot timer to clear it after 3 seconds
        t = timer( ...
            'ExecutionMode', 'singleShot', ...
            'StartDelay', 3, ...
            'TimerFcn', @(~,~) clearHtmlMessage(app));
        
        start(t);
        
        % Optional cleanup when timer finishes
        t.StopFcn = @(src,~) delete(src);

        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            % Everytime a click occurs in UITable this gets triggered
            
        end

        % Value changed function: EditFieldWave
        function EditFieldWaveValueChanged(app, event)
            value = app.EditFieldWave.Value;
            if ~app.profiles.hasData
                app.HTML.HTMLSource=app.formatHTML('Changes will be implemented after data import...');
                return
            end
            if isa(app.profiles.xrd.MonoWavelength,'numeric')
            wave=app.profiles.xrd.MonoWavelength;
            end
                    if isempty(value)
                        
                        app.HTML2.HTMLSource=app.formatHTML("Enter a valid value for wavelength in Å");
                        app.EditFieldWave.Value=1.5406; % default back
                        return
                    elseif isnumeric(value)
                        app.profiles.KAlpha1 = value;
                    else
                        app.HTML2.HTMLSource=app.formatHTML("Enter a valid value for wavelength in Å");
                        app.EditFieldWave.Value=1.5406; % default back     
                        return
                    end

        end

        % Button down function: BayesianTab
        function BayesianTabButtonDown(app, event)
       

            if ~app.profiles.hasData
                app.HTML.HTMLSource=app.formatHTML('Input data to continue...');
                uialert(app.figure1, 'Input data to continue, and do least squares fit before using Bayesian', 'Notice'); % handy alert

                app.TabGroup.SelectedTab=app.SetupTab;
                pause(1)

                return
            elseif isempty(app.profiles.FitResults)
                app.HTML.HTMLSource=app.formatHTML('Least squares fit must be done before using Bayesian');
                uialert(app.figure1, 'Least squares fit must be done before using Bayesian', 'Notice'); % handy alert

                app.TabGroup.SelectedTab=app.SetupTab;
                pause(1)

                return
            end    

            if app.profiles.hasFit && app.PlotViewsButtonGroup.Buttons(2).Value % Defaults to peak fit since any changes to fit parameters or funcstions leads to error, 2-3-2026
                app.PlotViewsButtonGroup.Buttons(1).Value=1; % Also, YScale stops working when Coefficients is used
                PlotViewsButtonGroupSelectionChanged(app);
            end
            
            if ~isempty(app.BD)
            app.SubplotPanel.Visible="on"; % Turns Bayesian plot on, will need something that checks if Bayes has been done 
            end

        % Optional alert
        % uialert(app.figure1, 'Bayesian tab not available yet.', 'Notice'); % handy alert
        % Redirect to Setup
        % app.TabGroup.SelectedTab=app.SetupTab;

        % I think UITable4 should be updated everytime a Fit is done
        app.UITable4.RowName=app.UITable2.RowName;

        idBkg=app.profiles.xrd.getBackgroundOrder+2; % to remove Bkg Coeffs since they will not be in Bayesian analysis

        if any(contains(app.UITable4.RowName,'bkg'))
            idBkg=1;
            app.IncludeBackgroundCheckBox.Value=1;
        else
                idBkg=sum(contains(app.profiles.FitResults{1}{1}.CoeffNames,'bkg'))+1; % to remove Bkg Coeffs when they will not be in Bayesian analysis
        end

    app.UITable4.Data=[app.profiles.FitResults{1}{1}.CoeffValues(idBkg:end)'...
    app.profiles.FitResults{1}{1}.CoeffValues(idBkg:end)'-app.profiles.FitResults{1}{1}.CoeffError(idBkg:end)'...
    app.profiles.FitResults{1}{1}.CoeffValues(idBkg:end)'+app.profiles.FitResults{1}{1}.CoeffError(idBkg:end)' app.profiles.FitResults{1}{1}.CoeffError(idBkg:end)'/1.96];

        end

        % Button pushed function: RunBayesianButton
        function RunBayesianButtonPushed(app, event)
            SP=app.UITable4.Data(:,1)';
            LB=app.UITable4.Data(:,2)';
            UB=app.UITable4.Data(:,3)';
            SD=app.UITable4.Data(:,4)';
            Sig2=app.SPEditField.Value;
            Sig2SD=app.SDEditField.Value;
            Sig2LB=app.LBEditField.Value;
            Sig2UB=app.UBEditField.Value;
            iterations=app.IterationsEditField.Value;
            burnin=app.BurninEditField.Value;
            if app.AutoCheckBox.Value; Naive='on'; app.DefaultButton.Value=1; app.LBUBDropDown.Value='3'; else; Naive='off'; end % Naive is the auto function and requires Default bounds
            if app.DefaultButton.Value; Default='on'; app.LBUBDropDown.Value='1'; app.SDDropDown.Value='1'; else; Default='off'; end
            if app.IncludeBackgroundCheckBox.Value; BayesBkg=1; else; BayesBkg=0; end

            [app.BD, status]=model.BayesLIPRAS(app, SP, LB, UB, SD, Sig2, Sig2SD, Sig2UB, Sig2LB,iterations, burnin,Naive,Default,BayesBkg);

            if ~status.success
            app.HTML.HTMLSource=app.formatHTML(status.message);
                return
            end

            % Update Tables 
            % app.UITable5.Data=app.BD.acc_ratio;
            % app.UITable5.Data(:,2:end)=[];

            app.UITable4.RowName=app.profiles.FitResults{1}{1}.CoeffNames(app.BD.idBkg:end);
            % app.UITable5.RowName=app.UITable4.RowName;
            app.FilesListBox_2.Items=app.profiles.FileNames;
            app.FilesListBox_2.Value=app.profiles.FileNames{1}; % default to first name selected
            
            custB=app.CustomButton.Value;
            if custB==1||app.AutoCheckBox.Value
            app.UITable4.RowName=app.profiles.FitResults{1}{1}.CoeffNames(app.BD.idBkg:end);
            app.UITable4.Data=[app.BD.SP' app.BD.LB' app.BD.UB' app.BD.param_sd'];
            else
            end

            % Update Sigma2 to Edit fields, is this really needed?
            app.SPEditField.Value=app.BD.sigma2;
            app.SDEditField.Value=app.BD.sigma2sd;
            app.LBEditField.Value=app.BD.sigma2lb;
            app.UBEditField.Value=app.BD.sigma2ub;

            idBkg=sum(contains(app.profiles.FitResults{1}{1}.CoeffNames,'bkg'))+1; % to remove Bkg Coeffs when they will not be in Bayesian analysis
            if app.IncludeBackgroundCheckBox.Value 
                        idBkg=1; % Not sure i follow this, i think its when box is checked that it forces removal
            else
            end

            if length(app.UITable4.Data(:,1))~=length(app.profiles.FitResults{1}{1}.CoeffNames(idBkg:end))
            try
                app.UITable4.Data=[app.BD.SP' app.BD.LB' app.BD.UB' app.BD.param_sd'];
            catch
                app.UITable4.Data=[app.profiles.FitResults{1}{1}.CoeffValues(idBkg:end)'...
                app.profiles.FitResults{1}{1}.CoeffValues(idBkg:end)'-app.profiles.FitResults{1}{1}.CoeffError(idBkg:end)'...
                app.profiles.FitResults{1}{1}.CoeffValues(idBkg:end)'+app.profiles.FitResults{1}{1}.CoeffError(idBkg:end)' app.profiles.FitResults{1}{1}.CoeffError(idBkg:end)'/1.96];
            end
            end

            idF=app.FilesListBox_2.ValueIndex; % this will be based on which file to view
            
           BayesPlot(app,idF) % plots based on selected Trace or Histogram Option

% Report Metrics

                % Extract result values
                rp_ls    = round(app.profiles.FitResults{1}{idF}.Rp, 2);
                rp_bayes   = round(app.BD.Rp, 2);
                app.UITable4.ColumnWidth = {'fit'};

        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            value = app.IterationsEditField.Value;
            
        end

        % Button down function: ResultsTab
        function ResultsTabButtonDown(app, event)
            app.SubplotPanel.Visible="off"; % Turns Bayesian plot off when tabing away

        end

        % Button down function: SetupTab
        function SetupTabButtonDown(app, event)
            app.SubplotPanel.Visible="off";  % Turns Bayesian plot off when tabing away
            
            
            if app.profiles.hasFit && app.PlotViewsButtonGroup.Buttons(2).Value % Defaults to peak fit since any changes to fit parameters or funcstions leads to error, 2-3-2026
                app.PlotViewsButtonGroup.Buttons(1).Value=1;
                PlotViewsButtonGroupSelectionChanged(app);
            end
                        
        end

        % Selection changed function: UITable2
        function UITable2SelectionChanged(app, event)
            selection = app.UITable2.Selection;
            
        end

        % Cell edit callback: UITable4
        function UITable4CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Value changed function: FilesListBox_2
        function FilesListBox_2ValueChanged(app, event)
            value = app.FilesListBox_2.Value;
            
        end

        % Selection changed function: PlottingandResultsButtonGroup
        function PlottingandResultsButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlottingandResultsButtonGroup.SelectedObject;
            
        end

        % Button pushed function: PlotBayesFitButton
        function PlotBayesFitButtonPushed(app, event)
            
            % --- Clear previous layout (if it exists)
            if ~isempty(app.SubplotGrid) && isvalid(app.SubplotGrid)
                delete(app.SubplotGrid)
            end
            
            % --- Bayesian results check
            if isempty(app.BD)
                app.HTML.HTMLSource = app.formatHTML("Run Bayesian before plotting");
                return
            end
            % --- Selected files (force into cell array of strings)
            selected = app.FilesListBox_2.Value;
            if ischar(selected) || isstring(selected)
                selected = cellstr(selected);
            end
            n = numel(selected);

            if n>25
                uialert(app.figure1, 'Plotting supported for up to 25 subplots', 'Notice'); % handy alert
                return
            end
            
            % --- Determine grid (square-ish)
            subD = ceil(sqrt(n));
            subD = min(subD, 5);   % max 5×5
            
            % --- Create layout
            app.SubplotGrid = uigridlayout(app.SubplotPanel, [subD subD]);
            app.SubplotGrid.RowHeight   = repmat({'1x'}, 1, subD);
            app.SubplotGrid.ColumnWidth = repmat({'1x'}, 1, subD);
            app.SubplotGrid.Padding     = [2 2 2 2];
            
            % --- Preallocate axes
            app.axbayes = gobjects(n,1);
            
            % =======================
            %     MAIN PLOTTING
            % =======================
            for k = 1:n
                % Create axis
                app.axbayes(k) = uiaxes(app.SubplotGrid);
                ax = app.axbayes(k);
                hold(ax, 'on');
                box(ax, 'on');
            
                % Get file index
                idF = find(strcmp(app.FilesListBox_2.Items, selected{k}), 1);
            
                % --- Extract data
                x = app.profiles.FitResults{1}{idF}.TwoTheta;
                curve = app.profiles.xrd.getData(idF);
            
                % LS fit (with or w/o background depending on refinement)
                if any(contains(app.profiles.FitResults{1}{1}.CoeffNames, 'bkg'))
                    curve1 = app.profiles.FitResults{1}{idF}.FData;
                else
                    curve1 = app.profiles.FitResults{1}{idF}.FData + ...
                             app.profiles.FitResults{1}{idF}.Background;
                end
            
                % Background handling for Bayesian
                Bkg = app.profiles.FitResults{1}{idF}.Background;
                if any(contains(app.BD.coeff, 'bkg'))
                    Bkg = 0;
                end
            
                curve2 = app.BD.fit_mean{idF} + Bkg;
                curve3 = app.BD.fit_low{idF}  + Bkg;
                curve4 = app.BD.fit_high{idF} + Bkg;
            
                % --- Plot raw, LS, Bayes
                plot(ax, x, curve, 'o', ...
                    'Color', [0 0.17 0.5], 'MarkerFaceColor', [0 0.17 0.5], 'MarkerSize', 4);
                plot(ax, x, curve1, '-', 'Color', [0 0.5 0], 'LineWidth', 1.5);
                plot(ax, x, curve2, '-', 'Color', 'k', 'LineWidth', 1.5);
                plot(ax, x, curve3, '-b', 'LineWidth', 1.5);
                plot(ax, x, curve4, '-r', 'LineWidth', 1.5);
            
                % --- Fill uncertainty bands
                x2 = [x, fliplr(x)];
                inBetween    = [curve2, fliplr(curve3)];
                inBetweenUp  = [curve2, fliplr(curve4)];
            
                fill(ax, x2, inBetween,  [0.5 0.5 0.5], 'FaceAlpha',0.25,'EdgeColor','none');
                fill(ax, x2, inBetweenUp,[0.5 0.5 0.5], 'FaceAlpha',0.25,'EdgeColor','none');
            
                % --- Axis limits
                ax.XLim = [app.profiles.Min2T, app.profiles.Max2T];
            
                % labels/titles
                xlabel(ax, '2\theta (°)', 'FontWeight','bold');
                ylabel(ax, 'Intensity (a.u.)', 'FontWeight','bold');
                title(ax, ['File: ', app.profiles.FileNames{idF}]);
            
                if n>9; fontS=9;else; fontS=12;end % makes legend smaller


                % ---- Rp annotation
                yl = ax.YLim; xl = ax.XLim;
                text(ax, xl(1)*1.005, yl(2)*0.95, ...
                    sprintf('Rp_{LS} = %.2f%%', app.profiles.FitResults{1}{idF}.Rp), ...
                    'FontSize', fontS+2, 'FontWeight','bold');
            
                text(ax, xl(1)*1.005, yl(2)*0.90, ...
                    sprintf('Rp_{Bayes} = %.2f%%', app.BD.Rp(idF)), ...
                    'FontSize', fontS+2, 'FontWeight','bold');
            
            % --- Legend
            app.LegendBayes = legend(ax, {'Obs','LS','Bayesian','Low','High'}, ...
                'Location','northeast', 'FontSize', fontS);

            if app.LegendOff.Checked
            app.LegendBayes.Visible = 'off';   % or 'on'

            end

            app.axbayes(k).XLabel.FontWeight = 'bold'; app.axbayes(k).YLabel.FontWeight = 'bold';
                set(app.axbayes(k),'FontWeight','bold')
            end
                    linkaxes(app.axbayes, 'x')        % Added 2-15-2026

            app.axbayesid=1; % Id so YScale works on bayesplots
            app.HTML.HTMLSource=app.formatHTML('Y-Scale and Math are ON for Bayes Plot');

            menu_yplotscale_Callback(app,event)
        end

        % Value changed function: LBUBDropDown
        function LBUBDropDownValueChanged(app, event)
            value = app.LBUBDropDown.Value;

            if app.CustomButton.Value % Only change when Custom is on
            idBkg=app.profiles.xrd.getBackgroundOrder+2; % to remove Bkg Coeffs since they will not be in Bayesian analysis
    
            if any(contains(app.UITable4.RowName,'bkg'))
                idBkg=1;
                app.IncludeBackgroundCheckBox.Value=1;
            else
                    idBkg=sum(contains(app.profiles.FitResults{1}{1}.CoeffNames,'bkg'))+1; % to remove Bkg Coeffs when they will not be in Bayesian analysis
            end
    
            app.UITable4.Data(:,2)=app.profiles.FitResults{1}{1}.CoeffValues(idBkg:end)'-app.profiles.FitResults{1}{1}.CoeffError(idBkg:end)'*str2double(value);
            app.UITable4.Data(:,3)=app.profiles.FitResults{1}{1}.CoeffValues(idBkg:end)'+app.profiles.FitResults{1}{1}.CoeffError(idBkg:end)'*str2double(value);

            end
        end

        % Value changed function: SDDropDown
        function SDDropDownValueChanged(app, event)
            value = app.SDDropDown.Value;
            if app.CustomButton.Value % Only change when Custom is on
            idBkg=app.profiles.xrd.getBackgroundOrder+2; % to remove Bkg Coeffs since they will not be in Bayesian analysian
            if any(contains(app.UITable4.RowName,'bkg'))
                idBkg=1;
                app.IncludeBackgroundCheckBox.Value=1;
            else
                    idBkg=sum(contains(app.profiles.FitResults{1}{1}.CoeffNames,'bkg'))+1; % to remove Bkg Coeffs when they will not be in Bayesian analysis
            end
                app.UITable4.Data(:,4)=(app.profiles.FitResults{1}{1}.CoeffError(idBkg:end)'/1.96)*str2double(value);
            end
        end

        % Value changed function: IncludeBackgroundCheckBox
        function IncludeBackgroundCheckBoxValueChanged(app, event)
        value = app.IncludeBackgroundCheckBox.Value;
        % Update table to include bkg cofficients 

     
        if app.profiles.xrd.BkgLS && event.Value
% Everything defaults to first file, which may not be the case.
        idBkg=1;
        app.UITable4.RowName=app.profiles.FitResults{1}{1}.CoeffNames(idBkg:end);
        app.UITable4.Data=[app.profiles.FitResults{1}{1}.CoeffValues(idBkg:end)'...
        app.profiles.FitResults{1}{1}.CoeffValues(idBkg:end)'-app.profiles.FitResults{1}{1}.CoeffError(idBkg:end)'...
        app.profiles.FitResults{1}{1}.CoeffValues(idBkg:end)'+app.profiles.FitResults{1}{1}.CoeffError(idBkg:end)' app.profiles.FitResults{1}{1}.CoeffError(idBkg:end)'/1.96];
        else

        idBkg=sum(contains(app.profiles.FitResults{1}{1}.CoeffNames,'bkg'))+1; % to remove Bkg Coeffs when they will not be in Bayesian analysis
        app.UITable4.RowName=app.profiles.FitResults{1}{1}.CoeffNames(idBkg:end);
        app.UITable4.Data=[app.profiles.FitResults{1}{1}.CoeffValues(idBkg:end)'...
        app.profiles.FitResults{1}{1}.CoeffValues(idBkg:end)'-app.profiles.FitResults{1}{1}.CoeffError(idBkg:end)'...
        app.profiles.FitResults{1}{1}.CoeffValues(idBkg:end)'+app.profiles.FitResults{1}{1}.CoeffError(idBkg:end)' app.profiles.FitResults{1}{1}.CoeffError(idBkg:end)'/1.96];
        end
        end

        % Selection changed function: ControlsButtonGroup
        function ControlsButtonGroupSelectionChanged(app, event)
            selectedButton = app.ControlsButtonGroup.SelectedObject;

            sel = app.FilesListBox_2.Value;
            sel = cellstr(sel);        % guarantees a cell array
            n   = numel(sel);

            if n > 1 % For when user has multiselect on the file list
                app.FilesListBox_2.Value = app.FilesListBox_2.Value(1);
                app.HTML.HTMLSource=app.formatHTML("Only file selected to view Trace and Histogram");
            end

            idF=app.FilesListBox_2.ValueIndex; % this will be based on which file to view


            if ~isempty(app.BD)
                try
                BayesPlot(app,idF)
                catch
                    app.HTML.HTMLSource=app.formatHTML("Run Bayesian first...");
                end
          
            end
        end

        % Selection changed function: ParametersandBoundsButtonGroup
        function ParametersandBoundsButtonGroupSelectionChanged(app, event)
            selectedButton = app.ParametersandBoundsButtonGroup.SelectedObject;

            idBkg=app.profiles.xrd.getBackgroundOrder+2; % to remove Bkg Coeffs since they will not be in Bayesian analysis
    
            if any(contains(app.UITable4.RowName,'bkg'))
                idBkg=1;
                app.IncludeBackgroundCheckBox.Value=1;
            else
                    idBkg=sum(contains(app.profiles.FitResults{1}{1}.CoeffNames,'bkg'))+1; % to remove Bkg Coeffs when they will not be in Bayesian analysis
            end

        if strcmp(selectedButton.Text,'Custom')
            app.UITable4.Enable='on';
            app.UITable4.ColumnEditable = true(1, size(app.UITable4.Data, 2));
            app.UITable4.Data(:,2)=app.profiles.FitResults{1}{1}.CoeffValues(idBkg:end)'-app.profiles.FitResults{1}{1}.CoeffError(idBkg:end)'*str2double(app.LBUBDropDown.Value);
            app.UITable4.Data(:,3)=app.profiles.FitResults{1}{1}.CoeffValues(idBkg:end)'+app.profiles.FitResults{1}{1}.CoeffError(idBkg:end)'*str2double(app.LBUBDropDown.Value);
            app.UITable4.Data(:,4)=app.profiles.FitResults{1}{1}.CoeffError(idBkg:end)'/1.96/str2double(app.SDDropDown.Value);
        else % Default
            app.UITable4.Enable='off';
        end
        end

        % Button down function: figure1
        function figure1ButtonDown(app, event)
            % disp('y')
        end

        % Menu selected function: MathMenu
        function MathMenuSelected(app, event)
            
        end

        % Menu selected function: DerivativeMenu, IntegralMenu
        function DerivativeMenuSelected(app, event)
            % Executes when any option under 'Plot'->'Math' menu is clicked. The
            %   default selection is 'menu_ylinear'.
            % turn off checks in all x plot menu items
            plotter = app.gui.Plotter;
            o = string(event.Source.Text);

            if ~app.ScaleID; return; end % Do nothing because you are in 'coefficients'
            
            % handles
            mDer = app.DerivativeMenu;
            mInt = app.IntegralMenu;
            
            % Check what is checked in Y-Scale
            kids = app.menu_yaxis.Children;
            idx  = strcmp({kids.Checked}, "on");
            checkedText = kids(idx).Text;   % this will be 'Linear' / 'Logarithm' / 'Square Root'
            
            switch checkedText
                case 'Linear'
                    yscale = 'linear';
                case 'Logarithm'
                    yscale = 'log';
                case 'Square Root'
                    yscale = 'sqrt';
            end
            

            % --- toggle logic (mutually exclusive) ---
            if o == "Derivative"
                if strcmpi(mDer.Checked,"on")
                    % turn OFF derivative -> revert to linear
                    mDer.Checked = "off";
                    plotter.YScale =yscale;
                else
                    % turn ON derivative, turn OFF integral
                    mDer.Checked = "on";
                    mInt.Checked = "off";
                    plotter.YScale = 'Derivative';
                    yscale = 'Derivative';

                end
            
            elseif o == "Integral"
                if strcmpi(mInt.Checked,"on")
                    % turn OFF integral -> revert to linear
                    mInt.Checked = "off";
                    plotter.YScale = yscale;
                else
                    % turn ON integral, turn OFF derivative
                    mInt.Checked = "on";
                    mDer.Checked = "off";
                    plotter.YScale = 'Integral';
                    yscale = 'Integral';

                end
            end
            
            % --- keep your base y-scale menus consistent (always revert to linear here) ---
            % app.menu_ylinear.Checked = "on";
            % app.menu_yroot.Checked   = "off";
            % app.menu_ylog.Checked    = "off";
            
            % --- replot/transform (use your existing path) ---
            if strcmp(app.SubplotPanel.Visible,'on') && app.axbayesid
                transformData(app, app.axbayes, yscale);
            end
                    drawnow
                    figure(app.figure1)
        end

        % Menu selected function: YesMenu_5
        function YesMenu_5Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.NoMenu_5.Checked=0;
            app.profiles.KalphaAuto=1;
            end
        end

        % Menu selected function: NoMenu_5
        function NoMenu_5Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.YesMenu_5.Checked=0;
            app.profiles.KalphaAuto=0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'LIPRAS: Line-Profile Analysis Software';
            app.figure1.Icon = fullfile(pathToMLAPP, 'Logo', 'LIPRAS-Icon-v2.png');
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.WindowButtonDownFcn = createCallbackFcn(app, @figure1WindowButtonDown, true);
            app.figure1.WindowButtonMotionFcn = createCallbackFcn(app, @figure1WindowButtonMotion, true);
            app.figure1.ButtonDownFcn = createCallbackFcn(app, @figure1ButtonDown, true);
            app.figure1.WindowKeyPressFcn = createCallbackFcn(app, @figure1WindowKeyPress, true);
            app.menu_file = uimenu(app.figure1);
            app.menu_file.Text = 'File';
            app.menuoption_new = uimenu(app.menu_file);
            app.menuoption_new.Text = 'New...';
            app.menu_new = uimenu(app.menuoption_new);
            app.menu_new.MenuSelectedFcn = createCallbackFcn(app, @button_browse_Callback, true);
            app.menu_new.Accelerator = 'N';
            app.menu_new.Text = 'Dataset';
            app.Untitled_14 = uimenu(app.menu_file);
            app.Untitled_14.Text = 'Import...';
            app.FitParamImport = uimenu(app.Untitled_14);
            app.FitParamImport.MenuSelectedFcn = createCallbackFcn(app, @FitParamImport_Callback, true);
            app.FitParamImport.Accelerator = 'I';
            app.FitParamImport.Text = 'Fit Parameters';
            app.Untitled_12 = uimenu(app.menu_file);
            app.Untitled_12.Separator = 'on';
            app.Untitled_12.Text = 'Export...';
            app.FitParamExport = uimenu(app.Untitled_12);
            app.FitParamExport.MenuSelectedFcn = createCallbackFcn(app, @FitParamExport_Callback, true);
            app.FitParamExport.Accelerator = 'S';
            app.FitParamExport.Text = 'Fit Parameters';
            app.menu_saveasimage = uimenu(app.Untitled_12);
            app.menu_saveasimage.MenuSelectedFcn = createCallbackFcn(app, @menu_saveasimage_Callback, true);
            app.menu_saveasimage.Accelerator = 'F';
            app.menu_saveasimage.Text = 'Plot';
            app.ResetSizeMenu = uimenu(app.menu_file);
            app.ResetSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetSizeMenuSelected, true);
            app.ResetSizeMenu.Text = 'Reset Size';
            app.menu_FileResetProfile = uimenu(app.menu_file);
            app.menu_FileResetProfile.MenuSelectedFcn = createCallbackFcn(app, @menu_FileResetProfile_Callback, true);
            app.menu_FileResetProfile.Separator = 'on';
            app.menu_FileResetProfile.Text = 'Reset Profile';
            app.menu_restart = uimenu(app.menu_file);
            app.menu_restart.MenuSelectedFcn = createCallbackFcn(app, @menu_restart_Callback, true);
            app.menu_restart.Text = 'Restart LIPRAS';
            app.menu_close = uimenu(app.menu_file);
            app.menu_close.MenuSelectedFcn = createCallbackFcn(app, @menu_closeMenuSelected, true);
            app.menu_close.Separator = 'on';
            app.menu_close.Accelerator = 'W';
            app.menu_close.Text = 'Close';
            app.menu_plot = uimenu(app.figure1);
            app.menu_plot.Text = 'Plot';
            app.menu_xaxis = uimenu(app.menu_plot);
            app.menu_xaxis.Text = 'X-Axis Scale';
            app.menu_xaxis_linear = uimenu(app.menu_xaxis);
            app.menu_xaxis_linear.MenuSelectedFcn = createCallbackFcn(app, @menu_xplotscale_Callback, true);
            app.menu_xaxis_linear.Tooltip = {'Converts back to 2Θ or to the original file’s input scale'};
            app.menu_xaxis_linear.Checked = 'on';
            app.menu_xaxis_linear.Text = 'Linear';
            app.menu_xaxis_dspace = uimenu(app.menu_xaxis);
            app.menu_xaxis_dspace.MenuSelectedFcn = createCallbackFcn(app, @menu_xplotscale_Callback, true);
            app.menu_xaxis_dspace.Tooltip = {'Convert to d-space based on wavelength entered'};
            app.menu_xaxis_dspace.Text = 'D-Space';
            app.menu_yaxis = uimenu(app.menu_plot);
            app.menu_yaxis.Text = 'Y-Axis Scale';
            app.menu_ylinear = uimenu(app.menu_yaxis);
            app.menu_ylinear.MenuSelectedFcn = createCallbackFcn(app, @menu_yplotscale_Callback, true);
            app.menu_ylinear.Checked = 'on';
            app.menu_ylinear.Text = 'Linear';
            app.menu_ylog = uimenu(app.menu_yaxis);
            app.menu_ylog.MenuSelectedFcn = createCallbackFcn(app, @menu_yplotscale_Callback, true);
            app.menu_ylog.Text = 'Logarithm';
            app.menu_ylog.Tag = 'log';
            app.menu_yroot = uimenu(app.menu_yaxis);
            app.menu_yroot.MenuSelectedFcn = createCallbackFcn(app, @menu_yplotscale_Callback, true);
            app.menu_yroot.Text = 'Square Root';
            app.menuPlot_superimpose = uimenu(app.menu_plot);
            app.menuPlot_superimpose.MenuSelectedFcn = createCallbackFcn(app, @menuPlot_superimpose_Callback, true);
            app.menuPlot_superimpose.Separator = 'on';
            app.menuPlot_superimpose.Accelerator = 'U';
            app.menuPlot_superimpose.Text = 'Superimpose Raw Data';
            app.LegendMenu = uimenu(app.menu_plot);
            app.LegendMenu.Text = 'Legend';
            app.LegendOn = uimenu(app.LegendMenu);
            app.LegendOn.MenuSelectedFcn = createCallbackFcn(app, @LegendOnSelected, true);
            app.LegendOn.Checked = 'on';
            app.LegendOn.Text = 'Yes';
            app.LegendOff = uimenu(app.LegendMenu);
            app.LegendOff.MenuSelectedFcn = createCallbackFcn(app, @LegendOffSelected, true);
            app.LegendOff.Text = 'No';
            app.MathMenu = uimenu(app.menu_plot);
            app.MathMenu.MenuSelectedFcn = createCallbackFcn(app, @MathMenuSelected, true);
            app.MathMenu.Text = 'Math';
            app.DerivativeMenu = uimenu(app.MathMenu);
            app.DerivativeMenu.MenuSelectedFcn = createCallbackFcn(app, @DerivativeMenuSelected, true);
            app.DerivativeMenu.Tooltip = {'Plot derivative of data'};
            app.DerivativeMenu.Text = 'Derivative';
            app.IntegralMenu = uimenu(app.MathMenu);
            app.IntegralMenu.MenuSelectedFcn = createCallbackFcn(app, @DerivativeMenuSelected, true);
            app.IntegralMenu.Tooltip = {'Plot integral of data'};
            app.IntegralMenu.Text = 'Integral';
            app.PreferencesMenu = uimenu(app.figure1);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Text = 'Preferences';
            app.LeastSquaresWeightsMenu = uimenu(app.PreferencesMenu);
            app.LeastSquaresWeightsMenu.MenuSelectedFcn = createCallbackFcn(app, @LeastSquaresWeightsMenuSelected, true);
            app.LeastSquaresWeightsMenu.Text = 'Least Squares Weights';
            app.DefaultMenu_2 = uimenu(app.LeastSquaresWeightsMenu);
            app.DefaultMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DefaultMenu_2Selected, true);
            app.DefaultMenu_2.Tooltip = {'1/σ², where σ comes from the data file, or is assumed as √I if not provided.'};
            app.DefaultMenu_2.Checked = 'on';
            app.DefaultMenu_2.Text = 'Default';
            app.NoneMenu_2 = uimenu(app.LeastSquaresWeightsMenu);
            app.NoneMenu_2.MenuSelectedFcn = createCallbackFcn(app, @NoneMenu_2Selected, true);
            app.NoneMenu_2.Tooltip = {'No weighting. Useful for treating all points equally and for fitting non diffraction data.'};
            app.NoneMenu_2.Text = 'None';
            app.obsMenu_2 = uimenu(app.LeastSquaresWeightsMenu);
            app.obsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @obsMenu_2Selected, true);
            app.obsMenu_2.Tooltip = {'Weights each point by the inverse of the observed intensity.'};
            app.obsMenu_2.Text = '1/obs';
            app.sqrtobsMenu_2 = uimenu(app.LeastSquaresWeightsMenu);
            app.sqrtobsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @sqrtobsMenu_2Selected, true);
            app.sqrtobsMenu_2.Tooltip = {'Weights each point by the inverse square root of the observed intensity.'};
            app.sqrtobsMenu_2.Text = '1/sqrt(obs)';
            app.StartingDirectoryMenu = uimenu(app.PreferencesMenu);
            app.StartingDirectoryMenu.Tooltip = {'Set starting directory for data'};
            app.StartingDirectoryMenu.Text = 'Starting Directory';
            app.SelectMenu_2 = uimenu(app.StartingDirectoryMenu);
            app.SelectMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SelectMenu_2Selected, true);
            app.SelectMenu_2.Text = 'Select';
            app.UniqueSaveMenu = uimenu(app.PreferencesMenu);
            app.UniqueSaveMenu.MenuSelectedFcn = createCallbackFcn(app, @UniqueSaveMenuSelected, true);
            app.UniqueSaveMenu.Tooltip = {'Every fit instance is saved, which creates multiple folders.'};
            app.UniqueSaveMenu.Text = 'Unique Save';
            app.YesMenu_3 = uimenu(app.UniqueSaveMenu);
            app.YesMenu_3.MenuSelectedFcn = createCallbackFcn(app, @YesMenu_3Selected, true);
            app.YesMenu_3.Text = 'Yes';
            app.NoMenu_3 = uimenu(app.UniqueSaveMenu);
            app.NoMenu_3.MenuSelectedFcn = createCallbackFcn(app, @NoMenu_3Selected, true);
            app.NoMenu_3.Checked = 'on';
            app.NoMenu_3.Text = 'No';
            app.ImageResolutionMenu = uimenu(app.PreferencesMenu);
            app.ImageResolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageResolutionMenuSelected, true);
            app.ImageResolutionMenu.Text = 'Image Resolution';
            app.DPIMenu_6 = uimenu(app.ImageResolutionMenu);
            app.DPIMenu_6.MenuSelectedFcn = createCallbackFcn(app, @DPIMenu_6Selected, true);
            app.DPIMenu_6.Checked = 'on';
            app.DPIMenu_6.Text = '100 DPI';
            app.DPIMenu_7 = uimenu(app.ImageResolutionMenu);
            app.DPIMenu_7.MenuSelectedFcn = createCallbackFcn(app, @DPIMenu_7Selected, true);
            app.DPIMenu_7.Text = '200 DPI';
            app.DPIMenu_8 = uimenu(app.ImageResolutionMenu);
            app.DPIMenu_8.MenuSelectedFcn = createCallbackFcn(app, @DPIMenu_8Selected, true);
            app.DPIMenu_8.Text = '300 DPI';
            app.ImageTypeMenu = uimenu(app.PreferencesMenu);
            app.ImageTypeMenu.Tooltip = {'Image export type'};
            app.ImageTypeMenu.Text = 'Image Type';
            app.TIFFMenu_2 = uimenu(app.ImageTypeMenu);
            app.TIFFMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TIFFMenu_2Selected, true);
            app.TIFFMenu_2.Checked = 'on';
            app.TIFFMenu_2.Text = 'TIFF';
            app.JPEGMenu_2 = uimenu(app.ImageTypeMenu);
            app.JPEGMenu_2.MenuSelectedFcn = createCallbackFcn(app, @JPEGMenu_2Selected, true);
            app.JPEGMenu_2.Text = 'JPEG';
            app.PNGMenu_2 = uimenu(app.ImageTypeMenu);
            app.PNGMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PNGMenu_2Selected, true);
            app.PNGMenu_2.Text = 'PNG';
            app.PDFMenu_2 = uimenu(app.ImageTypeMenu);
            app.PDFMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PDFMenu_2Selected, true);
            app.PDFMenu_2.Text = 'PDF';
            app.ImageSaveAllMenu = uimenu(app.PreferencesMenu);
            app.ImageSaveAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageSaveAllMenuSelected, true);
            app.ImageSaveAllMenu.Tooltip = {'Saves all images on export if there are multiple files.'};
            app.ImageSaveAllMenu.Text = 'Image Save All';
            app.YesMenu_4 = uimenu(app.ImageSaveAllMenu);
            app.YesMenu_4.MenuSelectedFcn = createCallbackFcn(app, @YesMenu_4Selected, true);
            app.YesMenu_4.Text = 'Yes';
            app.NoMenu_4 = uimenu(app.ImageSaveAllMenu);
            app.NoMenu_4.MenuSelectedFcn = createCallbackFcn(app, @NoMenu_4Selected, true);
            app.NoMenu_4.Checked = 'on';
            app.NoMenu_4.Text = 'No';
            app.AutoCuK2Menu = uimenu(app.PreferencesMenu);
            app.AutoCuK2Menu.Tooltip = {'When XRDML, ASC, or RAS is imported automatically check Cu Kα2'};
            app.AutoCuK2Menu.Text = 'Auto Cu Kα2';
            app.YesMenu_5 = uimenu(app.AutoCuK2Menu);
            app.YesMenu_5.MenuSelectedFcn = createCallbackFcn(app, @YesMenu_5Selected, true);
            app.YesMenu_5.Text = 'Yes';
            app.NoMenu_5 = uimenu(app.AutoCuK2Menu);
            app.NoMenu_5.MenuSelectedFcn = createCallbackFcn(app, @NoMenu_5Selected, true);
            app.NoMenu_5.Checked = 'on';
            app.NoMenu_5.Text = 'No';
            app.SavePreferenceFileMenu = uimenu(app.PreferencesMenu);
            app.SavePreferenceFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SavePreferenceFileMenuSelected, true);
            app.SavePreferenceFileMenu.Tooltip = {'Saves preferences to file to make the default upon startup'};
            app.SavePreferenceFileMenu.Accelerator = 'P';
            app.SavePreferenceFileMenu.Text = 'Save Preference File';
            app.menulabel_help = uimenu(app.figure1);
            app.menulabel_help.Text = 'Help';
            app.menu_about = uimenu(app.menulabel_help);
            app.menu_about.MenuSelectedFcn = createCallbackFcn(app, @menu_about_Callback, true);
            app.menu_about.Text = 'About';
            app.menu_help = uimenu(app.menulabel_help);
            app.menu_help.MenuSelectedFcn = createCallbackFcn(app, @menu_helpMenuSelected, true);
            app.menu_help.Separator = 'on';
            app.menu_help.Text = 'Help';
            app.checkUpdate = uimenu(app.menulabel_help);
            app.checkUpdate.MenuSelectedFcn = createCallbackFcn(app, @checkforupdates, true);
            app.checkUpdate.Text = 'Updates';
            app.UIAxes = uiaxes(app.figure1);
            ylabel(app.UIAxes, 'Intensity (a.u.)')
            app.UIAxes2 = uiaxes(app.figure1);
            ylabel(app.UIAxes2, 'Obs-Calc')
            app.UIAxes2.Visible = 'off';
            app.text22 = uilabel(app.figure1);
            app.text22.Text = 'Dataset Path ';
            app.edit8 = uitextarea(app.figure1);
            app.edit8.Editable = 'off';
            app.edit8.Enable = 'off';
            app.edit8.Tooltip = {'Path to loaded dataset'};
            app.edit8.Value = {'Upload new file(s)...'};
            app.checkbox_reverse = uicheckbox(app.figure1);
            app.checkbox_reverse.ValueChangedFcn = createCallbackFcn(app, @checkbox_reverse_Callback, true);
            app.checkbox_reverse.Tooltip = 'Reverses the order that the files are read in.';
            app.checkbox_reverse.Text = 'Reverse File Order';
            app.DropDownLabel = uilabel(app.figure1);
            app.DropDownLabel.Visible = 'off';
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.figure1);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.button_browse = uibutton(app.figure1, 'push');
            app.button_browse.ButtonPushedFcn = createCallbackFcn(app, @button_browse_Callback, true);
            app.button_browse.Tooltip = 'Select file(s) containing data to fit.';
            app.button_browse.Text = 'Browse';
            app.TabGroup = uitabgroup(app.figure1);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.SetupTab.ButtonDownFcn = createCallbackFcn(app, @SetupTabButtonDown, true);
            app.ThetaLabel = uilabel(app.SetupTab);
            app.ThetaLabel.Text = 'Range (°)';
            app.EditMin2T = uieditfield(app.SetupTab, 'numeric');
            app.EditMin2T.ValueDisplayFormat = '%.4f';
            app.EditMin2T.ValueChangedFcn = createCallbackFcn(app, @EditMin2TValueChanged, true);
            app.EditMax2T = uieditfield(app.SetupTab, 'numeric');
            app.EditMax2T.ValueDisplayFormat = '%.4f';
            app.EditMax2T.ValueChangedFcn = createCallbackFcn(app, @EditMax2TValueChanged, true);
            app.lambdaLabel = uilabel(app.SetupTab);
            app.lambdaLabel.Text = 'λ (Å)';
            app.EditFieldWave = uieditfield(app.SetupTab, 'numeric');
            app.EditFieldWave.Limits = [0 Inf];
            app.EditFieldWave.ValueDisplayFormat = '%.4f';
            app.EditFieldWave.ValueChangedFcn = createCallbackFcn(app, @EditFieldWaveValueChanged, true);
            app.EditFieldWave.Enable = 'off';
            app.EditFieldWave.Tooltip = {'Wavelength of source, Cu default is 1.5406 Å, only for d-space plotting'};
            app.EditFieldWave.Value = 1.5406;
            app.BackgroundPanel = uipanel(app.SetupTab);
            app.BackgroundPanel.Title = 'Background';
            app.ModelDropDownLabel = uilabel(app.BackgroundPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.BackgroundPanel);
            app.ModelDropDown.Items = {'Polynomial', 'Spline'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'Polynomial';
            app.EditModeButtonGroup = uibuttongroup(app.BackgroundPanel);
            app.EditModeButtonGroup.Title = 'Edit Mode';
            app.NewButton = uiradiobutton(app.EditModeButtonGroup);
            app.NewButton.Text = 'New';
            app.NewButton.Value = true;
            app.AddButton = uiradiobutton(app.EditModeButtonGroup);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uiradiobutton(app.EditModeButtonGroup);
            app.DeleteButton.Text = 'Delete';
            app.PolyOrderSpinnerLabel = uilabel(app.BackgroundPanel);
            app.PolyOrderSpinnerLabel.Text = 'Poly Order';
            app.PolyOrderSpinner = uispinner(app.BackgroundPanel);
            app.PolyOrderSpinner.Limits = [0 15];
            app.PolyOrderSpinner.ValueChangedFcn = createCallbackFcn(app, @PolyOrderSpinnerValueChanged, true);
            app.PolyOrderSpinner.Value = 1;
            app.SelectPointsButton = uibutton(app.BackgroundPanel, 'push');
            app.SelectPointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointsButtonPushed, true);
            app.SelectPointsButton.Tooltip = {'Select background points'};
            app.SelectPointsButton.Text = 'Select Points';
            app.PlotBackgroundButton = uibutton(app.BackgroundPanel, 'push');
            app.PlotBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBackgroundButtonPushed, true);
            app.PlotBackgroundButton.Text = 'Plot Background';
            app.LineProfilesPanel = uipanel(app.SetupTab);
            app.LineProfilesPanel.Enable = 'off';
            app.LineProfilesPanel.Title = 'Line-Profiles';
            app.NumberofPeaksSpinnerLabel = uilabel(app.LineProfilesPanel);
            app.NumberofPeaksSpinnerLabel.Text = 'Number of Peaks';
            app.NumberofPeaksSpinner = uispinner(app.LineProfilesPanel);
            app.NumberofPeaksSpinner.Limits = [0 20];
            app.NumberofPeaksSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofPeaksSpinnerValueChanged, true);
            app.CuK2CheckBox = uicheckbox(app.LineProfilesPanel);
            app.CuK2CheckBox.ValueChangedFcn = createCallbackFcn(app, @CuK2CheckBoxValueChanged, true);
            app.CuK2CheckBox.Tooltip = {'Adds Cu K-alpha 2 contribution to all peaks, wavelength is forced to 1.5406 Å'};
            app.CuK2CheckBox.Text = 'Cu Kα2';
            app.AsymmetryCheckBox = uicheckbox(app.LineProfilesPanel);
            app.AsymmetryCheckBox.ValueChangedFcn = createCallbackFcn(app, @AsymmetryCheckBoxValueChanged, true);
            app.AsymmetryCheckBox.Tooltip = {'Enables asymmetry for Gaussian, Lorentzian, PV, and PVII peak shapes.'};
            app.AsymmetryCheckBox.Text = 'Asymmetry';
            app.UITable = uitable(app.LineProfilesPanel);
            app.UITable.ColumnName = {'Profile'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.ConstraintsPanel = uipanel(app.LineProfilesPanel);
            app.ConstraintsPanel.Title = 'Constraints';
            app.ConstraintsPanel.ButtonDownFcn = createCallbackFcn(app, @ConstraintsPanelButtonDown, true);
            app.NCheckBox = uicheckbox(app.ConstraintsPanel);
            app.NCheckBox.ValueChangedFcn = createCallbackFcn(app, @NCheckBoxValueChanged, true);
            app.NCheckBox.Text = 'N';
            app.xCheckBox = uicheckbox(app.ConstraintsPanel);
            app.xCheckBox.ValueChangedFcn = createCallbackFcn(app, @xCheckBoxValueChanged, true);
            app.xCheckBox.Text = 'x';
            app.fCheckBox = uicheckbox(app.ConstraintsPanel);
            app.fCheckBox.ValueChangedFcn = createCallbackFcn(app, @fCheckBoxValueChanged, true);
            app.fCheckBox.Text = 'f';
            app.mCheckBox = uicheckbox(app.ConstraintsPanel);
            app.mCheckBox.ValueChangedFcn = createCallbackFcn(app, @mCheckBoxValueChanged, true);
            app.mCheckBox.Text = 'm';
            app.wCheckBox = uicheckbox(app.ConstraintsPanel);
            app.wCheckBox.ValueChangedFcn = createCallbackFcn(app, @wCheckBoxValueChanged, true);
            app.wCheckBox.Text = 'w';
            app.SelectPeaksButton = uibutton(app.LineProfilesPanel, 'push');
            app.SelectPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPeaksButtonPushed, true);
            app.SelectPeaksButton.Tooltip = {'Select peak positions'};
            app.SelectPeaksButton.Text = 'Select Peak(s)';
            app.UITable2 = uitable(app.LineProfilesPanel);
            app.UITable2.ColumnName = {'SP'; 'LB'; 'UB'};
            app.UITable2.RowName = {};
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.UITable2.SelectionChangedFcn = createCallbackFcn(app, @UITable2SelectionChanged, true);
            app.RecycleCheckBox = uicheckbox(app.LineProfilesPanel);
            app.RecycleCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecycleCheckBoxValueChanged, true);
            app.RecycleCheckBox.Text = 'Recycle';
            app.RefineBkgCheckBox = uicheckbox(app.LineProfilesPanel);
            app.RefineBkgCheckBox.ValueChangedFcn = createCallbackFcn(app, @RefineBkgCheckBoxValueChanged, true);
            app.RefineBkgCheckBox.Text = 'Refine Bkg';
            app.NoBoundsCheckBox = uicheckbox(app.LineProfilesPanel);
            app.NoBoundsCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoBoundsCheckBoxValueChanged, true);
            app.NoBoundsCheckBox.Text = 'No Bounds';
            app.FitDataButton = uibutton(app.LineProfilesPanel, 'push');
            app.FitDataButton.ButtonPushedFcn = createCallbackFcn(app, @FitDataButtonPushed, true);
            app.FitDataButton.Enable = 'off';
            app.FitDataButton.Tooltip = {'Fit data using parameters in table'};
            app.FitDataButton.Text = 'Fit Data';
            app.UpdateButton = uibutton(app.LineProfilesPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Tooltip = {'Replace the current starting parameters with the latest fit results'};
            app.UpdateButton.Text = 'Update';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ResultsTab.ButtonDownFcn = createCallbackFcn(app, @ResultsTabButtonDown, true);
            app.PlotViewsButtonGroup = uibuttongroup(app.ResultsTab);
            app.PlotViewsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotViewsButtonGroupSelectionChanged, true);
            app.PlotViewsButtonGroup.Title = 'Plot Views';
            app.FilesListBoxLabel = uilabel(app.PlotViewsButtonGroup);
            app.FilesListBoxLabel.Text = 'File(s)';
            app.PeakFitButton = uiradiobutton(app.PlotViewsButtonGroup);
            app.PeakFitButton.Text = 'Peak Fit';
            app.PeakFitButton.Value = true;
            app.FilesListBox = uilistbox(app.PlotViewsButtonGroup);
            app.FilesListBox.Items = {''};
            app.FilesListBox.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.FilesListBox.Value = {};
            app.CoefficientsButton = uiradiobutton(app.PlotViewsButtonGroup);
            app.CoefficientsButton.Text = 'Coefficient(s)';
            app.FitStatisticsButton = uibutton(app.ResultsTab, 'push');
            app.FitStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @FitStatisticsButtonPushed, true);
            app.FitStatisticsButton.Tooltip = {'Fit statistics for all fits done'};
            app.FitStatisticsButton.Text = 'Fit Statistics';
            app.AllFitsButton = uibutton(app.ResultsTab, 'push');
            app.AllFitsButton.ButtonPushedFcn = createCallbackFcn(app, @AllFitsButtonPushed, true);
            app.AllFitsButton.Tooltip = {'Plots the fit for all files that were fit'};
            app.AllFitsButton.Text = 'All Fits';
            app.UITable3 = uitable(app.ResultsTab);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.BayesianTab = uitab(app.TabGroup);
            app.BayesianTab.Title = 'Bayesian';
            app.BayesianTab.ButtonDownFcn = createCallbackFcn(app, @BayesianTabButtonDown, true);
            app.ControlsButtonGroup = uibuttongroup(app.BayesianTab);
            app.ControlsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ControlsButtonGroupSelectionChanged, true);
            app.ControlsButtonGroup.Title = 'Controls';
            app.IterationsEditFieldLabel = uilabel(app.ControlsButtonGroup);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.ControlsButtonGroup, 'numeric');
            app.IterationsEditField.Limits = [0 Inf];
            app.IterationsEditField.ValueDisplayFormat = '%.0f';
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.IterationsEditField.Tooltip = {'Total number of sampling steps to run.'; 'More steps give smoother results but take longer.'};
            app.IterationsEditField.Value = 10000;
            app.SPEditFieldLabel = uilabel(app.ControlsButtonGroup);
            app.SPEditFieldLabel.Text = 'SP';
            app.LBEditFieldLabel = uilabel(app.ControlsButtonGroup);
            app.LBEditFieldLabel.Text = 'LB';
            app.UBEditFieldLabel = uilabel(app.ControlsButtonGroup);
            app.UBEditFieldLabel.Text = 'UB';
            app.SDEditFieldLabel = uilabel(app.ControlsButtonGroup);
            app.SDEditFieldLabel.Text = 'SD';
            app.BurninEditFieldLabel = uilabel(app.ControlsButtonGroup);
            app.BurninEditFieldLabel.Text = 'Burnin';
            app.BurninEditField = uieditfield(app.ControlsButtonGroup, 'numeric');
            app.BurninEditField.Limits = [0 Inf];
            app.BurninEditField.ValueDisplayFormat = '%.0f';
            app.BurninEditField.Tooltip = {'The sampler discards the early steps while it’s still stabilizing.'; 'Only values after this point are kept for the final results.'};
            app.BurninEditField.Value = 1000;
            app.Label = uilabel(app.ControlsButtonGroup);
            app.Label.Tooltip = {'Noise Variance (σ²): Adjusts the proposal step size during MCMC sampling. The goal is to tune this parameter so the algorithm achieves an optimal acceptance ratio of around 23%–44% (or 0.234–0.44) for efficient exploration of the parameter space.'};
            app.Label.Text = 'σ²';
            app.SPEditField = uieditfield(app.ControlsButtonGroup, 'numeric');
            app.SPEditField.ValueDisplayFormat = '%.0f';
            app.SPEditField.Tooltip = {'Initial guess for σ²'};
            app.SPEditField.Value = 500;
            app.LBEditField = uieditfield(app.ControlsButtonGroup, 'numeric');
            app.LBEditField.ValueDisplayFormat = '%.0f';
            app.LBEditField.Tooltip = {'Minimum value of σ²'};
            app.UBEditField = uieditfield(app.ControlsButtonGroup, 'numeric');
            app.UBEditField.ValueDisplayFormat = '%.0f';
            app.UBEditField.Tooltip = {'Maximum value of σ²'};
            app.UBEditField.Value = 1000;
            app.SDEditField = uieditfield(app.ControlsButtonGroup, 'numeric');
            app.SDEditField.ValueDisplayFormat = '%.0f';
            app.SDEditField.Tooltip = {'Standard deviation for prior on σ²'};
            app.SDEditField.Value = 100;
            app.AccRatioLabel = uilabel(app.ControlsButtonGroup);
            app.AccRatioLabel.Tooltip = {'If using Gibbs sampling, σ² is always accepted (100%)—that’s expected.'; ''; 'If not using Gibbs, σ² acceptance around ~20–60% is fine.'};
            app.AccRatioLabel.Text = 'σ² Acc. Ratio =';
            app.GibbsSamplingCheckBox = uicheckbox(app.ControlsButtonGroup);
            app.GibbsSamplingCheckBox.Tooltip = {'σ² is sampled directly from its inverse-gamma posterior (acceptance = 1)'};
            app.GibbsSamplingCheckBox.Text = 'Gibbs Sampling';
            app.AutoCheckBox = uicheckbox(app.ControlsButtonGroup);
            app.AutoCheckBox.Text = 'Auto';
            app.PlotTraceButton = uiradiobutton(app.ControlsButtonGroup);
            app.PlotTraceButton.Text = 'Plot Trace';
            app.PlotTraceButton.Value = true;
            app.RunBayesianButton = uibutton(app.ControlsButtonGroup, 'push');
            app.RunBayesianButton.ButtonPushedFcn = createCallbackFcn(app, @RunBayesianButtonPushed, true);
            app.RunBayesianButton.Text = 'Run Bayesian';
            app.IncludeBackgroundCheckBox = uicheckbox(app.ControlsButtonGroup);
            app.IncludeBackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeBackgroundCheckBoxValueChanged, true);
            app.IncludeBackgroundCheckBox.Text = 'Include Background';
            app.PlotHistogramButton = uiradiobutton(app.ControlsButtonGroup);
            app.PlotHistogramButton.Text = 'Plot Histogram';
            app.ParametersandBoundsButtonGroup = uibuttongroup(app.BayesianTab);
            app.ParametersandBoundsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ParametersandBoundsButtonGroupSelectionChanged, true);
            app.ParametersandBoundsButtonGroup.Title = 'Parameters and Bounds';
            app.DefaultButton = uiradiobutton(app.ParametersandBoundsButtonGroup);
            app.DefaultButton.Tooltip = {'Default setting uses results from least squares to set bounds for Bayesian analysis.'};
            app.DefaultButton.Text = 'Default';
            app.DefaultButton.Value = true;
            app.CustomButton = uiradiobutton(app.ParametersandBoundsButtonGroup);
            app.CustomButton.Tooltip = {'Changes in the table below will constraint Bayesian analysis.'};
            app.CustomButton.Text = 'Custom';
            app.LBUBDropDownLabel = uilabel(app.ParametersandBoundsButtonGroup);
            app.LBUBDropDownLabel.Text = 'LB|UB';
            app.LBUBDropDown = uidropdown(app.ParametersandBoundsButtonGroup);
            app.LBUBDropDown.Items = {'0.25', '0.50', '0.75', '1', '2', '3', '4', '5'};
            app.LBUBDropDown.ValueChangedFcn = createCallbackFcn(app, @LBUBDropDownValueChanged, true);
            app.LBUBDropDown.Tooltip = {'Sets how wide the allowed sampling range is around each parameter.'; ''; 'Smaller multipliers keep the sampler close to the fitted value.'; ''; 'Larger multipliers allow exploring a wider range but may slow convergence.'};
            app.LBUBDropDown.Value = '2';
            app.SDDropDownLabel = uilabel(app.ParametersandBoundsButtonGroup);
            app.SDDropDownLabel.Text = 'SD';
            app.SDDropDown = uidropdown(app.ParametersandBoundsButtonGroup);
            app.SDDropDown.Items = {'0.25', '0.50', '0.75', '1', '2', '3', '4', '5'};
            app.SDDropDown.ValueChangedFcn = createCallbackFcn(app, @SDDropDownValueChanged, true);
            app.SDDropDown.Tooltip = {'Adjusts how much the algorithm wiggles each parameter when sampling.'; ''; 'Smaller values make tiny, slow steps.'; ''; 'Larger values make big, often rejected steps.'; ''; 'Moderate values usually give the best results.'};
            app.SDDropDown.Value = '0.75';
            app.UITable4 = uitable(app.ParametersandBoundsButtonGroup);
            app.UITable4.ColumnName = {'SP'; 'LB'; 'UB'; 'SD'};
            app.UITable4.RowName = {};
            app.UITable4.ColumnEditable = true;
            app.UITable4.CellEditCallback = createCallbackFcn(app, @UITable4CellEdit, true);
            app.UITable4.Tooltip = {'Parameters used in Bayesian analysis.'};
            app.UITable4.Enable = 'off';
            app.MultiplyLabel = uilabel(app.ParametersandBoundsButtonGroup);
            app.MultiplyLabel.Text = 'Multiply: ';
            app.PlottingandResultsButtonGroup = uibuttongroup(app.BayesianTab);
            app.PlottingandResultsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlottingandResultsButtonGroupSelectionChanged, true);
            app.PlottingandResultsButtonGroup.Title = 'Plotting and Results';
            app.FilesListBox_2Label = uilabel(app.PlottingandResultsButtonGroup);
            app.FilesListBox_2Label.Text = 'File(s)';
            app.FilesListBox_2 = uilistbox(app.PlottingandResultsButtonGroup);
            app.FilesListBox_2.Items = {};
            app.FilesListBox_2.Multiselect = 'on';
            app.FilesListBox_2.ValueChangedFcn = createCallbackFcn(app, @FilesListBox_2ValueChanged, true);
            app.FilesListBox_2.Value = {};
            app.PlotBayesFitButton = uibutton(app.PlottingandResultsButtonGroup, 'push');
            app.PlotBayesFitButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBayesFitButtonPushed, true);
            app.PlotBayesFitButton.Tooltip = {'Plots Bayesian fit for selected files'};
            app.PlotBayesFitButton.Text = 'Plot Bayes Fit';
            app.text55 = uilabel(app.figure1);
            app.text55.Visible = 'off';
            app.text55.Text = '';
            app.HTML2 = uihtml(app.figure1);
            app.HTML2.HTMLSource = ' ';
            app.HTML = uihtml(app.figure1);
            app.HTML.HTMLSource = ' ';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            app.CFLabel = uilabel(app.figure1);
            app.CFLabel.Visible = 'off';
            app.CFLabel.Tooltip = {'Seeing a red ''CF'' displayed means the Curve Fitting Toolbox is not being used.'};
            app.CFLabel.Text = 'CF';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LIPRAS

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @LIPRAS_OpeningFcn)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end