classdef LIPRAS_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        menu_file                  matlab.ui.container.Menu
        menuoption_new             matlab.ui.container.Menu
        menu_new                   matlab.ui.container.Menu
        Untitled_14                matlab.ui.container.Menu
        FitParamImport             matlab.ui.container.Menu
        Untitled_12                matlab.ui.container.Menu
        FitParamExport             matlab.ui.container.Menu
        menu_saveasimage           matlab.ui.container.Menu
        ResetSizeMenu              matlab.ui.container.Menu
        menu_FileResetProfile      matlab.ui.container.Menu
        menu_restart               matlab.ui.container.Menu
        menu_close                 matlab.ui.container.Menu
        menu_plot                  matlab.ui.container.Menu
        menu_xaxis                 matlab.ui.container.Menu
        menu_xaxis_linear          matlab.ui.container.Menu
        menu_xaxis_dspace          matlab.ui.container.Menu
        menu_yaxis                 matlab.ui.container.Menu
        menu_ylinear               matlab.ui.container.Menu
        menu_ylog                  matlab.ui.container.Menu
        menu_yroot                 matlab.ui.container.Menu
        menuPlot_superimpose       matlab.ui.container.Menu
        LegendMenu                 matlab.ui.container.Menu
        LegendOn                   matlab.ui.container.Menu
        LegendOff                  matlab.ui.container.Menu
        PreferencesMenu            matlab.ui.container.Menu
        UniqueSaveMenu             matlab.ui.container.Menu
        YesMenu_3                  matlab.ui.container.Menu
        NoMenu_3                   matlab.ui.container.Menu
        StartingDirectoryMenu      matlab.ui.container.Menu
        SelectMenu_2               matlab.ui.container.Menu
        LeastSquaresWeightsMenu    matlab.ui.container.Menu
        DefaultMenu_2              matlab.ui.container.Menu
        NoneMenu_2                 matlab.ui.container.Menu
        obsMenu_2                  matlab.ui.container.Menu
        sqrtobsMenu_2              matlab.ui.container.Menu
        ImageResolutionMenu        matlab.ui.container.Menu
        DPIMenu_6                  matlab.ui.container.Menu
        DPIMenu_7                  matlab.ui.container.Menu
        DPIMenu_8                  matlab.ui.container.Menu
        ImageTypeMenu              matlab.ui.container.Menu
        TIFFMenu_2                 matlab.ui.container.Menu
        JPEGMenu_2                 matlab.ui.container.Menu
        PNGMenu_2                  matlab.ui.container.Menu
        PDFMenu_2                  matlab.ui.container.Menu
        ImageSaveAllMenu           matlab.ui.container.Menu
        YesMenu_4                  matlab.ui.container.Menu
        NoMenu_4                   matlab.ui.container.Menu
        SavePreferenceFileMenu     matlab.ui.container.Menu
        menulabel_help             matlab.ui.container.Menu
        menu_about                 matlab.ui.container.Menu
        menu_help                  matlab.ui.container.Menu
        checkUpdate                matlab.ui.container.Menu
        DropDown                   matlab.ui.control.DropDown
        DropDownLabel              matlab.ui.control.Label
        HTML2                      matlab.ui.control.HTML
        HTML                       matlab.ui.control.HTML
        checkbox_reverse           matlab.ui.control.CheckBox
        text22                     matlab.ui.control.Label
        button_browse              matlab.ui.control.Button
        edit8                      matlab.ui.control.TextArea
        text55                     matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        SetupTab                   matlab.ui.container.Tab
        EditFieldWave              matlab.ui.control.NumericEditField
        lambdaLabel                matlab.ui.control.Label
        LineProfilesPanel          matlab.ui.container.Panel
        AsymmetryCheckBox          matlab.ui.control.CheckBox
        FitDataButton              matlab.ui.control.Button
        NoBoundsCheckBox           matlab.ui.control.CheckBox
        RefineBkgCheckBox          matlab.ui.control.CheckBox
        RecycleCheckBox            matlab.ui.control.CheckBox
        UITable2                   matlab.ui.control.Table
        ConstraintsPanel           matlab.ui.container.Panel
        mCheckBox                  matlab.ui.control.CheckBox
        wCheckBox                  matlab.ui.control.CheckBox
        fCheckBox                  matlab.ui.control.CheckBox
        xCheckBox                  matlab.ui.control.CheckBox
        NCheckBox                  matlab.ui.control.CheckBox
        SelectPeaksButton          matlab.ui.control.Button
        UpdateButton               matlab.ui.control.Button
        CuK2CheckBox               matlab.ui.control.CheckBox
        NumberofPeaksSpinner       matlab.ui.control.Spinner
        NumberofPeaksSpinnerLabel  matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        EditMax2T                  matlab.ui.control.NumericEditField
        EditMin2T                  matlab.ui.control.NumericEditField
        ThetaLabel                 matlab.ui.control.Label
        BackgroundPanel            matlab.ui.container.Panel
        PlotBackgroundButton       matlab.ui.control.Button
        SelectPointsButton         matlab.ui.control.Button
        EditModeButtonGroup        matlab.ui.container.ButtonGroup
        DeleteButton               matlab.ui.control.RadioButton
        AddButton                  matlab.ui.control.RadioButton
        NewButton                  matlab.ui.control.RadioButton
        PolyOrderSpinner           matlab.ui.control.Spinner
        PolyOrderSpinnerLabel      matlab.ui.control.Label
        ModelDropDown              matlab.ui.control.DropDown
        ModelDropDownLabel         matlab.ui.control.Label
        ResultsTab                 matlab.ui.container.Tab
        UITable3                   matlab.ui.control.Table
        BayesianButton             matlab.ui.control.Button
        AllFitsButton              matlab.ui.control.Button
        FitStatisticsButton        matlab.ui.control.Button
        PlotViewsButtonGroup       matlab.ui.container.ButtonGroup
        FilesListBox               matlab.ui.control.ListBox
        FilesListBoxLabel          matlab.ui.control.Label
        CoefficientsButton         matlab.ui.control.RadioButton
        PeakFitButton              matlab.ui.control.RadioButton
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % app % Description, commented out on 1-10-2026
        profiles
        gui
        NoEqualData
        Min2T % Description
        Max2T
        a
        PointSelected
        KeyPressed
        BkgMode
        BkgSelect
        BkgCalcNext
        PeakSelect
        PeakPositions
        FitFunctions
        xrd0
        Asym
        Plotter
        XScale='linear'
        YScale='linear'
        prevCondsChanged=0;
        Coefficients
        FitInitial
        FitResults
        CheckBox0
        Fxn0
        TableC0 % For resetting/updating Coeff table
        UIAxesplot
        OrigCD
        
    end
    
    methods (Access = private)
        function ImagePref(~, o, ~, app, type)
            if strcmp(type,'DPI')
                val=o.Value;
                w=o.String(val);
                app.profiles.ImageRes=w{1};
            
            elseif strcmp(type,'Format')
                 val=o.Value;
                w=o.String(val);
                 app.profiles.ImageFormat=w{1};
            
            elseif strcmp(type,'ExportAll')
                val=o.Value;
                if val
                    app.profiles.ImageSaveAll=1;
                else
                    app.profiles.ImageSaveAll=0;
                end
            end
        end
        
        function LIPRAS_StatusChangedFcn(~, o, e, app)
            %STATUSCHANGE executes when the ProfileListManager property 'Status' is changed.
            app.statusbarObj.setText(app.profiles.Status);
        end
        
        function LIPRAS_WindowButtonMotionFcn(~, hObject, evt, app)
            % Executes when the mouse moves inside the figure.
            %
            %   If it is not empty, display the TooltipString for an object in statusbarObj even when it's
            %   disabled.
            msg = '';
            try
                obj = hittest(hObject);
                if  ~isempty(obj.TooltipString)
                    msg = obj.TooltipString;
                end
            catch
                try
                    xx = num2str(app.UIAxes.CurrentPoint(1,1));
                    yy = sprintf('%.3G', app.UIAxes.CurrentPoint(1,2));
                    if strcmpi(class(obj), 'matlab.graphics.chart.primitive.Line')
                        displayName = obj.DisplayName;
                        msg = [displayName ': (' xx ', ' yy ')'];
                    end
                catch
                end
            end
            app.gui.Status = msg;
        end
        
        function SavePref(app)
                   PreferenceFile=fopen('Preference File.txt','w');
                        fprintf(PreferenceFile,'%s %s\n','OpenDirectory=',app.profiles.DataPath);
                        fprintf(PreferenceFile,'%s %s\n','Weights=',app.profiles.Weights);
                        fprintf(PreferenceFile,'%s %i\n','UniqueSave=',app.profiles.UniqueSave);
                        fprintf(PreferenceFile,'%s %s\n','ImageRes=',app.profiles.ImageRes);
                        fprintf(PreferenceFile,'%s %s\n','ImageFormat=',app.profiles.ImageFormat);
                        fprintf(PreferenceFile,'%s %i\n','ImageExportAll=',app.profiles.ImageSaveAll);
                        fprintf(PreferenceFile,'%s %f %f %f \n','GUIColor=',app.profiles.GUIColor(1),app.profiles.GUIColor(2),app.profiles.GUIColor(3));                   
                        fclose all;
        end

        function LoadPref(app)
        % Preference File Read on LIPRAS start up
        try
            PrefFile=fopen('Preference File.txt','r');
            dat=fscanf(PrefFile,'%c');
            sdat=strsplit(dat,'\n');
            data_path=strsplit(sdat{1},'= ');
            rWeights=strsplit(sdat{2},'= ');
            rUniqueSave=strsplit(sdat{3},'= ');
            rImageRes=strsplit(sdat{4},'= ');
            rImageFormat=strsplit(sdat{5},'= ');
            rImageExportAll=strsplit(sdat{6},'= ');
            Gcolor=strsplit(sdat{7},'= ');
        
            fclose(PrefFile);
            app.profiles.DataPath=data_path{2};
            app.profiles.Weights=rWeights{2};
            app.profiles.UniqueSave=str2double(rUniqueSave{2});
            app.profiles.ImageRes=rImageRes{2};
            app.profiles.ImageFormat=rImageFormat{2};
            app.profiles.ImageSaveAll=str2double(rImageExportAll{2});
            
            % Changes GUI Color, I like the white, but we can possibly do
            % Dark mode, might need to make it two options since this can
            % be a very stylistic thing
            Gc=str2num(Gcolor{2});
            app.figure1.Color= Gc;
            % app.SetupTab.BackgroundColor=Gc;
            app.SetupTab.ForegroundColor=[0 0 0];
            % app.BackgroundPanel.BackgroundColor=Gc;
            % app.BackgroundPanel.ForegroundColor=Gc;
            % app.BackgroundPanel.BorderColor=Gc;
            app.ResultsTab.BackgroundColor=Gc;
            % app.ResultsTab.ForegroundColor=[0 0 0];
            app.LineProfilesPanel.BackgroundColor=Gc;
            % app.LineProfilesPanel.BorderColor=Gc;
            app.PlotViewsButtonGroup.BackgroundColor=Gc;
            % app.PlotViewsButtonGroup.BorderColor=Gc;
            app.EditModeButtonGroup.BackgroundColor=Gc;
            % app.EditModeButtonGroup.BorderColor=Gc;
        catch
            disp('Prefence file may not have fully loaded')
        end

        % Set All Relevant Menu Items

        % rUniqueSave
        if app.profiles.UniqueSave == 1
            app.YesMenu_3.Checked = 1;   % check Yes
            app.NoMenu_3.Checked  = 0;  % uncheck No
        end

        % Weights
        weightOption = strtrim(rWeights{2});  % clean leading/trailing whitespace
        set(app.LeastSquaresWeightsMenu.Children, 'Checked', 'off'); % Reset all checked boxes
        if ~strcmp(weightOption, 'Default')
            switch weightOption
                case 'None'
                    % Do what’s needed for 'None'
                    app.NoneMenu_2.Checked=1;
                case '1/obs'
                    % Action for inverse of observations
                    app.obsMenu_2.Checked=1;
                case '1/sqrt(obs)'
                    % Action for inverse sqrt of observations
                    app.sqrtobsMenu_2.Checked=1;
                otherwise
                    warning('Unrecognized weight option: %s', weightOption);
                    % Optional: fall back to default behavior or raise an error
                    app.DefaultMenu_2.Checked=1;

            end
        else
                    app.DefaultMenu_2.Checked=1;
        end

        % ImageRes
        ImageRes = strtrim(rImageRes{2});  % clean leading/trailing whitespace
        set(app.ImageResolutionMenu.Children, 'Checked', 'off'); % Reset all checked boxes
        if ~strcmp(ImageRes, 'Default')
            switch ImageRes
                case '100 DPI'
                    % Do what’s needed for 'None'
                    app.DPIMenu_6.Checked=1;
                case '200 DPI'
                    % Action for inverse of observations
                    app.DPIMenu_7.Checked=1;
                case '300 DPI'
                    % Action for inverse sqrt of observations
                    app.DPIMenu_8.Checked=1;
                otherwise
                    warning('Unrecognized image DPI: %s', ImageRes);
                    % Optional: fall back to default behavior or raise an error
                    app.DPIMenu_6.Checked=1;

            end
        else
                    app.DPIMenu_6.Checked=1;
        end

        %ImageType
        ImageType = strtrim(rImageFormat{2});  % clean leading/trailing whitespace
        set(app.ImageTypeMenu.Children, 'Checked', 'off'); % Reset all checked boxes
        if ~strcmp(ImageType, 'Default')
            switch ImageType
                case 'TIFF'
                    % Do what’s needed for 'None'
                    app.TIFFMenu_2.Checked=1;
                case 'JPEG'
                    % Action for inverse of observations
                    app.JPEGMenu_2.Checked=1;
                case 'PNG'
                    % Action for inverse sqrt of observations
                    app.PNGMenu_2.Checked=1;
                case 'PDF'
                    % Action for inverse sqrt of observations
                    app.PDFMenu_2.Checked=1;
                otherwise
                    warning('Unrecognized image DPI: %s', ImageType);
                    % Optional: fall back to default behavior or raise an error
                    app.TIFFMenu_2.Checked=1;

            end
        else
                    app.TIFFMenu_2.Checked=1;
        end

        % SaveAll
        if app.profiles.ImageSaveAll == 1
            app.YesMenu_4.Checked = 1;   % check Yes
            app.NoMenu_4.Checked  = 0;  % uncheck No
        end


        end
        
        function vali = Validate_bkg(app)
            
                        if isempty(app.profiles.xrd.getBackgroundPoints)
                            app.HTML.HTMLSource=app.formatHTML('Select background points...');
                           vali=1;
                            return
                        elseif length(app.profiles.xrd.getBackgroundPoints) <= app.profiles.xrd.getBackgroundOrder
                              d = dialog('Position',[300 500 300 120],'Name','Warning:');
                        txt = uicontrol('Parent',d,...
                          'Style','text',...
                          'Position',[25 10 270 100],...
                          'String',{'Polynomial order is greater than or equal to the number of points selected, add more background points or reduce polynomial order'},'FontSize',10);
                        btn1 = uicontrol('Parent',d,...
                       'Position',[100 10 100 30],...
                       'String','Ok','FontSize',11,...
                       'Callback','delete(gcf)');
                            vali=1;
                            app.HTML.HTMLSource=app.formatHTML('Select more background points...');

                            return
                        else
                            vali=0;
                        end

        end
        
        function choosedialog(app)
            btnsize=40;
            r1v=120;
            r1h=15;
            textb=10;
            
                d = dialog('Position',[300 300 350 200],'Name','LIPRAS- Help');
                txt = uicontrol('Parent',d,...
                       'Style','text',...
                       'Position',[70 150 210 40],...
                       'String','Select Topic','FontSize',11);
            
                btn1 = uicontrol('Parent',d,...
                       'Position',[125 10 100 btnsize],...
                       'String','Close','FontSize',textb,...
                       'Callback','delete(gcf)');
            
                btn2 = uicontrol('Parent',d,...
                       'Position',[r1h r1v 100 btnsize],...
                       'FontSize',textb,...
                       'Callback',@web1);
                   set(btn2, 'String', '<html><center>LIPRAS<br>Web Page</center>');
            
                btn3 = uicontrol('Parent',d,...
                       'Position',[r1h+110 r1v 100 btnsize],...
                       'FontSize',textb,...
                       'Callback',@web2);
                 set(btn3, 'String', '<html><center>Least-Squares<br>Fitting</center>');
            
            
                  btn4 = uicontrol('Parent',d,...
                       'Position',[r1h+220 r1v 100 btnsize],...
                       'FontSize',textb,...
                       'Callback',@web3);
                        set(btn4, 'String', '<html><center>Statistics</center>');
            
                   btn5 = uicontrol('Parent',d,...
                       'Position',[125 r1v-50 100 btnsize],...
                       'FontSize',textb,...
                       'Callback',@web4);
                        set(btn5, 'String', '<html><center>LIPRAS Manual</center>');
            
            
                % Wait for d to close before running to completion
                uiwait(d);
        end
        
        function edit_polyorder_Callback(~, src, ~, app)
            %BACKGROUNDORDERCHANGED Summary of this function goes here
            %   Detailed explanation goes here
            value = round(src.getValue);
            if value == 1 && strcmpi(app.gui.BackgroundModel, 'Spline')
               value = 2;
               app.gui.PriorityStatus = '<html><font color="red">Spline Order must be > 1.';
            end
            xrd = app.profiles.xrd;
            xrd.setBackgroundOrder(value);
            app.gui.PolyOrder = value;
        end
        
        function menu_help_Callback(~, ~, ~)
            
            choosedialog
        end
        
        function openD(~, ~, ~, app)
                    folder_name=uigetdir;
            
            app.profiles.DataPath=folder_name;
        end
        
        function pref(~,app)
            btnsize=30;
            r1v=120;
            r1h=20;
            textb=10;
            
                d = dialog('Position',[100 400 350 250],'Name','Preferences');
            
                btn1 = uicontrol('Parent',d,...
                       'Position',[175 10 100 btnsize],...
                       'String','Close','FontSize',textb,...
                       'Callback','delete(gcf)');
            
                 btns = uicontrol('Parent',d,...
                       'Position',[50 10 100 btnsize],...
                       'String','Save to File','TooltipString','Save Preferences to a text file that is read with newly imported data','FontSize',textb,...
                       'Callback',@(o,e)LIPRAS('SavePref',o,e,app));
            
            % Starting Director for Files
                edbox1= uicontrol('Parent',d,...
                       'Position',[r1h r1v+60 300 btnsize],...
                       'Style','text','HorizontalAlignment','left',...
                       'FontSize',textb, 'String','Set Starting Directory:');
            
                btn2 = uicontrol('Parent',d,...
                       'Position',[r1h+200 r1v+65 100 btnsize],...
                       'FontSize',textb,'TooltipString','After selecting directory, hit "Save to File" to preserve for next LIPRAS startup',...
                       'Callback',@(o,e)LIPRAS('openD',o,e,app));
                   set(btn2, 'String', '<html><center>Select Directory</center>');
            
            % LSQ Weights
                      edbox2= uicontrol('Parent',d,...
                       'Position',[r1h r1v+25 300 btnsize],...
                       'Style','text','HorizontalAlignment','left',...
                       'FontSize',textb, 'String','Least Squares Weights:');
            
                        pop1=uicontrol('Parent',d,...
                            'Position', [r1h+200 r1v+30 100 btnsize],...
                            'FontSize',textb,...
                            'String', {'Default','None','1/obs','1/sqrt(obs)'},...
                            'Style','popup','TooltipString','Takes immediate effect, hit "Save to File" to preserve for next LIPRAS startup',...
                            'Callback',@(o,e)LIPRAS('weight',o,e,app));
            try
            lst=app.profiles.Weights;
            catch
                lst='Default';
            end
            if strcmp(lst,'Default');id=1;
            elseif strcmp(lst,'None');id=2;
            elseif strcmp(lst,'1/obs');id=3;
            elseif strcmp(lst,'1/sqrt(obs)'); id=4;
            
            else
                id=1;
            end
                         set(pop1,'Value',id)
            
                % Unique Save
                            chkbox1 = uicontrol('Parent',d,...
                       'Position',[r1h+240 r1v+97 100 btnsize],...
                       'FontSize',textb,'HorizontalAlignment','left',...
                       'Style','checkbox','TooltipString','Generates new folder everytime a fit is conducted, takes immediate effect, hit "Save to File" to preserve for next LIPRAS startup',...
                            'Callback',@LIPRAS_App);
                   try
                            if app.profiles.UniqueSave==1
                                set(chkbox1,'Value',1);
                            else
                            end
                   catch
                   end
            
                      textbox3= uicontrol('Parent',d,...
                       'Position',[r1h r1v+90 200 btnsize],...
                       'Style','text','HorizontalAlignment','left',...
                       'FontSize',textb, 'String','Toggle Unique Save:');
            
            % Image Pref
                      textbox4= uicontrol('Parent',d,...
                       'Position',[r1h r1v-10 300 btnsize],...
                       'Style','text','HorizontalAlignment','left',...
                       'FontSize',textb, 'String','Image Resolution:');
            
                               pop4=uicontrol('Parent',d,...
                            'Position', [r1h+200 r1v-5 100 btnsize],...
                            'FontSize',textb,...
                            'String', {'50 DPI','100 DPI','200 DPI','300 DPI','600 DPI'},...
                            'Style','popup','TooltipString','Takes immediate effect, hit "Save to File" to preserve for next LIPRAS startup',...
                            'Callback',@(o,e)LIPRAS_App('ImagePref',o,e,app,'DPI'));
                try
            lst=app.profiles.ImageRes;
                catch
                lst='50 DPI'; % default
                end
            if strcmp(lst,'50 DPI');id=1;elseif strcmp(lst,'100 DPI');id=2;elseif strcmp(lst,'200 DPI'); id=3;elseif strcmp(lst,'300 DPI');id=4;
            elseif strcmp(lst,'600 DPI'); id=5;
            else
                id=1;
            end
                         set(pop4,'Value',id)
            %
                             textbox5= uicontrol('Parent',d,...
                       'Position',[r1h r1v-45 300 btnsize],...
                       'Style','text','HorizontalAlignment','left',...
                       'FontSize',textb, 'String','Image Type:');
            
                                      pop5=uicontrol('Parent',d,...
                            'Position', [r1h+200 r1v-40 100 btnsize],...
                            'FontSize',textb,...
                            'String', {'TIFF','JPEG','PNG','PDF','BMP','EPSC','SVG'},...
                            'Style','popup','TooltipString','Takes immediate effect, hit "Save to File" to preserve for next LIPRAS startup',...
                            'Callback',@(o,e)LIPRAS_App('ImagePref',o,e,app,'Format'));
                try
            lst=app.profiles.ImageFormat;
                catch
                lst='TIFF'; % default
                end
            if strcmp(lst,'TIFF');id=1;elseif strcmp(lst,'JPEG');id=2;elseif strcmp(lst,'PNG'); id=3;elseif strcmp(lst,'PDF');id=4;
            elseif strcmp(lst,'BMP'); id=5;elseif strcmp(lst,'EPSC'); id=6;elseif strcmp(lst,'SVG'); id=7;
            else
                id=1;
            end
                         set(pop5,'Value',id)
            
                   %
                              textbox6= uicontrol('Parent',d,...
                       'Position',[r1h r1v-80 300 btnsize],...
                       'Style','text','HorizontalAlignment','left',...
                       'FontSize',textb, 'String','Image Save All:');
            
                                   chkbox6 = uicontrol('Parent',d,...
                       'Position',[r1h+240 r1v-70 100 btnsize],...
                       'FontSize',textb,'HorizontalAlignment','left',...
                       'Style','checkbox','TooltipString','Toogle to export all images for all files, instead of currently viewed fit (default)',...
                       'Callback',@(o,e)LIPRAS_App('ImagePref',o,e,app,'ExportAll'));
            
                  try
                            if app.profiles.ImageSaveAll==1
                                set(chkbox6,'Value',1);
                            else
                            end
                   catch
                   end
            
            
                   uiwait(d)
        end
        
        function uniqueSav(app)
                            val=hObject.Value; % identifies what was selected
                            if val
                                try
                                app.profiles.UniqueSave=1;
                                disp('Unique Save On')
                                catch
                                    msgbox('Load Data First')
                                end
                            else
                                try
                                app.profiles.UniqueSave=0;
                                catch
                                    msgbox('Load Data First')
                                end
                                disp('Unique Save Off')
                            end
        end
        
        function web1(app, ~, ~)
                        web('https://sneakysnail.github.io/LIPRAS/','-browser')
        end
        
        function web2(app, ~, ~)
                        web('https://www.mathworks.com/help/curvefit/least-squares-fitting.html#bq_5kr9-3','-browser')
        end
        
        function web3(app, ~, ~)
                        web('https://www.mathworks.com/help/curvefit/evaluating-goodness-of-fit.html','-browser')
        end
        
        function web4(app, ~, ~)
                        try
                            h=evalin('base','app');
                            cd(h.OrigCD) % switches to LIPRAS directory to find manual
                            manu=dir('*manual*.pdf');
                            open(manu.name)
                        catch
                                if ~isempty(manu)
                                web(['https://github.com/SneakySnail/LIPRAS/blob/master/' manu.name ],'-browser')
                                else
                                h1=warndlg('Could not find local version...searching online');
                                web('https://github.com/SneakySnail/LIPRAS/blob/master/LIPRAS_Manual_6.pdf','-browser') % this needs manual adjustment
                                end
                        end
        end
        
        function webupdate(app, ~, ~)
                        choice = questdlg('Go to Web Page or Download ZIP?', ...
                                    'Options', ...
                                    'LIPRAS Web Page','Download ZIP File','No thank you','No thank you');
                        % Handle response
                        switch choice
                                 case 'LIPRAS Web Page'
                                web('http://www.mathworks.com/matlabcentral/fileexchange/62162-line-profile-analysis-software--lipras-','-browser')
                                case 'Download ZIP File'
                                    try % attemps to find accurate version number to account for number of downloads
                                w=webread('https://www.mathworks.com/matlabcentral/fileexchange/62162-line-profile-analysis-software--lipras-');
                                n=strsplit(w);
                                [~,c]=find(contains(n,'/matlabcentral/mlc-downloads/downloads/submissions/62162')==1); % trying to find current version number
                                pp=n{c};
                                p2=strsplit(pp,'/');
                                Num=p2{end-1};
                                web(['https://www.mathworks.com/matlabcentral/mlc-downloads/downloads/submissions/62162/versions/' num2str(Num) '/download/zip'],'-new','-notoolbar','-browser');
                                    catch
                                web('https://www.mathworks.com/matlabcentral/mlc-downloads/downloads/submissions/62162/versions/18/download/zip','-new','-notoolbar','-browser');
                                    end
                                case 'No Thank You'
                        end
        end
        
        function weight(app, o, ~, ~)
                            val=o.Value; % identifies what was selected
                            w=o.String(val);
                            try
                            dd=['Setting weights to ' w];
                            app.profiles.Weights=w{:};
                            disp(dd)
                            catch
                                msgbox('Load Data First')
                            end
        end


        function results = disableFitData(app)
            app.UITable2.Enable="off";
            app.FitDataButton.Enable='off';
        end
        
        function exportPlotAsImage(app)
            % Export current or all plots based on app settings
            formatMap = struct( ...
                'TIFF', '-dtiff', ...
                'JPEG', '-djpeg', ...
                'PNG',  '-dpng', ...
                'PDF',  '-dpdf' ...
            );
        
            imgFormat = upper(app.profiles.ImageFormat);
            dpiValue = str2double(app.profiles.ImageRes(1:3));
        
            if ~isfield(formatMap, imgFormat)
                errordlg(sprintf('Unsupported format: %s', imgFormat), 'Export Error');
                return;
            end
        
            printFlag = formatMap.(imgFormat);
        
            % Create output directory
            outpath = fullfile(app.profiles.Writer.OutputPath, 'ExportedImages');
            if app.profiles.UniqueSave
                index = 0;
                prefix = '00';
                while exist(fullfile(outpath, ['Image_' prefix num2str(index)]), 'dir') == 7
                    index = index + 1;
                    if index > 100
                        prefix = '';
                    elseif index > 10
                        prefix = '0';
                    end
                end
                outpath = fullfile(outpath, ['Image_' prefix num2str(index)]);
            end
        
            if ~exist(outpath, 'dir')
                mkdir(outpath);
            end
        
            oldpath = pwd;
            cd(outpath);

            lineStyles  = arrayfun(@(h) h.LineStyle,  app.UIAxes.Children, 'UniformOutput', false);
            markerTypes = arrayfun(@(h) h.Marker,     app.UIAxes.Children, 'UniformOutput', false);
            markerSizes = arrayfun(@(h) h.MarkerSize, app.UIAxes.Children);
            markerFills = arrayfun(@(h) h.MarkerFaceColor, app.UIAxes.Children, 'UniformOutput', false);
        
            try
                if ~app.profiles.ImageSaveAll || app.CoefficientsButton.Value
                    % === SINGLE IMAGE EXPORT ===
                    filename = app.gui.FileNames{app.gui.CurrentFile};
                    [~, filename, ~] = fileparts(filename);
                    if app.CoefficientsButton.Value
                        filename = [filename '-Coeffs'];
                    end
                    % Get pixel sizes and positions
                    p1 = app.UIAxes.Position;   % [left, bottom, width, height]
                    p2 = app.UIAxes2.Position;
                    
                    % Determine total figure height needed
                    totalWidth  = max(p1(3), p2(3));
                    totalHeight = p1(4) + p2(4);
                    
                    % Create figure with exact size
                    fig = figure('Visible', 'off', 'Units', 'pixels', ...
                                 'Position', [100, 100, totalWidth, totalHeight], ...
                                 'PaperPositionMode', 'auto');
                    
                    % Copy axes
                    ax1 = copyobj(app.UIAxes, fig);
                    ax2 = copyobj(app.UIAxes2, fig);
                    
                    % Position axes exactly in stacked layout (lower-left corner origin)
                    set(ax1, 'Units', 'pixels', 'Position', [0, p2(4), p1(3), p1(4)]);  % top
                    set(ax2, 'Units', 'pixels', 'Position', [0, 0,     p2(3), p2(4)]);  % bottom
                    
                    % Optional: adjust styling
                    legend(ax1, 'show', 'Location', 'northeast', 'Box', 'off', 'FontSize', 9);
                    legend(ax2, 'show', 'Location', 'northeast', 'Box', 'off', 'FontSize', 9);
                    ax1.FontSize = 12; ax2.FontSize = 12;
                    
                    drawnow;
                    
                    % Export
                    print(fig, filename, printFlag, ['-r' num2str(dpiValue)]);
                    close(fig);

                else

                % === MULTIPLE IMAGE EXPORT USING UIAxes LAYOUT (Centered) ===
                
                % Get dimensions from UIAxes
                p1 = app.UIAxes.Position;    % Top plot
                p2 = app.UIAxes2.Position;   % Bottom plot
                
                % Use max width and combined height
                plotWidth  = max(p1(3), p2(3));
                plotHeight = p1(4) + p2(4);
                gap = 60;  % Vertical gap between plots
                
                % Add margins
                hMargin = 70;  % left/right margin
                vMargin = 50;  % top/bottom margin
                leftShift = 90;  % increase to shift more right

                figWidth  = plotWidth  + 2 * hMargin;
                figHeight = plotHeight + gap + 2 * vMargin;
                
                tt = app.profiles.getProfileResult{1}.TwoTheta;
                
                for jj = 1:numel(app.DropDown.Items)
                    filename = app.gui.FileNames{jj};
                    [~, baseName, ~] = fileparts(filename);
                
                    data  = app.profiles.xrd.getData(jj);
                    fit   = app.profiles.getProfileResult{jj}.FData;
                    bkg   = app.profiles.getProfileResult{jj}.Background;
                    peaks = app.profiles.getProfileResult{jj}.FPeaks;
                    % yy    = [data; fit; bkg; peaks + bkg];

                    if app.profiles.xrd.BkgLS == 1
                        yy = [data; fit; bkg; peaks + bkg];
                        fit_plot   = fit;
                        peaks_plot = peaks + bkg;
                    else
                        yy = [data; fit + bkg; bkg; peaks + bkg];
                        fit_plot   = fit + bkg;
                        peaks_plot = peaks + bkg;
                    end

                    residuals = data - fit;
                
                    % Create figure
                    fig = figure('Visible', 'off', 'Units', 'pixels', ...
                                 'Position', [100, 100, figWidth, figHeight], ...
                                 'PaperPositionMode', 'auto');
                
                    % Top axis (UIAxes)
                    ax1 = axes('Parent', fig, 'Units', 'pixels', ...
                               'Position', [leftShift, vMargin + p2(4) + gap, p1(3), p1(4)]);
                    hold(ax1, 'on');
                    plot(ax1, tt, data, "LineStyle",lineStyles{5},"Marker",markerTypes{5}, 'MarkerSize',markerSizes(5),'MarkerFaceColor',markerFills{5}, 'Color', [0.1 0.3 0.9]);
                    plot(ax1, tt, fit_plot, '.-', 'LineWidth', 1.5, 'Color', [0 0.6 0]);
                    plot(ax1, tt, bkg, '--', 'LineWidth', 1.5, 'Color', [1 0 0]);
                    plot(ax1, tt, peaks_plot, '--', 'LineWidth', 1.5, 'Color', [0.6 0 0.6]);
                    hold(ax1, 'off');
                    title(ax1, baseName, 'Interpreter', 'none');
                    box(ax1, 'on');
                    legend(ax1, {'Data','Fit','Background','Peaks+Background'}, ...
                           'Location','northeast','Box','off','FontSize',12);
                    xlabel(ax1, '2\theta');
                    ylabel(ax1, 'Intensity (a.u.)');
                    ax1.FontSize = 12;
                    xlim(ax1, app.UIAxes.XLim);
                    ylim(ax1, app.UIAxes.YLim);

                    % Bottom axis (UIAxes2)
                    ax2 = axes('Parent', fig, 'Units', 'pixels', ...
                               'Position', [leftShift, vMargin, p2(3), p2(4)]);
                    plot(ax2, tt, residuals, '-', 'LineWidth', 1.5, 'Color', 'r');
                    legend(ax2, 'Residuals (Data - Fit)', ...
                           'Location','northeast','Box','off','FontSize',9);
                    xlabel(ax2, '2\theta');
                    ylabel(ax2, 'Obs-Calc');
                    ax2.FontSize = 12;
                    xlim(ax2, app.UIAxes.XLim);

                    drawnow;
              
                    % Export
                    outname = fullfile(outpath, [baseName '.' lower(app.profiles.ImageFormat)]);
                    print(fig, outname, printFlag, ['-r' num2str(dpiValue)]);
                
                    close(fig);
                end

                end
        
                app.gui.PriorityStatus = sprintf('Successfully exported plot(s) as %s (%d DPI).', imgFormat, dpiValue);
            catch ME
                warning('Export failed: %s');
            end
        
            cd(oldpath);
        end


        function results = toolbar_legend_OnCallback(app)
                    % Turns on the legend.
            app.gui.Legend = 'on';
            app.gui.Legend = 'reset';
        end
        
        function results = toolbar_legend_OffCallback(app)
            app.gui.Legend = 'off';

        end


        function results = toolbar_legend_ClickedCallback(app)
            if strcmpi(app.State,'on')
                toolbar_legend_OnCallback(app);
            else
                toolbar_legend_OffCallback(app);
            end
        end
        
        function html = formatHTML(~,msg)
            html = sprintf('<div align="right"><font size="2" face="Helvetica"><i>%s</i></font></div>', msg);

        end
end

    methods (Access = public)
        
        function result = isFitDirty(app)
            %ISFITDIRTY returns TRUE if the selected options above the 'UPDATE' button (i.e. the fit
        %   parameters) don't match the table_fitinitial coefficients.
        result = false;
        xrd = app.profiles.xrd;
        if isempty(xrd)
            return
        end
        if app.profiles.hasFit
            fitted = app.profiles.getProfileResult{1};
                    if xrd.BkgLS
                        if ~isequal(app.profiles.FitInitial.coeffs, xrd.getCoeffs)
                            result = true;
                            xrd.BkgLS=0;
                            xrd.ignore_bounds=0;
                        end
                        
                    else % For when not using BkgLS since it works, reversed or
                        % add transpose to xrd.getFunctionNames on
                        % 07-09-2020
                        if isequal(app.FcnNames, xrd.getFunctionNames') && ... % this elseif is to yield a result=false after completing a fit, reversed order of this and subsequent on 3-4-2017
                            isequal(app.Coefficients, xrd.getCoeffs) && ...
                            isequal(fitted.CoeffValues, app.FitResults{1}) % troubleshoot, this needs to compare to last stored fit, 07-09-2020
                            result = false;
                        elseif ~isequal(fitted.FunctionNames, app.FcnNames) || ...
                            ~isequal(fitted.CoeffNames, app.Coefficients) || ...
                            ~isequal(fitted.FitInitial.start, app.FitInitial.start)
                            result = true;                            
                        elseif isequal(app.FcnNames, xrd.getFunctionNames) || ... % this else if is to yield result=true when a constaint has been checked
                            isequal(app.Coefficients, xrd.getCoeffs) || ...
                            isequal(app.FitInitial.start, xrd.FitInitial.start)
                        result = true;
                        end
                    end
        else
            result = true;
        end
        
        end
        
        function value = areFuncsReady(app)
                    if isempty(find(cellfun(@isempty, app.FcnNames), 1))
                        % No blank cells in table
                        value = true;
                    else
                        value = false;
                    end
        end
        
        function h = FcnNames(app)
                    % Cell array of the fit function names.
                h = app.UITable.Data;

        end
        
        
        function setTable(this,input)
        
        hObject = this.UITable2;
        % Clear the table if input is empty
        if isempty(input)
            hObject.Data = cell(size(hObject.Data));
            return
            
        elseif isnumeric(input)
            if length(input) ~= length(hObject.Data(:,1))
                msg = 'Value must be the same length as table';
                throw(MException('LIPRAS:ProfileGUIManager:FitInitial:InvalidLength', msg))
            end
            input = num2cell(input);
            hObject.Data = input;
            
        elseif isstruct(input)
            if isfield(input, 'start') && ~isempty(input.start) && all(this.TableC0==0)
                 startpoints = num2cell(input.start)';
                 emptyIdx = cellfun(@(c)c<0, startpoints);
                 emptyIdx(contains(input.coeffs,{'x' 'a'})==1)=0; % allows for negative peak positions             
                 startpoints(emptyIdx) = {[]};
                 hObject.Data(:, 1) = startpoints;
            else
                hObject.Data(:, 1) = cell(size(hObject.Data,1),1);
            end
            if isfield(input, 'lower') && ~isempty(input.lower) && all(this.TableC0==0)
                lower = num2cell(input.lower)';
                emptyIdx = cellfun(@(c)c<0, lower);
                 emptyIdx(contains(input.coeffs,{'x' 'a'})==1)=0; % allows for negative peak positions             
                lower(emptyIdx) = {[]};
                hObject.Data(:, 2) = lower;
            else
                hObject.Data(:, 2) = cell(size(hObject.Data,1),1);
            end
            if isfield(input, 'upper') && ~isempty(input.upper) && all(this.TableC0==0)
                upper = num2cell(input.upper)';
                emptyIdx = cellfun(@(c)c<0, upper);
                 emptyIdx(contains(input.coeffs,{'x' 'a'})==1)=0; % allows for negative peak positions             
                upper(emptyIdx) = {[]};
                hObject.Data(:, 3) = upper;
            else
                hObject.Data(:, 3) = cell(size(hObject.Data,1),1);
            end
        
        elseif ~iscell(input)
            msg = 'Value must be a numeric array.';
            throw(MException('LIPRAS:ProfileGUIManager:FitInitial:InvalidType', msg))
        end
            
            
        end
        
        function results = PeakSelectChange(app)
            try
                if isequal(app.Fxn0, app.UITable.Data) 
                    app.TableC0=0;
                else
                    app.TableC0=1;
                    app.UITable2.Data=[];
                    app.FitDataButton.Enable=0;
                end
            catch ME
                
            end
        end
        
        function results = SelectPointsX(app, mode)
            obj = hittest(app.figure1);
            % 1) Must be inside app.UIAxes
            ax = ancestor(obj,'matlab.ui.control.UIAxes');
            if isempty(ax) || ax ~= app.UIAxes
                return
            end
            results = app.UIAxes.CurrentPoint(1,1);

            if strcmp(mode,'Peaks')
                dataLine = findobj(app.UIAxes, 'tag', 'Obs');
                xdata = dataLine.XData; ydata = dataLine.YData;
                idx = utils.findIndex(xdata, results);
                plot(app.UIAxes, results, ydata(idx), 'xr', 'MarkerSize', 15,'LineWidth',2,'Tag','PeakS');
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function LIPRAS_OpeningFcn(app)
            import ui.control.*
            import model.*
            import utils.fileutils.*

            app.profiles = ProfileListManager.getInstance();
            app.CheckBox0=[fliplr([app.ConstraintsPanel.Children.Value]) app.AsymmetryCheckBox.Value]; % Checkboxes
            app.OrigCD=pwd; % Default directory, used only for recovery if something goes wrong on restart
            
            % guidata(app.figure1,app) % GUIController.getInstance(app.figure1);
            app.gui=GUIController.getInstance(app);
            
            try    LoadPref(app); catch;                disp('No Preference File...one will be written'); SavePref(app);            end % If no preference file, this shouldnt give error
            % assignin('base','app',app);


        end

        % Callback function
        function AsymmPeaks_Callback(app, event)
             if get(o, 'value')
              nFxn=length(app.profiles.xrd.FitFunctions);
              for p=1:nFxn
                  app.profiles.xrd.FitFunctions{p}.Asym=1;
              end
                app.table_fitinitial.Enable='off';
             else
              nFxn=length(app.profiles.xrd.FitFunctions);
              for p=1:nFxn
                  app.profiles.xrd.FitFunctions{p}.Asym=0;
              end
                app.table_fitinitial.Enable='off';
            
            end
        end

        % Callback function: button_browse, menu_new
        function button_browse_Callback(app, event)
                        
            if isfield(event, 'test')
                isNew = app.profiles.newXRD(evt.path, evt.filename);
            else
                app.profiles.XRDMLScan=[]; % for when reading in different datasets, this should always reset to nothing
                isNew = app.profiles.newXRD();
                 app.HTML.HTMLSource=app.formatHTML('Importing selected...');
            end
            
            % Next two lines are fixes to uifileget moving App 'to back'
            drawnow;
            figure(app.figure1)

            if isNew % if not the same dataset as before
                app.DropDown.Items=app.profiles.FileNames;
                app.DropDown.ItemsData=1:app.profiles.xrd.NumFiles;
                app.SelectPeaksButton.Enable=0;            
                app.DropDown.Value=1;
                app.UITable2.Data=[];
                app.UITable3.Data=[];

                try
                ui.update(app, 'dataset');
                cla(app.UIAxes);
                utils.plotutils.plotX(app, 'data'); % this should be activating setappdata(xdata), calls PlotX
                catch
                    
                end
            else
                 app.HTML.HTMLSource=app.formatHTML('Nothing selected...');
            end
            
            if app.profiles.NumFiles~=0 && isNew~=0 % this is for when canceling browse file before a file has been inputtedd..only on startup
            for k=1:app.profiles.NumFiles
                cF(k)=isequal(app.profiles.dataReadin.two_theta{1},app.profiles.dataReadin.two_theta{k}); % test to make sure data is equal for inputted x values
            end
            if sum(double(cF))~=k
            h=warndlg({'You have entered files that do not have the same number of points AND/OR different X-values. CAUTION when using a fitting range', 'Check your 2-Theta range!!!'},'!! Warning !!');
            app.NoEqualData=1;
            else
                app.NoEqualData=0;
            end
            end
            
            if ~isempty(app.profiles.XRDMLScan)
                FiID=app.profiles.XRDMLScan{app.DropDown.Value};
                if ~isempty(FiID)
                    if strcmpi(FiID,'Gonio')||strcmpi(FiID,'2Theta')
                                    app.ThetaLabel.Text=['2' char(952) ' Range (°):'];
                                    app.UIAxes.XLabel.String=['2' char(952) ' (°)'];
                    elseif strcmpi(FiID,'Omega')
                                    app.ThetaLabel.Text=[char(969) ' Range (°):'];
                                    app.UIAxes.XLabel.String=[char(969) ' (°)'];
                    elseif strcmpi(FiID,'Chi')
                                    app.ThetaLabel.Text=[char(967) ' Range (°):'];
                                    app.UIAxes.XLabel.String=[char(967) ' (°)'];                                  
                    elseif strcmpi(FiID,'Phi')
                                    app.ThetaLabel.Text=[char(966) ' Range (°):'];
                                    app.UIAxes.XLabel.String=[char(966) ' (°)'];                                
                    elseif strcmpi(FiID,'2Theta/Omega')
                                    app.ThetaLabel.Text=['2' char(952) ' Range (°):'];
                                    app.UIAxes.XLabel.String=['2' char(952) ' (°)'];                                
                    elseif strcmpi(FiID,'Omega/2Theta')
                                    app.ThetaLabel.Text=['2' char(969) ' Range (°):'];
                                    app.UIAxes.XLabel.String=[char(969) ' (°)'];                        
                    end
                end
            else
                app.ThetaLabel.Text=['2' char(952) ' Range (°):'];
                app.UIAxes.XLabel.String=['2' char(952) ' (°)'];
                % return % Do this since nothing was selected, 1-3-25,
                % 1-10-2026, why was there a return here?
            end
                        
            c=app.DropDown.Value;
            app.DropDownLabel.Visible=1;
            app.DropDownLabel.Text=[num2str(c) ' of ' num2str(app.profiles.xrd.NumFiles)];
            
            % assignin('base','app',app); % may not be needed 1-9-2026
            app.NewButton.Value=1;
            app.EditMin2T.Value=app.profiles.xrd.DataSet{1}.Min2T;
            app.EditMax2T.Value=app.profiles.xrd.DataSet{1}.Max2T;
            app.edit8.Value=app.profiles.DataPath;
            app.EditFieldWave.Enable='on';

            % guidata(app.figure1,app) % need to get rid of this

        end

        % Callback function
        function checkbox_CuKa_Callback(app, event)
            if get(hObject,'Value')
                app.profiles.xrd.CuKa=true;
                app.profiles.xrd.KAlpha1 = app.gui.KAlpha1;
                app.profiles.xrd.KAlpha2 = app.gui.KAlpha2;
                set(app.panel_cuka,'Visible', 'on');
            else
                app.profiles.xrd.CuKa=false;
                set(app.panel_cuka,'Visible', 'off');
            end
        end

        % Callback function
        function checkbox_constraints_Callback(app, event)
            % Save new constraint as an index from panel_constraints.UserData
            if o.Value
                app.profiles.xrd.constrain(o.String);
            else
                app.profiles.xrd.unconstrain(o.String);
            end
            ui.update(app, 'Constraints');
        end

        % Value changed function: checkbox_reverse
        function checkbox_reverse_Callback(app, event)
        value=app.checkbox_reverse.Value;
        if isempty(app.profiles.xrd)
            return
        end
            app.profiles.xrd.reverseDataSetOrder;
            newList=app.profiles.FileNames;
            app.DropDown.Items=newList;
            app.DropDown.Value=1;
            app.DropDownValueChanged;
            
        end

        % Menu selected function: checkUpdate
        function checkforupdates(app, event)
                if nargin<4
                    ini='na';
                end
                        cV=app.profiles.LIPRAS_Version;
            options = weboptions('ContentType','auto');
            data=webread('https://github.com/SneakySnail/LIPRAS/tree/master',options); % for releases based on commit number
            b1=strsplit(data,'<span class="num text-emphasized">\n');
            c=strsplit(b1{4},' '); % number of releases
            b=strsplit(b1{2},' ');
            if and(isequal(str2double(b{2}),cV),strcmp(ini,'na'))
                    d = dialog('Position',[550 550 350 100],'Name','LIPRAS');
                        txt = uicontrol('Parent',d,...
                       'Style','text',...
                       'Position',[70 50 210 40],...
                       'String','LIPRAS is up to date','FontSize',11);
                   txt2 = uicontrol('Parent',d,...
                       'Style','text',...
                       'Position',[70 25 210 40],...
                       'String',['Current Version: ' num2str(app.profiles.LIPRAS_Version)],'FontSize',11); %%%
                btn1 = uicontrol('Parent',d,...
                       'Position',[125 10 100 30],...
                       'String','Close','FontSize',12,...
                       'Callback','delete(gcf)');
            elseif and(~isequal(str2double(b{2}),cV),strcmp(ini,'Silent'))
                app.profiles.Status='<html><strong>New version available, check for updates under "Help". Otherwise, import file(s) to start using "Browse"</strong></html>';
            elseif ~isequal(str2double(b{2}),cV)
                        d = dialog('Position',[550 550 350 150],'Name','LIPRAS');
                        txt = uicontrol('Parent',d,...
                       'Style','text',...
                       'Position',[70 30 210 60],...
                       'String','New version available, check MATLAB FileExchange','FontSize',11);
                          txt2 = uicontrol('Parent',d,...
                       'Style','text',...
                       'Position',[70 90 210 25],...
                       'String',['Current Version: ' num2str(app.profiles.LIPRAS_Version)],'FontSize',11); %%%
                          txt3 = uicontrol('Parent',d,...
                       'Style','text',...
                       'Position',[70 120 210 25],...
                       'String',['Available Version: ' num2str(b{2})],'FontSize',11); %%%
                btn1 = uicontrol('Parent',d,...
                       'Position',[190 10 100 30],...
                       'String','Close','FontSize',12,...
                       'Callback','delete(gcf)');
                    btn2 = uicontrol('Parent',d,...
                       'Position',[60 10 100 30],...
                       'FontSize',12,...
                       'Callback',@webupdate);
                   set(btn2, 'String', '<html><center>Download</center>');
            
            end
        end

        % Callback function
        function edit_kalpha_Callback(app, event)
            ka1 = str2double(get(app.edit_kalpha1, 'String'));
            ka2 = str2double(get(app.edit_kalpha2, 'String'));
            app.profiles.xrd.KAlpha1 = ka1;
            app.profiles.xrd.KAlpha2 = ka2;
            app.gui.KAlpha1 = ka1;
            app.gui.KAlpha2 = ka2;
            utils.plotutils.plotX(app);
            app.gui.Status = ['<html>' hObject.TooltipString ' wavelength set to ' hObject.String '.'];
        end

        % Callback function
        function listbox_results_Callback(app, event)
            %listbox_results_Callback executes when the selection is changed in the
            
            %   - If 'Peak Fit' view is selected, the listbox displays a list of all
            %   the files. When the selection is changed, it plots the fit for the
            %   new selection.
            %   - If 'Coefficient Trends' is selected, the listbox displays a list of
            %   the coefficient names. When the selection is changed, it plots the
            %   coefficient values in a sequence for all files.
            selectedPlotView = app.panel_choosePlotView.SelectedObject;
            selectedListItem = hObject.Value;
            switch selectedPlotView
                case app.radio_peakeqn
                    app.gui.CurrentFile = selectedListItem;
                case app.radio_coeff
                    %TODO
            end
            utils.plotutils.plotX(app);
        end

        % Menu selected function: menuPlot_superimpose
        function menuPlot_superimpose_Callback(app, event)
             cla(app.UIAxes)
             if app.menuPlot_superimpose.Checked=='on'
                 app.menuPlot_superimpose.Checked='off';
             else
             app.menuPlot_superimpose.Checked='on';
             end
             % If box is checked, turn on hold in axes1
             if app.menuPlot_superimpose.Checked=='on'
                 hold(app.UIAxes, 'on')
                 app.UIAxes.ColorOrderIndex = 1;
                 utils.plotutils.plotX(app, 'superimpose');
                 app.HTML.HTMLSource=app.formatHTML('Superimposing data...');
             else
                 utils.plotutils.plotX(app);
                 app.HTML.HTMLSource='<div> </div>';
             end
%              app.gui.Legend = 'reset';
        end

        % Menu selected function: menu_FileResetProfile
        function menu_FileResetProfile_Callback(app, event)
                if isempty(app.profiles.xrd)
                    app.HTML.HTMLSource=app.formatHTML('Nothing to reset');
                else
            app.profiles.reset;
            cla(app.UIAxes);
            ui.update(app, 'dataset');
            % Reset actions
            app.disableFitData;
            app.UITable.Data=[]; app.NumberofPeaksSpinner.Value=0; % reset profiles table.
            app.SelectPeaksButton.Enable='off';
            app.LineProfilesPanel.Enable="off";

            utils.plotutils.plotX(app, 'data');
            app.HTML.HTMLSource=app.formatHTML('Profiles reset');

%             app.gui.Legend = 'reset';
% add here reset functions
                end
        end

        % Menu selected function: menu_about
        function menu_about_Callback(app, event)
            % Displays a message box
            
            tex=['LIPRAS, App Version:' ' ' num2str(app.profiles.LIPRAS_Version)];
            h = msgbox({tex 'Authors: Klarissa Ramos, Giovanni Esteves, ' ...
                'Christopher M. Fancher, and Jacob L. Jones' ' ' 'North Carolina State University (2016)' '' ...
                'Contact Information' 'Giovanni Esteves' 'Email: gesteves21@gmail.com' ...
                'Jacob Jones' 'Email: jacobjones@ncsu.edu'}, 'About');
            hold on
            
            set(h, 'Position',[500 440 400 300]) % posx, posy, horiz, vert
            ah=get(h,'CurrentAxes');
            c=get(ah,'Children');
            set(c,'FontSize',10);
            I=imread(strcat('Logo',filesep,'Logo_R4.png'));
            I=flipud(I);
            image(I);
        end

        % Menu selected function: FitParamImport
        function FitParamImport_Callback(app, event)

        if app.profiles.hasData
            % Fit exists — open dialog in that directory
            filespec = fullfile(app.profiles.OutputPath, '*.txt');
            [filename, pathName] = uigetfile(filespec, 'Select Input File', ...
                                             'MultiSelect', 'off');
        else
            % No fit yet — return
                            app.gui.PriorityStatus='Input data first, then import';
            return
        end
        
        % Only proceed if user actually selected a file
        if ischar(filename) && ~isequal(filename, 0)
            fullFile = fullfile(pathName, filename);
            app.profiles.importProfileParametersFile(fullFile);
            ui.update(app, 'parameters');
        end
        end

        % Callback function
        function menu_preferences_Callback(app, event)
%                 pref(app);
        end

        % Menu selected function: menu_restart
        function menu_restart_Callback(app, event)
            OrigCD=app.OrigCD; % takes OrigCD before deletion
            app.menu_closeMenuSelected
            try
            LIPRAS_App;
            pause(1) % wait
            catch
                cd(OrigCD)
            LIPRAS_App;
                disp('Switching to LIPRAS Directory...')
            end
            
        end

        % Menu selected function: FitParamExport
        function FitParamExport_Callback(app, event)
            if app.profiles.hasFit
                app.profiles.exportProfileParametersFile();
            end
        end

        % Menu selected function: menu_saveasimage
        function menu_saveasimage_Callback(app, event)
                if ~isempty(app.profiles.FitResults)
                    exportPlotAsImage(app);
                else
                   msgbox('Conduct a fit first')
                end
        end

        % Menu selected function: menu_xaxis_dspace, menu_xaxis_linear
        function menu_xplotscale_Callback(app, event)
            plotter = app.gui.Plotter;
            if ~app.profiles.hasData
                app.HTML.HTMLSource=app.formatHTML('Input data to continue...');
                return

            end

            if isa(app.profiles.xrd.MonoWavelength,'numeric')
            wave=app.profiles.xrd.MonoWavelength;
            end
            o=event.Source.Text;
            switch o
                case 'Linear'
                    plotter.XScale = 'linear';
                    app.menu_xaxis_linear.Checked="on";
                    app.menu_xaxis_dspace.Checked="off";

                case 'D-Space'
     
                    plotter.XScale = 'dspace';
                    app.menu_xaxis_dspace.Checked="on";
                    app.menu_xaxis_linear.Checked="off";

            end

        end

        % Menu selected function: menu_ylinear, menu_ylog, menu_yroot
        function menu_yplotscale_Callback(app, event)
            %MENU_YPLOTSCALE_CALLBACK executes when any option under 'Plot'->'Y-Axis Scale' menu is clicked. The
            %   default selection is 'menu_ylinear'.
            % turn off checks in all x plot menu items
            app.menu_yaxis.Checked="off";
            plotter = app.gui.Plotter;
            o=event.Source.Text;
            switch o
                case 'Linear' % linear
%                     app.UIAxes.YScale="linear";
                    plotter.YScale = 'linear';
                    app.menu_ylinear.Checked="on";
                    app.menu_yroot.Checked="off";
                    app.menu_ylog.Checked="off";
                   
                case 'Logarithm'% log
                    plotter.YScale = 'log';
%                     app.UIAxes.YScale="log";
                    app.menu_ylog.Checked="on";
                    app.menu_ylinear.Checked="off";
                    app.menu_yroot.Checked="off";

                case 'Square Root' % d-space
                    plotter.YScale = 'sqrt';
                    app.menu_yroot.Checked="on";
                    app.menu_ylinear.Checked="off";
                    app.menu_ylog.Checked="off";


            end
                    drawnow
                    figure(app.figure1)
        end

        % Callback function
        function panel_choosePlotView_SelectionChangedFcn(app, event)
            % Executes upon Plot View change in the Results tab.
            
            switch hObject.SelectedObject
                case app.radio_peakeqn
                    app.gui.onPlotFitChange('peakfit');
            
                case app.radio_coeff
                    app.gui.onPlotFitChange('coeff');
            
                case app.radio_statistics
                    hObject.SelectedObject = evt.OldValue;
                    app.gui.onPlotFitChange('stats');
            
            end
        end

        % Callback function
        function popup_bkgdmodel_Callback(app, event)
            app.profiles.xrd.setBackgroundModel(o.String{o.Value});
            ui.update(app,'backgroundmodel');
        end

        % Callback function
        function popup_filename_Callback(app, event)
            app.gui.CurrentFile = hObject.Value;
            app.profiles.xrd.CurrentPro=hObject.Value;
            
            if ~isempty(app.profiles.XRDMLScan)
                FiID=app.profiles.XRDMLScan{app.popup_filename.Value};
                    if strcmpi(FiID,'Gonio')||strcmpi(FiID,'2Theta')
                                    app.text4.String=['2' char(952) 'Range (°):'];
                    elseif strcmpi(FiID,'Omega')
                                    app.text4.String=[char(969) ' Range (°):'];
                    elseif strcmpi(FiID,'Chi')
                                    app.text4.String=[char(967) ' Range (°):'];
                    elseif strcmpi(FiID,'Phi')
                                    app.text4.String=[char(966) ' Range (°):'];
                    elseif strcmpi(FiID,'2Theta/Omega')
                                    app.text4.String=['2' char(952) ' Range (°):'];
                    elseif strcmpi(FiID,'Omega/2Theta')
                                    app.text4.String=['2' char(969) ' Range (°):'];
                    end
            app.gui.Plotter.updateXLabel(app.axes1)
            else
                 app.text4.String=['2' char(952) 'Range (°):'];
            end
            
            superimposed=strcmp(app.menuPlot_superimpose.Checked,'on');
            if superimposed
                utils.plotutils.plotX(app, 'superimpose');
            else
                utils.plotutils.plotX(app);
            end
        end

        % Callback function
        function push_fitbkgd_Callback(app, event)
            import utils.plotutils.*
            
            if Validate_bkg(app) % checks to make sure BkgOrder is not greater than points selected
                return
            end
            
            if ~app.gui.areFuncsReady || app.gui.isFitDirty
                utils.plotutils.plotX(app, 'background');
            else
            app.profiles.xrd.calculateBackground;
            utils.plotutils.plotX(app, 'sample');
            end
        end

        % Callback function
        function push_fitdata_Callback(app, event)
            
            if Validate_bkg(app)
                return
            end
            
            % From Preferences
            app.profiles.xrd.Weights=app.profiles.Weights;
            app.profiles.xrd.UniqueSave=app.profiles.UniqueSave;
            try
                app.profiles.xrd.FitInitial.lower=app.gui.FitInitial.lower;
                app.profiles.xrd.FitInitial.upper=app.gui.FitInitial.upper;
            catch
            end
            
            try
                prfn = app.profiles.ActiveProfile;
                fitresults = app.profiles.fitDataSet(prfn);
            
                % this is so when switching constraints, the table wont update with fitted values until a fit is done
                app.profiles.xrd.OriginalFitInitial.coeffs=app.gui.FitInitial.coeffs;
                if ~isempty(fitresults)
                    ui.update(app, 'results');
                    utils.plotutils.plotX(app,'fit');
                else
                    utils.plotutils.plotX(app,'sample');
                end
            catch ME
                ME.getReport
                assignin('base','lastException',ME)
                errordlg(ME.message)
                return
            end
        end

        % Callback function
        function push_fitstats_Callback(app, event)
            app.gui.onPlotFitChange('stats');
        end

        % Callback function
        function push_newbkgd_Callback(app, event)
            %   EVENTDATA can be used to pass test values to this function to avoid any blocking calls like
            %   ginput. If the number of background points is less than the background order,
            %    issue a warning.
            import utils.plotutils.*
            utils.plotutils.plotX(app,'data');
            % app.checkbox_superimpose.Value = 0;
            app.gui.PriorityStatus = 'Selecting background points... Press the ESC key to cancel, "Z" to toogle zoom capability, and "Enter" to finish. Right-clicking can be used to delete points.';
            mode = get(app.group_bkgd_edit_mode.SelectedObject, 'String');
            points = utils.selectBackgroundPoints(app, mode);
            if length(points) == 1 && isnan(points)
                utils.plotutils.plotX(app, 'backgroundfit');
                return
            end
            app.profiles.BackgroundPoints = points;
            
            ui.update(app, 'backgroundpoints');
            utils.plotutils.plotX(app, 'background');
            if length(points) <= app.gui.PolyOrder
                LiprasDialogCollection.PolyNotUniqueWarning;
            end
        end

        % Callback function
        function push_selectpeak_Callback(app, event)
            import utils.contains
            import utils.plotutils.*
            
            if Validate_bkg(app) % checks to make sure BkgOrder is not greater than points selected
                return
            end
            
            positions = utils.plotutils.selectPeakPoints(app);
            if sum(positions)==0
                msgbox('No more points to delete, try again')
                return
            end
            if length(positions) < app.profiles.NumPeaks
                utils.plotutils.plotX(app, 'sample');
            else
                app.profiles.PeakPositions = positions;
                app.profiles.FitInitial = 'new';
                app.gui.PriorityStatus = 'New peak positions are set.';
                ui.update(app, 'peakposition');
                ui.update(app, 'fitinitial_peakselect');
                utils.plotutils.plotX(app, 'sample');
                app.profiles.xrd.OriginalFitInitial=app.profiles.xrd.FitInitial;
            end
        end

        % Callback function
        function push_tabswitch_Callback(app, event)
            % Switches between Tabs 1 (Setup), 2 (Parameters), and 3 (Results).
            switch hObject.Tag
                case 'tab1_next'
                    set(app.tabpanel, 'Selection', 2);
                case 'tab2_prev'
                    set(app.tabpanel, 'Selection', 1);
                case 'tab2_next'
                    set(app.tabpanel, 'Selection', 3);
                case 'tab3_prev'
                    set(app.tabpanel, 'Selection', 2);
            end
        end

        % Callback function
        function push_update_Callback(app, event)
            % This function sets the table_fitinitial in the GUI to have the coefficients for the new
            % user-inputted function names.
            % It also saves app.guidata into app.xrd
            
            % Doesnt update if BkgOrder is larger than points selected
            if Validate_bkg(app) % checks to make sure BkgOrder is not greater than points selected
                return
            end
             if app.radio_coeff.Value
            app.gui.onPlotFitChange('peakfit')
             end
            %
            if isempty(app.profiles.FitInitial)
                s=1;
            else
                       s= length(find(app.profiles.FitInitial.start<0));
                       l= length(find(app.profiles.FitInitial.lower<0));
                       u= length(find(app.profiles.FitInitial.upper<0));
                       nC=length(app.profiles.FitInitial.start);
                       try
                           nnC=length(app.gui.FitInitial.coeffs);
                       catch
                       end
            end
            app.profiles.FcnNames = app.gui.FcnNames;
            app.profiles.FitInitial = 'default';
            
                       try
                           nnC=length(app.profiles.FitInitial.coeffs);
                       catch
                       end
            
            cla(app.axes1);
            ui.update(app, 'fitinitial');
            utils.plotutils.plotX(app,'sample');
            
            app.gui.Legend = 'reset';
            
            app.gui.PriorityStatus = 'Fit options were updated.';
            if s==0 && l==0 && u==0
            else
            if s==1 || s==nC || s~=nnC
            %     app.table_fitinitial.Enable='off';
                app.gui.PriorityStatus='Now Select Peak Posistions using Select Peak(s)';
            end
            end
        end

        % Callback function
        function push_viewall_Callback(app, event)
            utils.plotutils.plotX(app, 'allfits');
        end

        % Callback function
        function pushbutton44_Callback(app, event)
                    f=helpdlg('Initiating Bayesian Inference GUI: Alpha release, some of these analysis can take anywhere from several seconds to hours');
                    waitfor(f)
            evalin('base','BayesLIPRASGUI')
        end

        % Callback function
        function table_fitinitial_CellEditCallback(app, event)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.TABLE)
            %	Indices: row and column indices of the cell(s) edited
            %	PreviousData: previous data for the cell(s) edited
            %	EditData: string(s) entered by the user
            %	NewData: EditData or its converted form set on the Data property. Empty if Data was not changed
            %	Error: error string when failed to convert EditData to appropriate value for Data
            if isnan(evt.NewData)
                app.gui.PriorityStatus = '<html><font color="red">Not a valid number.';
            	hObject.Data{evt.Indices(1), evt.Indices(2)} = evt.PreviousData;
            else
                   app.profiles.FitInitial = app.gui.FitInitial;
            %        app.gui.FitInitial = app.profiles.FitInitial;
            %     ui.update(app, 'fitinitial_tableEdit'); %i dont think this is
            %     needed otherwise it resets the table based on edit which is annoying
            %     when editing a value of N9, x9, etc,.,,
                emptyCell = find(cellfun(@isempty, app.table_fitinitial.Data), 1);
                if isempty(emptyCell)
                set(app.push_fitdata, 'enable', 'on');
                pause(0.5) % this is needed otherwise the GUI is too fast and wont activate...
                else
                set(app.push_fitdata, 'enable', 'off');
                end
                utils.plotutils.plotX(app, 'sample');
            end
            disp('Line 1760 code is needed')
            
            % assignin('base', 'app', app);
            % guidata(hObject,app)
        end

        % Callback function
        function table_paramselection_CellEditCallback(app, event)
            %   EVT can be used to test the GUI by passing a struct variable with the field name 'test'
            %   containing the value to set. It also has the field 'Indices'.
            row = evt.Indices(1);
            col = evt.Indices(2);
            if col == 1
                % Function change
                app.profiles.FcnNames{row} = app.gui.FcnNames{row};
                ui.update(app, 'functions');
                app.profiles.xrd.constrain(app.gui.Constraints);
                ui.update(app, 'constraints');
            else
                % On constraint value change
                app.profiles.xrd.unconstrain('Nxfwm');
                app.profiles.xrd.constrain(app.gui.ConstraintsInTable);
                ui.update(app, 'Constraints');
            end
        end

        % Value changed function: NumberofPeaksSpinner
        function NumberofPeaksSpinnerValueChanged(app, event)
            value = app.NumberofPeaksSpinner.Value;
            ln=length(app.UITable.Data);
            if value==1
            app.UITable.Data = {''}; % have 3 rows of gender information.
            app.UITable.ColumnFormat = {{'Gaussian', 'Lorentzian','Pearson VII','Pseudo-Voigt','Asymmetric Pearson VII'}}; % have option cell array within ColumnFormat property to indicate the 1st column is using dropdown menus.
            app.UITable.ColumnEditable = true;
            else
                % Logic for increasing and decreasing number of profiles in
                % fit
               if value== ln+1
                row=cell(value,1);
                app.UITable.Data{end+1,1}='';
               elseif value< ln
                   app.UITable.Data(end)=[];
               elseif isempty(app.UITable.Data{value})
                   app.UITable.Data{end+1,1}='';
               else
                   
               end
            app.UITable.ColumnFormat = {{'Gaussian', 'Lorentzian','Pearson VII','Pseudo-Voigt','Asymmetric Pearson VII'}}; % have option cell array within ColumnFormat property to indicate the 1st column is using dropdown menus.
            % added 07-12-2020 to reduce user from accidentally typing a
            % letter during peak fxn selection and creating a new profile
            % shape function that is not available. 
            end
            
            try % added on 12-28-2022
                nP=sum(cellfun(@isempty, app.UITable.Data)); % how many are empty
                if nP~=0
                app.SelectPeaksButton.Enable='off';
                else
                app.SelectPeaksButton.Enable='on';

                end
            catch

            end
            UpdateButtonPushed(app, event) % added 1-5-26, attempt to remove Update button
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            c= app.DropDown.Value;
            
            app.DropDownLabel.Text=[num2str(c) ' of ' num2str(app.profiles.xrd.NumFiles)];
            app.gui.CurrentFile=c;
                        
            if  app.profiles.hasFit
                utils.plotutils.plotX(app,'fit')
                
                % Extract result values
                fitResult = app.profiles.FitResults{1}{app.DropDown.Value};
                rchi2 = round(fitResult.Rchi2, 2);
                rp    = round(fitResult.Rp, 2);
                rwp   = round(fitResult.Rwp, 2);
                
                % Create formatted HTML
                html = sprintf([ ...
                    '<div align="left"><font size="2" face="Helvetica"><b>', ...
                    '&Chi;<sup>2</sup> = %.2f, ', ...
                    'R<sub>p</sub> = %.2f%%, ', ...
                    'R<sub>wp</sub> = %.2f%%', ...
                    '</b></font></div>'], ...
                    rchi2, rp, rwp);
                
                % Assign to component
                app.HTML2.HTMLSource = html;

            elseif~isempty(app.profiles.xrd.Background.InitialPoints)
                delete(app.UIAxes.Children(~contains({app.UIAxes.Children.Tag},'Obs'))) % delete everything but Obs
                app.profiles.xrd.Background.Order=app.PolyOrderSpinner.Value;
                bkg=app.profiles.xrd.calculateBackground;
                utils.plotutils.plotX(app,'background')
            else
            end

            app.profiles.xrd.CurrentPro=c;
            
            if ~isempty(app.profiles.XRDMLScan)
                FiID=app.profiles.XRDMLScan{c};
                    if strcmpi(FiID,'Gonio')||strcmpi(FiID,'2Theta')
                                    app.ThetaLabel.Text=['2' char(952) 'Range (°):'];
                    elseif strcmpi(FiID,'Omega')
                                    app.ThetaLabel.Text=[char(969) ' Range (°):'];
                    elseif strcmpi(FiID,'Chi')
                                    app.ThetaLabel.Text=[char(967) ' Range (°):'];
                    elseif strcmpi(FiID,'Phi')
                                    app.ThetaLabel.Text=[char(966) ' Range (°):'];
                    elseif strcmpi(FiID,'2Theta/Omega')
                                    app.ThetaLabel.Text=['2' char(952) ' Range (°):'];
                    elseif strcmpi(FiID,'Omega/2Theta')
                                    app.ThetaLabel.Text=['2' char(969) ' Range (°):'];
                    end
            app.gui.Plotter.updateXLabel(app.UIAxes)
            else
                 app.ThetaLabel.Text=['2' char(952) ' Range (°):'];
            end
            
    superimposed=strcmp(app.menuPlot_superimpose.Checked,'on'); 
    if superimposed
        utils.plotutils.plotX(app, 'superimpose');
    else
        utils.plotutils.plotX(app);
        app.gui.Plotter.updateXYLim;
    end

        end

        % Value changed function: EditMin2T
        function EditMin2TValueChanged(app, event)
            
            if isempty(app.profiles.xrd)
                app.EditMin2T.Value=0;
                return
            end
            
            value = app.EditMin2T.Value;
             if app.NoEqualData==1
                for p=1:app.profiles.NumFiles
                    cK(p)=min(app.profiles.dataReadin.two_theta{p});
                end
                if any(find(cK>str2double(app.edit_min2t.String)))
                    h=warndlg('You have files with different number of data points!! Check your bounds to ensure that it is captured by all files');
                    app.gui.Min2T = app.profiles.Min2T;
                    return
                end
            end
            app.profiles.Min2T = app.EditMin2T.Value;
            
            if length(app.profiles.BackgroundPoints) <= app.PolyOrderSpinner.Value % not sure why its here
                c=app.DropDown.Value;
            end
                utils.plotutils.plotX(app,'data') % moved outside of prev If on 12-17-2022
                
                if app.menu_xaxis_dspace.Checked=='on' % changes to d-space if selected when editing 2Theta range.
                    app.gui.Plotter.XScale="dspace";
                end


            if app.EditMin2T.Value<app.profiles.xrd.AbsoluteRange(1)
                app.EditMin2T.Value=app.profiles.xrd.AbsoluteRange(1);
            end
           
        end

        % Value changed function: EditMax2T
        function EditMax2TValueChanged(app, event)
            if isempty(app.profiles.xrd)
                app.EditMax2T.Value=0;
                return
            end
            
            value = app.EditMax2T.Value;
             if app.NoEqualData==1
                for p=1:app.profiles.NumFiles
                    cK(p)=min(app.profiles.dataReadin.two_theta{p});
                end
                if any(find(cK>str2double(app.edit_max2t.String)))
                    h=warndlg('You have files with different number of data points!! Check your bounds to ensure that it is captured by all files');
                    app.gui.Min2T = app.profiles.Min2T;
                    return
                end
            end
            app.profiles.Max2T = app.EditMax2T.Value;
            
            if length(app.profiles.BackgroundPoints) <= app.PolyOrderSpinner.Value % Not sure why this was added                
                c=app.DropDown.Value;
            end
                utils.plotutils.plotX(app,'data')

                if app.menu_xaxis_dspace.Checked=='on' % changes to d-space if selected when editing 2Theta range.
                    app.gui.Plotter.XScale="dspace";
                end

            if app.EditMax2T.Value>app.profiles.xrd.AbsoluteRange(2)
                app.EditMax2T.Value=app.profiles.xrd.AbsoluteRange(2);
            end
        end

        % Value changed function: RefineBkgCheckBox
        function RefineBkgCheckBoxValueChanged(app, event)
            if isempty(app.profiles.xrd)
                app.EditMax2T.Value=0;
                return
            end
            
            value = app.RefineBkgCheckBox.Value;
            if value
              app.profiles.xrd.BkgLS = 1;
            else
              app.profiles.xrd.BkgLS = 0;
            end
        end

        % Value changed function: NoBoundsCheckBox
        function NoBoundsCheckBoxValueChanged(app, event)
            if isempty(app.profiles.xrd)
                app.EditMax2T.Value=0;
                return
            end
            
            value = app.NoBoundsCheckBox.Value;
            if value

              app.profiles.xrd. ignore_bounds=1;
            else

              app.profiles.xrd.ignore_bounds=0;
            
            end
        end

        % Value changed function: RecycleCheckBox
        function RecycleCheckBoxValueChanged(app, event)
            if isempty(app.profiles.xrd)
                app.EditMax2T.Value=0;
                return
            end            
            value = app.RecycleCheckBox.Value;
            if value

              app.profiles.xrd.recycle_results=1;
            else
      
              app.profiles.xrd.recycle_results=0;
            
            end
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            delete(app.gui);
            delete(app.profiles);    
            app.delete
            clear('app', 'var');

        end

        % Menu selected function: menu_help
        function menu_helpMenuSelected(app, event)
            open('LIPRAS_Manual_6.pdf')
        end

        % Value changed function: PolyOrderSpinner
        function PolyOrderSpinnerValueChanged(app, event)
            value = app.PolyOrderSpinner.Value;
            if isempty(app.profiles.xrd)
            disp('Add data...')
            return
            end
            app.profiles.xrd.setBackgroundOrder(value);

            if value == 1 && strcmpi(app.profiles.xrd.getBackgroundModel, 'Spline')
            app.PolyOrderSpinner.Value=2;
            app.gui.PriorityStatus = '<html><font color="red">Spline Order must be > 1.';
            app.profiles.xrd.setBackgroundOrder(app.PolyOrderSpinner.Value);
            end
        end

        % Button pushed function: SelectPointsButton
        function SelectPointsButtonPushed(app, event)
            if isempty(app.profiles.xrd)
                app.HTML.HTMLSource=app.formatHTML('Input data to continue...');
                return
            end
            
            if ~ishold(app.UIAxes) % set plot to hold if not already
            hold(app.UIAxes)              
            end
            
            ab=[app.NewButton.Value app.AddButton.Value app.DeleteButton.Value];
            [~,c]=find(ab>0);
            if c==1
                mode='New';
                    if ~isempty(app.profiles.BackgroundPoints)
                        nn=length(app.profiles.BackgroundPoints);
                        app.profiles.BackgroundPoints=[];
                        bkgdplot = findobj(app.UIAxes.Children, 'tag', 'Bkgpts');

                        if isempty(bkgdplot)
                        else                        
                        delete(app.UIAxes.Children(~contains({app.UIAxes.Children.Tag},'Obs'))) % delete everything but Obs
                        end
                        
                        if ~isempty(findobj(app.UIAxes.Children, 'tag', 'background'))
                        delete(app.UIAxes.Children(contains({app.UIAxes.Children.Tag},'background'))) % delete only background

                        end
                    end
                
            elseif c==2
                mode='Add';                
                       if ~isempty(findobj(app.UIAxes.Children, 'tag', 'background'))
                        delete(app.UIAxes.Children(~contains({app.UIAxes.Children.Tag},'Obs'))) % delete everything but Obs
                       end     
                       
                       % For when user wants to go back and edit points after hitting 'Plot Background', recreate all points selected
                        bkgdplot = findobj(app.UIAxes.Children, 'tag', 'Bkgpts');
                        if isempty(bkgdplot) 
                            bkgpt=app.profiles.BackgroundPoints;
                            id=app.profiles.xrd.Background.InitialPointsIdx; % indexed points in XY data
                            dataLine = findobj(app.UIAxes.Children, 'tag', 'Obs');
                            xdata = dataLine.XData; ydata = dataLine.YData;
                            nn=length(bkgpt);
                            for i=1:nn
                            plot(app.UIAxes, bkgpt(i), ydata(id(i)), '*r','MarkerSize',8,'LineWidth',1.3,'Tag','Bkgpts');
                            end
                        end
                       
            else
                mode='Delete';  
                        if ~isempty(findobj(app.UIAxes.Children, 'tag', 'background'))
                            delete(app.UIAxes.Children(~contains({app.UIAxes.Children.Tag},'Obs'))) % delete everything but Obs
                        end
                        
                        % For when user wants to go back and edit points after hitting 'Plot Background', recreate all points selected
                        bkgdplot = findobj(app.UIAxes.Children, 'tag', 'Bkgpts');
                        if isempty(bkgdplot) 
                            bkgpt=app.profiles.BackgroundPoints;
                            id=app.profiles.xrd.Background.InitialPointsIdx; % indexed points in XY data
                            dataLine = findobj(app.UIAxes.Children, 'tag', 'Obs');
                            xdata = dataLine.XData; ydata = dataLine.YData;
                            nn=length(bkgpt);
                            for i=1:nn
                            plot(app.UIAxes, bkgpt(i), ydata(id(i)), '*r','MarkerSize',8,'LineWidth',1.3,'Tag','Bkgpts');
                            end
                        end
            end
            app.BkgMode=mode;
            app.BkgSelect='on';
            app.HTML.HTMLSource=app.formatHTML("Background selection is ON, Press 'Enter' to end ");
            app.KeyPressed='';
            app.LineProfilesPanel.Enable='on';


        end

        % Window button down function: figure1
        function figure1WindowButtonDown(app, event)
           
            obj = hittest(app.figure1);
            
            % Only react to clicks inside app.UIAxes
            ax = ancestor(obj,'matlab.ui.control.UIAxes');
            if isempty(ax) || ax ~= app.UIAxes
                return
            end
            
            % Safe: click is inside UIAxes, keep for debugging
            x = app.UIAxes.CurrentPoint(1,1);
            y = app.UIAxes.CurrentPoint(1,2);
            % disp([x y])


            % if ~isempty(event.Source.CurrentObject) &&
            % isequal(event.Source.CurrentObject.Tag,'Obs') % Toggle this if back in and you will have the ability to pick bkg points and peaks based on actual data
                %Store the CurrentPiont of the UIAxes as app.a
                app.a = app.UIAxes.CurrentPoint(1);
                alpha = 200;
                %Set the XLim of the bottom UIAxes to be +/- alpha of the
                %current point
                app.PointSelected = [(app.a-alpha) (app.a+alpha)];
                % disp(app.PointSelected) % keep for debug, 1-9-2026,
                % reports all clicks
                
                %** CLICKTYPE
                clickType = get(app.figure1, 'SelectionType');
                % disp(clickType) % 'alt' is used for right-click, keep for debugging, 1-9-2026
           
       %******** For BKG SELECTION START  
                if strcmp(app.BkgMode,'New')&&~isempty(app.profiles)&&~strcmp(app.KeyPressed,'escape')&&strcmp(app.BkgSelect,'on')
                  
                    if isempty(app.UIAxes.Children)
                        % No graphics objects — axes is effectively empty
                        app.HTML.HTMLSource=app.formatHTML("Data hasnt loaded, and nothing is being plotted...");
                        return
                    end
                     
                  points = utils.selectBackgroundPoints(app, 'New');
               
                  %To store bkgpoints
                    if isempty(app.profiles.BackgroundPoints)
                     app.profiles.BackgroundPoints = points;
                    else
                     app.profiles.BackgroundPoints = [app.profiles.BackgroundPoints points];
                    end

                elseif strcmp(app.BkgMode,'Delete')&&~isempty(app.profiles)&&~strcmp(app.KeyPressed,'escape')&&strcmp(app.BkgSelect,'on')
                    if isempty(app.profiles.xrd.getBackgroundPoints)
                        app.BkgSelect='off';
                        disp('Deleted All points')
                        return
                    end
      
                  points = utils.selectBackgroundPoints(app, 'Delete');
           
                  app.profiles.BackgroundPoints = points;
                  
                elseif strcmp(app.BkgMode,'Add')&&~isempty(app.profiles)&&~strcmp(app.KeyPressed,'escape')&&strcmp(app.BkgSelect,'on')
               
                    points = utils.selectBackgroundPoints(app, 'add');
                                  
                  %To store bkgpoints
                    if isempty(app.profiles.BackgroundPoints)
                     app.profiles.BackgroundPoints = points;
                    else
                     app.profiles.BackgroundPoints = [app.profiles.BackgroundPoints points];
                    end                  
                end
        %******** For BKG SELECTION END
        
        %******** For Peak SELECTION START  
        if strcmp(app.PeakSelect,'on')

            if ~ishold(app.UIAxes); hold(app.UIAxes);end
            
            % Peaks=utils.plotutils.selectPeakPoints(app);
            % Peaks=selectPeakPointsXXX(app);
            Peaks=SelectPointsX(app,'Peaks'); % Works the same, guess we will go with this

            if ~isempty(app.PeakPositions)
            app.PeakPositions=[app.PeakPositions Peaks];
            else
            app.PeakPositions=Peaks; % should only ping on first peak selected    
            end
                if app.NumberofPeaksSpinner.Value==length(app.PeakPositions)
                    app.PeakSelect='off';
                    app.HTML.HTMLSource=app.formatHTML('Peak selection is done');
                    delete(app.UIAxes.Children(1:app.NumberofPeaksSpinner.Value))
                    
                    app.profiles.xrd.PeakPositions=app.PeakPositions;
                    app.profiles.xrd.PeakPositions_=app.PeakPositions;
                    app.profiles.xrd.FitInitial=app.profiles.xrd.getDefaultBounds;

                    %% This is where we compute estimates
                    ui.update(app,'fitinitial_peakselect')
                    setTable(app,app.profiles.xrd.FitInitial)
                    utils.plotutils.plotX(app,'sample')
                    app.FitDataButton.Enable=1;
                end          
        end
        
        %******** For Peak SELECTION END  
                if strcmp(app.KeyPressed,'escape')% need to display that BkgSelectionis off
                app.BkgSelect='off';
                app.HTML.HTMLSource=app.formatHTML('Selection CANCELED');
                elseif strcmp(app.KeyPressed,'return') 
                app.BkgSelect='off';
                app.HTML.HTMLSource=app.formatHTML('Selection OFF');
                end

            % end
        end

        % Window key press function: figure1
        function figure1WindowKeyPress(app, event)
            key = event.Key;
                app.KeyPressed=key;
                                
                if strcmp(app.KeyPressed,'escape')% need to display that BkgSelectionis off
                app.BkgSelect='off';
                app.PeakSelect='off';
                    if ishold(app.UIAxes) % set plot to no-hold 
                    hold(app.UIAxes)              
                    end
                app.HTML.HTMLSource=app.formatHTML('Selection CANCELED');
                elseif strcmp(app.KeyPressed,'return') 
                app.BkgSelect='off';
                app.PeakSelect='off';
                    if ishold(app.UIAxes) % set plot to no-hold 
                    hold(app.UIAxes)              
                    end                
                app.PlotBackgroundButtonPushed(app); % Force plot, also updates to right polynomial
                app.HTML.HTMLSource=app.formatHTML('Selection OFF');   
                end
                
                disp(key)
        end

        % Window button motion function: figure1
        function figure1WindowButtonMotion(app, event)
            
        end

        % Button pushed function: PlotBackgroundButton
        function PlotBackgroundButtonPushed(app, event)
            if isempty(app.profiles.xrd) % make sure user doesnt hit plot without loading data
                return                   
            end
            if Validate_bkg(app) % checks to make sure BkgOrder is not greater than points selected
                return
            end

                        
            % From Original code, needs to be fixed if you keep new method
            % of doing bkg since new method relies on other function that
            % is not ginput which is sequential with the following lines.
%                 if length(points) == 1 && isnan(points)
%                     utils.plotutils.plotX(app, 'backgroundfit');
%                     return
%                 end
%                 app.profiles.BackgroundPoints = points;
%                 
%                 ui.update(app, 'backgroundpoints');
%                 utils.plotutils.plotX(app, 'background');


            if ~isempty(app.profiles.xrd.Background.InitialPoints)
                delete(app.UIAxes.Children(1:end-1)); % deletes everything to plot only Background and data
                app.profiles.xrd.Background.Order=app.PolyOrderSpinner.Value;
                
                if app.PolyOrderSpinner.Value > length(app.profiles.xrd.Background.InitialPoints)
                    CreateStruct.Interpreter = 'tex';
                    CreateStruct.WindowStyle = 'modal';
                    uiwait(msgbox('\fontsize{12} Poly order greater than number of points selected ','Warning', CreateStruct));
                    return
                else                
                bkg=app.profiles.xrd.calculateBackground;
                end
                
%                     if ~ishold(app.UIAxes) % set plot to no-hold 
%                     hold(app.UIAxes)              
%                     end
                xrd = app.profiles.xrd;

%                 utils.plotutils.plotX(app, 'backgroundfit');
%                 ui.update(app, 'backgroundpoints');
                utils.plotutils.plotX(app, 'background');

                if ~app.gui.areFuncsReady || app.gui.isFitDirty 
                    utils.plotutils.plotX(app, 'background');
                else
                app.profiles.xrd.calculateBackground;
                utils.plotutils.plotX(app, 'sample');
                end

            end
            app.BkgSelect='off';
            app.LineProfilesPanel.Enable='on';
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            
            % Check if input data exists
            if isempty(app.profiles.FileNames)
                app.HTML.HTMLSource = app.formatHTML("Input data...");
                return
            end
            
            % Validate background order selection
            if Validate_bkg(app)  % returns true if invalid
                return
            end
            
            % Validate UITable selection
            tableData = app.UITable.Data;
            if isempty(tableData) || any(cellfun(@isempty, tableData))
                app.HTML.HTMLSource = app.formatHTML("Select profiles to continue...");
                return
            end
                
            if app.CoefficientsButton.Value
            app.gui.onPlotFitChange('peakfit')
            end

            if isempty(app.profiles.FitInitial) 
                s=1;
            else
                s= length(find(app.profiles.FitInitial.start<0));
                l= length(find(app.profiles.FitInitial.lower<0));
                u= length(find(app.profiles.FitInitial.upper<0));
                nC=length(app.profiles.FitInitial.start);
                try
                   nnC=length(app.gui.FitInitial.coeffs);
                catch
                end
           end

            app.profiles.FcnNames = app.gui.FcnNames;
            app.profiles.FitInitial = 'default';

            % Check Asym property for each FitFunction based on global Asym
            % This needs to go here since app.profiles above initialize,
            % otherwise it rewrites one of the
            % app.profiles.xrd.FitFunctions{p}
            nFxn = numel(app.profiles.xrd.FitFunctions);
            app.Asym=double(app.AsymmetryCheckBox.Value);
            for p = 1:nFxn
                app.profiles.xrd.FitFunctions{p}.Asym = app.Asym;
            end
            
            if logical(app.Asym)
            app.profiles.FitInitial = 'default';% This is needed after adjusting app.Asym, because user should really be selecting peaks next.
            end % Hopefully this preserves the old way of being able to just hit fit after changing profile functions
            
           try
               nnC=length(app.profiles.FitInitial.coeffs);
           catch
           end

            cla(app.UIAxes);
            ui.update(app, 'fitinitial');
            utils.plotutils.plotX(app,'sample');

            app.gui.PriorityStatus = 'Fit options were updated.';
            if s==0 && l==0 && u==0
            else
            if s==1 || s==nC || s~=nnC
            %     app.table_fitinitial.Enable='off';
                app.gui.PriorityStatus='Now Select Peak Posistions using Select Peak(s)';
            end
            end

            % To preserve previous conditions, must happen as the last few
            % commands 
            app.UITable2.ColumnEditable=true(1,3);
            app.CheckBox0=[fliplr([app.ConstraintsPanel.Children.Value]) app.AsymmetryCheckBox.Value]; % Checkboxes
            app.Coefficients=app.UITable2.RowName';
            app.SelectPeaksButton.Enable=1;
            app.UITable2.ColumnWidth = 'fit';   % default automatic sizing
            app.UITable2.ColumnWidth = 'auto';   % default automatic sizing

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            data=app.UITable.Data; % will be used to check if any are empty
            
            % Check each cell for emptiness
            emptyMask = cellfun(@isempty, data);
       
            if ~isequal(app.Fxn0,app.UITable.Data) % update require since PFS were changed
                app.SelectPeaksButton.Enable=0;
                app.FitDataButton.Enable=0;
            elseif isequal(app.Fxn0,app.UITable.Data) % reset if checked back to original before hiting update
                app.SelectPeaksButton.Enable=1;
                app.FitDataButton.Enable=1;
            end

            row = event.Indices(1);
            col = event.Indices(2);
            if col == 1
                % Function change
                app.profiles.FcnNames{row} = app.gui.FcnNames{row}; % does not capture havign multiple functions, reducing to one, the continually changing that one function.
                ui.update(app, 'functions');
                try
                app.profiles.xrd.constrain(app.gui.Constraints);
                catch
                app.profiles.FcnNames= app.gui.FcnNames; % force function reset.
                app.profiles.xrd.constrain(app.gui.Constraints);

                end
                ui.update(app, 'constraints');
            else
                % On constraint value change
                app.profiles.xrd.unconstrain('Nxfwm');
                app.profiles.xrd.constrain(app.gui.ConstraintsInTable);
                ui.update(app, 'Constraints');
            end

            if any(emptyMask, 'all')
                disp("There is at least one empty slot in the UITable.");
                [row, col] = find(emptyMask);
                fprintf("Empty at row %d, column %d\n", row, col);
                app.SelectPeaksButton.Enable=0;

            else
                app.SelectPeaksButton.Enable=1;
                UpdateButtonPushed(app, event) % to remove 'Update' button, 1-6-2026
                disp("All cells are filled.");
            end


        end

        % Button pushed function: SelectPeaksButton
        function SelectPeaksButtonPushed(app, event)
            % Seletect Peak Positions for estimates...
            % Another Windows button down

            if app.NumberofPeaksSpinner.Value==0
            app.HTML.HTMLSource=app.formatHTML('Add peak(s) to continue... ');
            app.PeakSelect='off';
                return
            end
            pd=sum(cellfun(@isempty, app.UITable.Data));
            if pd>0                
            app.HTML.HTMLSource=app.formatHTML('Define profile shape functions first...');
                return
            elseif isempty(app.profiles.xrd)
            app.HTML.HTMLSource=app.formatHTML('Input data to continue...');
            app.PeakSelect='off';
                return
            end
            if isempty(app.profiles.BackgroundPoints)
            app.HTML.HTMLSource=app.formatHTML('Select background to continue...');
            app.PeakSelect='off';
            app.SelectPeaksButton.Enable=0;
                return
            else % Bkg not empty, need to remove points from bkg selection
            delete(app.UIAxes.Children(strcmp({app.UIAxes.Children.Tag},'Bkgpts')));
            
            end
            
            % Ensure  peak number wasnt increased, then Peak Select, before hitting 'Update'
            tableNames = app.UITable.Data(:);              % force column
            fcnNames   = app.profiles.getInstance.FcnNames(:);
            if isequal(tableNames, fcnNames)
                % same → proceed
                utils.plotutils.plotX(app, 'sample');
            else
                %  different → do X
            app.HTML.HTMLSource=app.formatHTML('"Update" before Peak Select'); % This shouldnt be occuring with more of Update occuring in the background
            pause(1);
            UpdateButtonPushed(app, event)
            utils.plotutils.plotX(app, 'sample');
            end

             try % Make sure all previous peaks selected that would get ploted are deleted, 1-6-2026
             objs = findall(app.UIAxes.Children, '-property', 'Tag');
             sampleObjs = objs(contains(string({objs.Tag}), "sample", 'IgnoreCase', true));
             delete(sampleObjs)
             catch
             end

            % utils.plotutils.plotX(app, 'sample'); % Note sure if this is needed, 1-6-2026

            app.HTML.HTMLSource=app.formatHTML('Select peak positions...');
            app.PeakSelect='on';
            app.KeyPressed='0';
            app.PeakPositions=[]; % reset everytime its time to select new peaks

            delete(findall(app.UIAxes, 'Tag', 'PeakS')); % Delete all previously selected points if they exist, tag matches what is in selectPeakPointsXXX


        end

        % Menu selected function: menu_close
        function menu_closeMenuSelected(app, event)
            delete(app.gui);
            delete(app.profiles);    
            app.delete
            clear('app', 'var');
        end

        % Value changed function: AsymmetryCheckBox
        function AsymmetryCheckBoxValueChanged(app, event)
        % Get checkbox state
        asymValue = double(app.AsymmetryCheckBox.Value);
        app.Asym = asymValue;
        
        % Update Asym property for each FitFunction
        nFxn = numel(app.profiles.xrd.FitFunctions);
        for p = 1:nFxn
            app.profiles.xrd.FitFunctions{p}.Asym = asymValue;
        end
        
        % Get current constraint values
        currentValues = [fliplr([app.ConstraintsPanel.Children.Value]), asymValue];
        
        % Check consistency with initial state
        matchInitial = isequal(app.CheckBox0, currentValues);
        isSameToggle = (app.CheckBox0(6) == asymValue);
        
        % Determine UI state
        state = 'on';
        if ~(isSameToggle && matchInitial)
            state = 'off';
        end
        
        % Update UI controls
        app.FitDataButton.Enable = state;
        app.SelectPeaksButton.Enable = state;
        app.UITable2.Enable = state;

        UpdateButtonPushed(app, event) % added 1-5-2026, attempt to remove 'Update' button
        end

        % Button pushed function: FitDataButton
        function FitDataButtonPushed(app, event)
         if Validate_bkg(app)
            return
        end       
        if isempty(app.UITable2.Data)
                    app.HTML.HTMLSource=app.formatHTML('Select peaks to continue...');
            return
        end

        if strcmp(app.profiles.xrd.getBackgroundModel,'Spline') && app.RefineBkgCheckBox.Value==1
            app.gui.PriorityStatus = '<html><font color="red">Reverting to polynomial background since Refine Bkg is selected.';
          pause(0.5)
        end
        % From Preferences
        app.profiles.xrd.Weights=app.profiles.Weights;
        app.profiles.xrd.UniqueSave=app.profiles.UniqueSave;
        try
            app.profiles.xrd.FitInitial.lower=app.gui.FitInitial.lower;
            app.profiles.xrd.FitInitial.upper=app.gui.FitInitial.upper;
        catch
        end

        try
            prfn = app.profiles.ActiveProfile;    
            fitresults = app.profiles.fitDataSet(prfn,app.HTML);
            
            % this is so when switching constraints, the table wont update with fitted values until a fit is done
            app.profiles.xrd.OriginalFitInitial.coeffs=app.gui.FitInitial.coeffs; 
            if ~isempty(fitresults)
                ui.update(app, 'results');
                utils.plotutils.plotX(app,'fit');
            else
                utils.plotutils.plotX(app,'sample');
            end
        catch ME
            ME.getReport
            assignin('base','lastException',ME)
            errordlg(ME.message)
            return
        end


        if ~isempty(fitresults)
        ui.update(app, 'results');
        if ~ishold(app.UIAxes); hold(app.UIAxes);end
        utils.plotutils.plotX(app,'fit');
        else
%         plotX(app,'sample');
        end
        app.HTML.HTMLSource=app.formatHTML('Fitting complete...');
        app.FilesListBox.Items=app.profiles.FileNames;
        app.FilesListBox.ItemsData=1:length(app.profiles.FileNames);
        app.FilesListBox.Value=app.DropDown.Value;
                % Extract result values
                fitResult = app.profiles.FitResults{1}{app.DropDown.Value};
                rchi2 = round(fitResult.Rchi2, 2);
                rp    = round(fitResult.Rp, 2);
                rwp   = round(fitResult.Rwp, 2);
                
                % Create formatted HTML
                html = sprintf([ ...
                    '<div align="left"><font size="2" face="Helvetica"><b>', ...
                    '&Chi;<sup>2</sup> = %.2f, ', ...
                    'R<sub>p</sub> = %.2f%%, ', ...
                    'R<sub>wp</sub> = %.2f%%', ...
                    '</b></font></div>'], ...
                    rchi2, rp, rwp);
                
                % Assign to component
                app.HTML2.HTMLSource = html;
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            indices = event.Indices; % Index of edited cell
            newData = event.NewData; % New value
            
            if isnan(newData)
                app.gui.PriorityStatus = '<html><font color="red">Not a valid number.';
	            hObject.Data{event.Indices(1), event.Indices(2)} = event.PreviousData;
            else
                app.profiles.FitInitial=app.gui.FitInitial;
                emptyCell = find(cellfun(@isempty, app.UITable2.Data), 1);
            if isempty(emptyCell) 
            set(app.FitDataButton, 'enable', 'on');
            pause(0.5) % this is needed otherwise the GUI is too fast and wont activate...
            else
            set(app.FitDataButton, 'enable', 'off');
            end
            utils.plotutils.plotX(app, 'sample');
            end          

            % assignin('base', 'app', app);
            % guidata(app.figure1,app)

        end

        % Value changed function: NCheckBox
        function NCheckBoxValueChanged(app, event)
            o = event;
            if or(isempty(app.profiles.xrd),isempty(app.UITable2.Data))
                app.HTML.HTMLSource=app.formatHTML('Follow through steps to do a fit...');
                return
            end
%             c=[fliplr([app.ConstraintsPanel.Children.Value]) app.AsymmetryCheckBox.Value];           
            
            if o.Value
                app.profiles.xrd.constrain(o.Source.Text);
            else
                app.profiles.xrd.unconstrain(o.Source.Text);
            end
            ui.update(app, 'Constraints');
            
        end

        % Value changed function: xCheckBox
        function xCheckBoxValueChanged(app, event)
            NCheckBoxValueChanged(app,event)
         
        end

        % Value changed function: fCheckBox
        function fCheckBoxValueChanged(app, event)
            NCheckBoxValueChanged(app,event)
          
        end

        % Value changed function: wCheckBox
        function wCheckBoxValueChanged(app, event)
            NCheckBoxValueChanged(app,event)
      
        end

        % Value changed function: mCheckBox
        function mCheckBoxValueChanged(app, event)
            NCheckBoxValueChanged(app,event)

            
        end

        % Value changed function: FilesListBox
        function FilesListBoxValueChanged(app, event)

            %% Change the call back of when a file is selected for plotting

        if strcmp(app.PlotViewsButtonGroup.SelectedObject.Text,'Coefficient(s)')==1   
            if app.profiles.hasFit
                app.FilesListBox.Items=app.profiles.FitInitial.coeffs;
                app.FilesListBox.ItemsData=1:length(app.profiles.FitInitial.coeffs);
                
                utils.plotutils.plotX(app,'coeff')
            else
                
            end
            
            
        else
            app.FilesListBox.Items=app.profiles.FileNames;
            app.FilesListBox.ItemsData=1:length(app.profiles.FileNames);
            
            if  app.profiles.hasFit
                value = app.FilesListBox.Value;
                app.DropDown.Value=value;
                app.DropDownValueChanged
                utils.plotutils.plotX(app,'fit','list')
            else
                utils.plotutils.plotX(app,'data')
            end        
        end
            
            
        end

        % Selection changed function: PlotViewsButtonGroup
        function PlotViewsButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotViewsButtonGroup.SelectedObject;
            if isempty(app.profiles.xrd)
                    app.HTML.HTMLSource=app.formatHTML('Do a fit before toggling these option');
                    if isempty(app.profiles.FitResults)
                    app.HTML.HTMLSource=app.formatHTML('Do a fit before toggling these option');
                    end
                return
            end
        if strcmp(selectedButton.Text,'Coefficient(s)')==1   
            if app.profiles.hasFit
                app.FilesListBox.Items=app.profiles.FitInitial.coeffs;
                app.FilesListBox.ItemsData=1:length(app.profiles.FitInitial.coeffs);
                app.FilesListBoxLabel.Text='Coefficient(s)';
                
                utils.plotutils.plotX(app,'coeff')
            else

            end
            
            
        else
            app.FilesListBox.Items=app.profiles.FileNames;
            app.FilesListBox.ItemsData=1:length(app.profiles.FileNames);
            app.FilesListBox.Value=app.DropDown.Value;
            app.FilesListBoxLabel.Text='File(s)';

            
            if  app.profiles.hasFit
                value = app.FilesListBox.Value;
                app.DropDown.Value=value;
                app.DropDownValueChanged % Plots fit
                % utils.plotutils.plotX(app,'fit','list') % double plot
            else
                utils.plotutils.plotX(app,'data')
            end        
        end
            
        end

        % Button pushed function: FitStatisticsButton
        function FitStatisticsButtonPushed(app, event)
            if ~isempty(app.profiles.xrd) %check to see if data has been loaded
                if isempty(app.profiles.FitResults) % checks for fits
                    app.HTML.HTMLSource=app.formatHTML('Do a fit before toggling Fit Statistics');
                else
                    utils.plotutils.plotX(app,'stats')
                end   
            end
        end

        % Button pushed function: AllFitsButton
        function AllFitsButtonPushed(app, event)
            if ~isempty(app.profiles.xrd) %check to see if data has been loaded
                if isempty(app.profiles.FitResults) % checks for fits
                    app.HTML.HTMLSource=app.formatHTML('Do a fit before toggling All Fits');
                else
                    utils.plotutils.plotX(app,'allfits')
                end   
            end                      
        end

        % Value changed function: CuK2CheckBox
        function CuK2CheckBoxValueChanged(app, event)
            value = app.CuK2CheckBox.Value;
            if value==1
                app.profiles.xrd.CuKa=true;
                app.profiles.KAlpha1 = 1.5405980;
                app.profiles.KAlpha2 = 1.5444260;
            else
                app.profiles.xrd.CuKa=false;
            end
            app.UpdateButtonPushed(app)
        end

        % Menu selected function: ResetSizeMenu
        function ResetSizeMenuSelected(app, event)
            app.figure1.Position=[100,100,1100,713]; % Change here to specify the size you want
        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
            data = app.HTML.Data;
            
        end

        % Button down function: ConstraintsPanel
        function ConstraintsPanelButtonDown(app, event)

        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)

        end

        % Menu selected function: UniqueSaveMenu
        function UniqueSaveMenuSelected(app, event)

        end

        % Menu selected function: YesMenu_3
        function YesMenu_3Selected(app, event)
            disp('Yes')
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.NoMenu_3.Checked=0;
            app.profiles.UniqueSave=1;
            end
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            value = app.ModelDropDown.Value;
            if isempty(app.profiles.xrd)
            disp('Add data...')
            else
            app.profiles.xrd.setBackgroundModel(value)
            end
            PolyOrderSpinnerValueChanged(app,event) % in event it is changed, for spline, it will reset to '2'
            
        end

        % Menu selected function: NoMenu_3
        function NoMenu_3Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.YesMenu_3.Checked=0;
            app.profiles.UniqueSave=0;
            end
        end

        % Menu selected function: LeastSquaresWeightsMenu
        function LeastSquaresWeightsMenuSelected(app, event)
            
        end

        % Menu selected function: NoneMenu_2
        function NoneMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.DefaultMenu_2.Checked=0;
            app.obsMenu_2.Checked=0;
            app.sqrtobsMenu_2.Checked=0;
            app.profiles.Weights='None';

            end
        end

        % Menu selected function: obsMenu_2
        function obsMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.DefaultMenu_2.Checked=0;
            app.NoneMenu_2.Checked=0;
            app.sqrtobsMenu_2.Checked=0;
            app.profiles.Weights='1/obs';

            end
        end

        % Menu selected function: sqrtobsMenu_2
        function sqrtobsMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.DefaultMenu_2.Checked=0;
            app.obsMenu_2.Checked=0;
            app.NoneMenu_2.Checked=0;
            app.profiles.Weights='1/sqrt(obs)';

            end
        end

        % Menu selected function: DefaultMenu_2
        function DefaultMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.NoneMenu_2.Checked=0;
            app.obsMenu_2.Checked=0;
            app.sqrtobsMenu_2.Checked=0;
            app.profiles.Weights='Default';
            end
        end

        % Menu selected function: DPIMenu_6
        function DPIMenu_6Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.DPIMenu_7.Checked=0;
            app.DPIMenu_8.Checked=0;
            app.profiles.ImageRes=' 100 DPI';
            end
        end

        % Menu selected function: DPIMenu_7
        function DPIMenu_7Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.DPIMenu_6.Checked=0;
            app.DPIMenu_8.Checked=0;
            app.profiles.ImageRes=' 200 DPI';

            end
        end

        % Menu selected function: DPIMenu_8
        function DPIMenu_8Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.DPIMenu_7.Checked=0;
            app.DPIMenu_6.Checked=0;
            app.profiles.ImageRes=' 300 DPI';

            end
        end

        % Menu selected function: TIFFMenu_2
        function TIFFMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.JPEGMenu_2.Checked=0;
            app.PNGMenu_2.Checked=0;
            app.PDFMenu_2.Checked=0;
            app.BMPMenu_2.Checked=0;
            app.EPSCMenu_2.Checked=0;
            app.SVGMenu_2.Checked=0;
            app.profiles.ImageFormat=event.Source.Text;
            end
        end

        % Menu selected function: JPEGMenu_2
        function JPEGMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.TIFFMenu_2.Checked=0;
            app.PNGMenu_2.Checked=0;
            app.PDFMenu_2.Checked=0;
            app.BMPMenu_2.Checked=0;
            app.EPSCMenu_2.Checked=0;
            app.SVGMenu_2.Checked=0;
            app.profiles.ImageFormat=event.Source.Text;

            end            
        end

        % Menu selected function: PNGMenu_2
        function PNGMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.JPEGMenu_2.Checked=0;
            app.TIFFMenu_2.Checked=0;
            app.PDFMenu_2.Checked=0;
            app.BMPMenu_2.Checked=0;
            app.EPSCMenu_2.Checked=0;
            app.SVGMenu_2.Checked=0;
            app.profiles.ImageFormat=event.Source.Text;

            end            
        end

        % Menu selected function: PDFMenu_2
        function PDFMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.JPEGMenu_2.Checked=0;
            app.PNGMenu_2.Checked=0;
            app.TIFFMenu_2.Checked=0;
            app.BMPMenu_2.Checked=0;
            app.EPSCMenu_2.Checked=0;
            app.SVGMenu_2.Checked=0;
            app.profiles.ImageFormat=event.Source.Text;

            end            
        end

        % Callback function
        function BMPMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.JPEGMenu_2.Checked=0;
            app.PNGMenu_2.Checked=0;
            app.PDFMenu_2.Checked=0;
            app.TIFFMenu_2.Checked=0;
            app.EPSCMenu_2.Checked=0;
            app.SVGMenu_2.Checked=0;
            app.profiles.ImageFormat=event.Source.Text;

            end            
        end

        % Callback function
        function EPSCMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.JPEGMenu_2.Checked=0;
            app.PNGMenu_2.Checked=0;
            app.PDFMenu_2.Checked=0;
            app.BMPMenu_2.Checked=0;
            app.TIFFMenu_2.Checked=0;
            app.SVGMenu_2.Checked=0;
            app.profiles.ImageFormat=event.Source.Text;

            end            
        end

        % Callback function
        function SVGMenu_2Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.JPEGMenu_2.Checked=0;
            app.PNGMenu_2.Checked=0;
            app.PDFMenu_2.Checked=0;
            app.BMPMenu_2.Checked=0;
            app.EPSCMenu_2.Checked=0;
            app.TIFFMenu_2.Checked=0;
            app.profiles.ImageFormat=event.Source.Text;

            end            
        end

        % Menu selected function: YesMenu_4
        function YesMenu_4Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.NoMenu_4.Checked=0;
            app.profiles.ImageSaveAll=1;

            end
        end

        % Menu selected function: NoMenu_4
        function NoMenu_4Selected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.YesMenu_4.Checked=0;
            app.profiles.ImageSaveAll=0;

            end            
        end

        % Menu selected function: LegendOn
        function LegendOnSelected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.LegendOff.Checked=0;
                toolbar_legend_OnCallback(app)
            end
        end

        % Menu selected function: LegendOff
        function LegendOffSelected(app, event)
            if event.Source.Checked~=1
            event.Source.Checked=1;
            app.LegendOn.Checked=0;
                toolbar_legend_OffCallback(app)
            end
        end

        % Menu selected function: ImageResolutionMenu
        function ImageResolutionMenuSelected(app, event)
            
        end

        % Menu selected function: ImageSaveAllMenu
        function ImageSaveAllMenuSelected(app, event)
            
        end

        % Menu selected function: SelectMenu_2
        function SelectMenu_2Selected(app, event)
        % Prompt user to select a directory
            startingPath = pwd; % Specify the initial path
            selectedDir = uigetdir(startingPath, 'Select a Folder');
            
            % Check if the user selected a directory or canceled
            if selectedDir ~= 0
                disp(['Selected Directory: ', selectedDir]);
            else
                disp('User canceled the selection.');
            end
            app.profiles.DataPath=selectedDir;

        end

        % Menu selected function: SavePreferenceFileMenu
        function SavePreferenceFileMenuSelected(app, event)
            
        SavePref(app)

        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            
        end

        % Value changed function: EditFieldWave
        function EditFieldWaveValueChanged(app, event)
            value = app.EditFieldWave.Value;

            if ~app.profiles.hasData
                app.HTML.HTMLSource=app.formatHTML('Changes will be implemented after data import...');
                return
            end

            if isa(app.profiles.xrd.MonoWavelength,'numeric')
            wave=app.profiles.xrd.MonoWavelength;
            end

                    if isempty(value)
                        
                        app.HTML2.HTMLSource=app.formatHTML("Enter a valid value for wavelength in Å");
                        app.EditFieldWave.Value=1.5406; % default back
                        return
                    elseif isnumeric(value)
                        app.profiles.KAlpha1 = value;
                    else
                        app.HTML2.HTMLSource=app.formatHTML("Enter a valid value for wavelength in Å");
                        app.EditFieldWave.Value=1.5406; % default back     
                        return
                    end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'LIPRAS, Line-Profile Analysis Software';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.WindowButtonDownFcn = createCallbackFcn(app, @figure1WindowButtonDown, true);
            app.figure1.WindowButtonMotionFcn = createCallbackFcn(app, @figure1WindowButtonMotion, true);
            app.figure1.WindowKeyPressFcn = createCallbackFcn(app, @figure1WindowKeyPress, true);
            app.menu_file = uimenu(app.figure1);
            app.menu_file.Text = 'File';
            app.menuoption_new = uimenu(app.menu_file);
            app.menuoption_new.Text = 'New...';
            app.menu_new = uimenu(app.menuoption_new);
            app.menu_new.MenuSelectedFcn = createCallbackFcn(app, @button_browse_Callback, true);
            app.menu_new.Accelerator = 'N';
            app.menu_new.Text = 'Dataset';
            app.Untitled_14 = uimenu(app.menu_file);
            app.Untitled_14.Text = 'Import...';
            app.FitParamImport = uimenu(app.Untitled_14);
            app.FitParamImport.MenuSelectedFcn = createCallbackFcn(app, @FitParamImport_Callback, true);
            app.FitParamImport.Accelerator = 'I';
            app.FitParamImport.Text = 'Fit Parameters';
            app.Untitled_12 = uimenu(app.menu_file);
            app.Untitled_12.Separator = 'on';
            app.Untitled_12.Text = 'Export...';
            app.FitParamExport = uimenu(app.Untitled_12);
            app.FitParamExport.MenuSelectedFcn = createCallbackFcn(app, @FitParamExport_Callback, true);
            app.FitParamExport.Accelerator = 'S';
            app.FitParamExport.Text = 'Fit Parameters';
            app.menu_saveasimage = uimenu(app.Untitled_12);
            app.menu_saveasimage.MenuSelectedFcn = createCallbackFcn(app, @menu_saveasimage_Callback, true);
            app.menu_saveasimage.Accelerator = 'F';
            app.menu_saveasimage.Text = 'Plot';
            app.ResetSizeMenu = uimenu(app.menu_file);
            app.ResetSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetSizeMenuSelected, true);
            app.ResetSizeMenu.Text = 'Reset Size';
            app.menu_FileResetProfile = uimenu(app.menu_file);
            app.menu_FileResetProfile.MenuSelectedFcn = createCallbackFcn(app, @menu_FileResetProfile_Callback, true);
            app.menu_FileResetProfile.Separator = 'on';
            app.menu_FileResetProfile.Text = 'Reset Profile';
            app.menu_restart = uimenu(app.menu_file);
            app.menu_restart.MenuSelectedFcn = createCallbackFcn(app, @menu_restart_Callback, true);
            app.menu_restart.Text = 'Restart LIPRAS';
            app.menu_close = uimenu(app.menu_file);
            app.menu_close.MenuSelectedFcn = createCallbackFcn(app, @menu_closeMenuSelected, true);
            app.menu_close.Separator = 'on';
            app.menu_close.Accelerator = 'W';
            app.menu_close.Text = 'Close';
            app.menu_plot = uimenu(app.figure1);
            app.menu_plot.Text = 'Plot';
            app.menu_xaxis = uimenu(app.menu_plot);
            app.menu_xaxis.Text = 'X-Axis Scale';
            app.menu_xaxis_linear = uimenu(app.menu_xaxis);
            app.menu_xaxis_linear.MenuSelectedFcn = createCallbackFcn(app, @menu_xplotscale_Callback, true);
            app.menu_xaxis_linear.Tooltip = {'Converts back to 2Θ or to the original file’s input scale'};
            app.menu_xaxis_linear.Checked = 'on';
            app.menu_xaxis_linear.Text = 'Linear';
            app.menu_xaxis_dspace = uimenu(app.menu_xaxis);
            app.menu_xaxis_dspace.MenuSelectedFcn = createCallbackFcn(app, @menu_xplotscale_Callback, true);
            app.menu_xaxis_dspace.Tooltip = {'Convert to d-space based on wavelength entered'};
            app.menu_xaxis_dspace.Text = 'D-Space';
            app.menu_yaxis = uimenu(app.menu_plot);
            app.menu_yaxis.Text = 'Y-Axis Scale';
            app.menu_ylinear = uimenu(app.menu_yaxis);
            app.menu_ylinear.MenuSelectedFcn = createCallbackFcn(app, @menu_yplotscale_Callback, true);
            app.menu_ylinear.Checked = 'on';
            app.menu_ylinear.Text = 'Linear';
            app.menu_ylog = uimenu(app.menu_yaxis);
            app.menu_ylog.MenuSelectedFcn = createCallbackFcn(app, @menu_yplotscale_Callback, true);
            app.menu_ylog.Text = 'Logarithm';
            app.menu_ylog.Tag = 'log';
            app.menu_yroot = uimenu(app.menu_yaxis);
            app.menu_yroot.MenuSelectedFcn = createCallbackFcn(app, @menu_yplotscale_Callback, true);
            app.menu_yroot.Text = 'Square Root';
            app.menuPlot_superimpose = uimenu(app.menu_plot);
            app.menuPlot_superimpose.MenuSelectedFcn = createCallbackFcn(app, @menuPlot_superimpose_Callback, true);
            app.menuPlot_superimpose.Separator = 'on';
            app.menuPlot_superimpose.Accelerator = 'U';
            app.menuPlot_superimpose.Text = 'Superimpose Raw Data';
            app.LegendMenu = uimenu(app.menu_plot);
            app.LegendMenu.Text = 'Legend';
            app.LegendOn = uimenu(app.LegendMenu);
            app.LegendOn.MenuSelectedFcn = createCallbackFcn(app, @LegendOnSelected, true);
            app.LegendOn.Checked = 'on';
            app.LegendOn.Text = 'Yes';
            app.LegendOff = uimenu(app.LegendMenu);
            app.LegendOff.MenuSelectedFcn = createCallbackFcn(app, @LegendOffSelected, true);
            app.LegendOff.Text = 'No';
            app.PreferencesMenu = uimenu(app.figure1);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Text = 'Preferences';
            app.UniqueSaveMenu = uimenu(app.PreferencesMenu);
            app.UniqueSaveMenu.MenuSelectedFcn = createCallbackFcn(app, @UniqueSaveMenuSelected, true);
            app.UniqueSaveMenu.Text = 'Unique Save';
            app.YesMenu_3 = uimenu(app.UniqueSaveMenu);
            app.YesMenu_3.MenuSelectedFcn = createCallbackFcn(app, @YesMenu_3Selected, true);
            app.YesMenu_3.Text = 'Yes';
            app.NoMenu_3 = uimenu(app.UniqueSaveMenu);
            app.NoMenu_3.MenuSelectedFcn = createCallbackFcn(app, @NoMenu_3Selected, true);
            app.NoMenu_3.Checked = 'on';
            app.NoMenu_3.Text = 'No';
            app.StartingDirectoryMenu = uimenu(app.PreferencesMenu);
            app.StartingDirectoryMenu.Tooltip = {'Set starting directory for data'};
            app.StartingDirectoryMenu.Text = 'Starting Directory';
            app.SelectMenu_2 = uimenu(app.StartingDirectoryMenu);
            app.SelectMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SelectMenu_2Selected, true);
            app.SelectMenu_2.Text = 'Select';
            app.LeastSquaresWeightsMenu = uimenu(app.PreferencesMenu);
            app.LeastSquaresWeightsMenu.MenuSelectedFcn = createCallbackFcn(app, @LeastSquaresWeightsMenuSelected, true);
            app.LeastSquaresWeightsMenu.Text = 'Least Squares Weights';
            app.DefaultMenu_2 = uimenu(app.LeastSquaresWeightsMenu);
            app.DefaultMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DefaultMenu_2Selected, true);
            app.DefaultMenu_2.Checked = 'on';
            app.DefaultMenu_2.Text = 'Default';
            app.NoneMenu_2 = uimenu(app.LeastSquaresWeightsMenu);
            app.NoneMenu_2.MenuSelectedFcn = createCallbackFcn(app, @NoneMenu_2Selected, true);
            app.NoneMenu_2.Text = 'None';
            app.obsMenu_2 = uimenu(app.LeastSquaresWeightsMenu);
            app.obsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @obsMenu_2Selected, true);
            app.obsMenu_2.Text = '1/obs';
            app.sqrtobsMenu_2 = uimenu(app.LeastSquaresWeightsMenu);
            app.sqrtobsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @sqrtobsMenu_2Selected, true);
            app.sqrtobsMenu_2.Text = '1/sqrt(obs)';
            app.ImageResolutionMenu = uimenu(app.PreferencesMenu);
            app.ImageResolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageResolutionMenuSelected, true);
            app.ImageResolutionMenu.Text = 'Image Resolution';
            app.DPIMenu_6 = uimenu(app.ImageResolutionMenu);
            app.DPIMenu_6.MenuSelectedFcn = createCallbackFcn(app, @DPIMenu_6Selected, true);
            app.DPIMenu_6.Checked = 'on';
            app.DPIMenu_6.Text = '100 DPI';
            app.DPIMenu_7 = uimenu(app.ImageResolutionMenu);
            app.DPIMenu_7.MenuSelectedFcn = createCallbackFcn(app, @DPIMenu_7Selected, true);
            app.DPIMenu_7.Text = '200 DPI';
            app.DPIMenu_8 = uimenu(app.ImageResolutionMenu);
            app.DPIMenu_8.MenuSelectedFcn = createCallbackFcn(app, @DPIMenu_8Selected, true);
            app.DPIMenu_8.Text = '300 DPI';
            app.ImageTypeMenu = uimenu(app.PreferencesMenu);
            app.ImageTypeMenu.Tooltip = {'Image export type'};
            app.ImageTypeMenu.Text = 'Image Type';
            app.TIFFMenu_2 = uimenu(app.ImageTypeMenu);
            app.TIFFMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TIFFMenu_2Selected, true);
            app.TIFFMenu_2.Checked = 'on';
            app.TIFFMenu_2.Text = 'TIFF';
            app.JPEGMenu_2 = uimenu(app.ImageTypeMenu);
            app.JPEGMenu_2.MenuSelectedFcn = createCallbackFcn(app, @JPEGMenu_2Selected, true);
            app.JPEGMenu_2.Text = 'JPEG';
            app.PNGMenu_2 = uimenu(app.ImageTypeMenu);
            app.PNGMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PNGMenu_2Selected, true);
            app.PNGMenu_2.Text = 'PNG';
            app.PDFMenu_2 = uimenu(app.ImageTypeMenu);
            app.PDFMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PDFMenu_2Selected, true);
            app.PDFMenu_2.Text = 'PDF';
            app.ImageSaveAllMenu = uimenu(app.PreferencesMenu);
            app.ImageSaveAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageSaveAllMenuSelected, true);
            app.ImageSaveAllMenu.Text = 'Image Save All';
            app.YesMenu_4 = uimenu(app.ImageSaveAllMenu);
            app.YesMenu_4.MenuSelectedFcn = createCallbackFcn(app, @YesMenu_4Selected, true);
            app.YesMenu_4.Text = 'Yes';
            app.NoMenu_4 = uimenu(app.ImageSaveAllMenu);
            app.NoMenu_4.MenuSelectedFcn = createCallbackFcn(app, @NoMenu_4Selected, true);
            app.NoMenu_4.Checked = 'on';
            app.NoMenu_4.Text = 'No';
            app.SavePreferenceFileMenu = uimenu(app.PreferencesMenu);
            app.SavePreferenceFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SavePreferenceFileMenuSelected, true);
            app.SavePreferenceFileMenu.Tooltip = {'   Saves preferences to file to make the default upon startup'};
            app.SavePreferenceFileMenu.Accelerator = 'P';
            app.SavePreferenceFileMenu.Text = 'Save Preference File';
            app.menulabel_help = uimenu(app.figure1);
            app.menulabel_help.Text = 'Help';
            app.menu_about = uimenu(app.menulabel_help);
            app.menu_about.MenuSelectedFcn = createCallbackFcn(app, @menu_about_Callback, true);
            app.menu_about.Text = 'About';
            app.menu_help = uimenu(app.menulabel_help);
            app.menu_help.MenuSelectedFcn = createCallbackFcn(app, @menu_helpMenuSelected, true);
            app.menu_help.Separator = 'on';
            app.menu_help.Text = 'Help';
            app.checkUpdate = uimenu(app.menulabel_help);
            app.checkUpdate.MenuSelectedFcn = createCallbackFcn(app, @checkforupdates, true);
            app.checkUpdate.Text = 'Check for Updates';
            app.UIAxes = uiaxes(app.figure1);
            ylabel(app.UIAxes, 'Intensity (a.u.)')
            app.UIAxes2 = uiaxes(app.figure1);
            ylabel(app.UIAxes2, 'Obs-Calc')
            app.UIAxes2.Visible = 'off';
            app.TabGroup = uitabgroup(app.figure1);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.BackgroundPanel = uipanel(app.SetupTab);
            app.BackgroundPanel.Title = 'Background';
            app.ModelDropDownLabel = uilabel(app.BackgroundPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.BackgroundPanel);
            app.ModelDropDown.Items = {'Polynomial', 'Spline'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'Polynomial';
            app.PolyOrderSpinnerLabel = uilabel(app.BackgroundPanel);
            app.PolyOrderSpinnerLabel.Text = 'Poly Order';
            app.PolyOrderSpinner = uispinner(app.BackgroundPanel);
            app.PolyOrderSpinner.Limits = [0 15];
            app.PolyOrderSpinner.ValueChangedFcn = createCallbackFcn(app, @PolyOrderSpinnerValueChanged, true);
            app.PolyOrderSpinner.Value = 1;
            app.EditModeButtonGroup = uibuttongroup(app.BackgroundPanel);
            app.EditModeButtonGroup.Title = 'Edit Mode';
            app.NewButton = uiradiobutton(app.EditModeButtonGroup);
            app.NewButton.Text = 'New';
            app.NewButton.Value = true;
            app.AddButton = uiradiobutton(app.EditModeButtonGroup);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uiradiobutton(app.EditModeButtonGroup);
            app.DeleteButton.Text = 'Delete';
            app.SelectPointsButton = uibutton(app.BackgroundPanel, 'push');
            app.SelectPointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointsButtonPushed, true);
            app.SelectPointsButton.Tooltip = {'Select background points'};
            app.SelectPointsButton.Text = 'Select Points';
            app.PlotBackgroundButton = uibutton(app.BackgroundPanel, 'push');
            app.PlotBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBackgroundButtonPushed, true);
            app.PlotBackgroundButton.Text = 'Plot Background';
            app.ThetaLabel = uilabel(app.SetupTab);
            app.ThetaLabel.Text = 'Range (°)';
            app.EditMin2T = uieditfield(app.SetupTab, 'numeric');
            app.EditMin2T.ValueDisplayFormat = '%.4f';
            app.EditMin2T.ValueChangedFcn = createCallbackFcn(app, @EditMin2TValueChanged, true);
            app.EditMax2T = uieditfield(app.SetupTab, 'numeric');
            app.EditMax2T.ValueDisplayFormat = '%.4f';
            app.EditMax2T.ValueChangedFcn = createCallbackFcn(app, @EditMax2TValueChanged, true);
            app.LineProfilesPanel = uipanel(app.SetupTab);
            app.LineProfilesPanel.Enable = 'off';
            app.LineProfilesPanel.Title = 'Line-Profiles';
            app.UITable = uitable(app.LineProfilesPanel);
            app.UITable.ColumnName = {'Profile'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.NumberofPeaksSpinnerLabel = uilabel(app.LineProfilesPanel);
            app.NumberofPeaksSpinnerLabel.Text = 'Number of Peaks';
            app.NumberofPeaksSpinner = uispinner(app.LineProfilesPanel);
            app.NumberofPeaksSpinner.Limits = [0 20];
            app.NumberofPeaksSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofPeaksSpinnerValueChanged, true);
            app.CuK2CheckBox = uicheckbox(app.LineProfilesPanel);
            app.CuK2CheckBox.ValueChangedFcn = createCallbackFcn(app, @CuK2CheckBoxValueChanged, true);
            app.CuK2CheckBox.Tooltip = {'Adds Cu K-alpha 2 contribution to all peaks, wavelength should be 1.5406 Å above'};
            app.CuK2CheckBox.Text = 'Cu Kα2';
            app.UpdateButton = uibutton(app.LineProfilesPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Tooltip = {'Replace the current starting parameters with the latest fit results'};
            app.UpdateButton.Text = 'Update';
            app.SelectPeaksButton = uibutton(app.LineProfilesPanel, 'push');
            app.SelectPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPeaksButtonPushed, true);
            app.SelectPeaksButton.Tooltip = {'Select peak positions'};
            app.SelectPeaksButton.Text = 'Select Peak(s)';
            app.ConstraintsPanel = uipanel(app.LineProfilesPanel);
            app.ConstraintsPanel.Title = 'Constraints';
            app.ConstraintsPanel.ButtonDownFcn = createCallbackFcn(app, @ConstraintsPanelButtonDown, true);
            app.NCheckBox = uicheckbox(app.ConstraintsPanel);
            app.NCheckBox.ValueChangedFcn = createCallbackFcn(app, @NCheckBoxValueChanged, true);
            app.NCheckBox.Text = 'N';
            app.xCheckBox = uicheckbox(app.ConstraintsPanel);
            app.xCheckBox.ValueChangedFcn = createCallbackFcn(app, @xCheckBoxValueChanged, true);
            app.xCheckBox.Text = 'x';
            app.fCheckBox = uicheckbox(app.ConstraintsPanel);
            app.fCheckBox.ValueChangedFcn = createCallbackFcn(app, @fCheckBoxValueChanged, true);
            app.fCheckBox.Text = 'f';
            app.wCheckBox = uicheckbox(app.ConstraintsPanel);
            app.wCheckBox.ValueChangedFcn = createCallbackFcn(app, @wCheckBoxValueChanged, true);
            app.wCheckBox.Text = 'w';
            app.mCheckBox = uicheckbox(app.ConstraintsPanel);
            app.mCheckBox.ValueChangedFcn = createCallbackFcn(app, @mCheckBoxValueChanged, true);
            app.mCheckBox.Text = 'm';
            app.UITable2 = uitable(app.LineProfilesPanel);
            app.UITable2.ColumnName = {'SP'; 'LB'; 'UB'};
            app.UITable2.RowName = {''};
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.RecycleCheckBox = uicheckbox(app.LineProfilesPanel);
            app.RecycleCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecycleCheckBoxValueChanged, true);
            app.RecycleCheckBox.Text = 'Recycle';
            app.RefineBkgCheckBox = uicheckbox(app.LineProfilesPanel);
            app.RefineBkgCheckBox.ValueChangedFcn = createCallbackFcn(app, @RefineBkgCheckBoxValueChanged, true);
            app.RefineBkgCheckBox.Text = 'Refine Bkg';
            app.NoBoundsCheckBox = uicheckbox(app.LineProfilesPanel);
            app.NoBoundsCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoBoundsCheckBoxValueChanged, true);
            app.NoBoundsCheckBox.Text = 'No Bounds';
            app.FitDataButton = uibutton(app.LineProfilesPanel, 'push');
            app.FitDataButton.ButtonPushedFcn = createCallbackFcn(app, @FitDataButtonPushed, true);
            app.FitDataButton.Enable = 'off';
            app.FitDataButton.Tooltip = {'Fit data using parameters in table'};
            app.FitDataButton.Text = 'Fit Data';
            app.AsymmetryCheckBox = uicheckbox(app.LineProfilesPanel);
            app.AsymmetryCheckBox.ValueChangedFcn = createCallbackFcn(app, @AsymmetryCheckBoxValueChanged, true);
            app.AsymmetryCheckBox.Tooltip = {'Makes the following peaks asymmetric, Gaussian, Lorentizian, PV, and PVII'};
            app.AsymmetryCheckBox.Text = 'Asymmetry';
            app.lambdaLabel = uilabel(app.SetupTab);
            app.lambdaLabel.Text = 'λ (Å)';
            app.EditFieldWave = uieditfield(app.SetupTab, 'numeric');
            app.EditFieldWave.Limits = [0 Inf];
            app.EditFieldWave.ValueDisplayFormat = '%.4f';
            app.EditFieldWave.ValueChangedFcn = createCallbackFcn(app, @EditFieldWaveValueChanged, true);
            app.EditFieldWave.Enable = 'off';
            app.EditFieldWave.Tooltip = {'Wavelength of source, for Cu default is 1.5406 Å'};
            app.EditFieldWave.Value = 1.5406;
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.PlotViewsButtonGroup = uibuttongroup(app.ResultsTab);
            app.PlotViewsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotViewsButtonGroupSelectionChanged, true);
            app.PlotViewsButtonGroup.Title = 'Plot Views';
            app.PeakFitButton = uiradiobutton(app.PlotViewsButtonGroup);
            app.PeakFitButton.Text = 'Peak Fit';
            app.PeakFitButton.Value = true;
            app.CoefficientsButton = uiradiobutton(app.PlotViewsButtonGroup);
            app.CoefficientsButton.Text = 'Coefficient(s)';
            app.FilesListBoxLabel = uilabel(app.PlotViewsButtonGroup);
            app.FilesListBoxLabel.Text = 'File(s)';
            app.FilesListBox = uilistbox(app.PlotViewsButtonGroup);
            app.FilesListBox.Items = {};
            app.FilesListBox.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.FilesListBox.Value = {};
            app.FitStatisticsButton = uibutton(app.ResultsTab, 'push');
            app.FitStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @FitStatisticsButtonPushed, true);
            app.FitStatisticsButton.Tooltip = {'Fit statistics for all fits done'};
            app.FitStatisticsButton.Text = 'Fit Statistics';
            app.AllFitsButton = uibutton(app.ResultsTab, 'push');
            app.AllFitsButton.ButtonPushedFcn = createCallbackFcn(app, @AllFitsButtonPushed, true);
            app.AllFitsButton.Tooltip = {'Plots the fit for all files that were fit'};
            app.AllFitsButton.Text = 'All Fits';
            app.BayesianButton = uibutton(app.ResultsTab, 'push');
            app.BayesianButton.Text = 'Bayesian';
            app.UITable3 = uitable(app.ResultsTab);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.text55 = uilabel(app.figure1);
            app.text55.Visible = 'off';
            app.text55.Text = '';
            app.edit8 = uitextarea(app.figure1);
            app.edit8.Editable = 'off';
            app.edit8.Enable = 'off';
            app.edit8.Tooltip = {'Path to loaded dataset'};
            app.edit8.Value = {'Upload new file(s)...'};
            app.button_browse = uibutton(app.figure1, 'push');
            app.button_browse.ButtonPushedFcn = createCallbackFcn(app, @button_browse_Callback, true);
            app.button_browse.Tooltip = 'Select file(s) containing data to fit.';
            app.button_browse.Text = 'Browse';
            app.text22 = uilabel(app.figure1);
            app.text22.Text = 'Dataset Path ';
            app.checkbox_reverse = uicheckbox(app.figure1);
            app.checkbox_reverse.ValueChangedFcn = createCallbackFcn(app, @checkbox_reverse_Callback, true);
            app.checkbox_reverse.Tooltip = 'Reverses the order that the files are read in.';
            app.checkbox_reverse.Text = 'Reverse File Order';
            app.HTML = uihtml(app.figure1);
            app.HTML.HTMLSource = ' ';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            app.HTML2 = uihtml(app.figure1);
            app.HTML2.HTMLSource = ' ';
            app.DropDownLabel = uilabel(app.figure1);
            app.DropDownLabel.Visible = 'off';
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.figure1);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LIPRAS_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @LIPRAS_OpeningFcn)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end